classdef CEPS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        editColumnRange               matlab.ui.control.EditField
        editEpochRange                matlab.ui.control.EditField
        editFileRange                 matlab.ui.control.EditField
        chkDebugMode                  matlab.ui.control.CheckBox
        pMenuDimension                matlab.ui.control.DropDown
        text120                       matlab.ui.control.Label
        pMenuEpochs                   matlab.ui.control.DropDown
        text118                       matlab.ui.control.Label
        pMenuFileNames                matlab.ui.control.DropDown
        text117                       matlab.ui.control.Label
        TabGroupSelection             matlab.ui.container.TabGroup
        DataFilesTab                  matlab.ui.container.Tab
        uiTableFilesName              matlab.ui.control.Table
        chkAllFiles                   matlab.ui.control.CheckBox
        MeasuresTab                   matlab.ui.container.Tab
        uitableSMeasures              matlab.ui.control.Table
        chkAllMeasures                matlab.ui.control.CheckBox
        MVerificationTab              matlab.ui.container.Tab
        uiTableVerification           matlab.ui.control.Table
        TabGroupButtom                matlab.ui.container.TabGroup
        NoEventTab                    matlab.ui.container.Tab
        KeytoverificationstatusPanel  matlab.ui.container.Panel
        txtSampleR_7                  matlab.ui.control.Label
        LoadDataTab                   matlab.ui.container.Tab
        txtEpochT_9                   matlab.ui.control.Label
        uiTableSelectedFiles          matlab.ui.control.Table
        pMenuDataFileName             matlab.ui.control.DropDown
        txtEpochT_6                   matlab.ui.control.Label
        pMenuDbFileExtension_2        matlab.ui.control.DropDown
        txtEpochT_2                   matlab.ui.control.Label
        UITableFileData               matlab.ui.control.Table
        LoadDataPanel                 matlab.ui.container.Panel
        editNumDbFiles                matlab.ui.control.EditField
        text42                        matlab.ui.control.Label
        pbSelectFiles                 matlab.ui.control.Button
        text45_3                      matlab.ui.control.Label
        editFileExt                   matlab.ui.control.EditField
        text45_2                      matlab.ui.control.Label
        editDataDir                   matlab.ui.control.EditField
        pMenuDbFileExtension          matlab.ui.control.DropDown
        text45                        matlab.ui.control.Label
        Panel5                        matlab.ui.container.Panel
        unevenDataPanel               matlab.ui.container.ButtonGroup
        editCutOffRatio_LD            matlab.ui.control.EditField
        txtCutOffRatio_LD             matlab.ui.control.Label
        chkEndpointEffect_LD          matlab.ui.control.CheckBox
        editSampleFS_LD               matlab.ui.control.EditField
        txtSampleR_24                 matlab.ui.control.Label
        chkResamplingFreq_LD          matlab.ui.control.CheckBox
        editResamplingFreq_LD         matlab.ui.control.EditField
        text117_8                     matlab.ui.control.Label
        pMenuInterpolation_LD         matlab.ui.control.DropDown
        pMenuTimeType_LD              matlab.ui.control.DropDown
        text117_6                     matlab.ui.control.Label
        chkLoadData_Uneven            matlab.ui.control.CheckBox
        TextArea_3                    matlab.ui.control.TextArea
        pbUnloadDataFile              matlab.ui.control.Button
        editDataFileName              matlab.ui.control.EditField
        pbLoadData                    matlab.ui.control.Button
        chkLoadAllSelectedFiles       matlab.ui.control.CheckBox
        TextArea                      matlab.ui.control.TextArea
        editRemoveCols                matlab.ui.control.EditField
        txtEpochT_4                   matlab.ui.control.Label
        editRemoveRows                matlab.ui.control.EditField
        txtEpochT_3                   matlab.ui.control.Label
        TabBottom_DataProcessing      matlab.ui.container.Tab
        TabGroup                      matlab.ui.container.TabGroup
        ViewDataTab                   matlab.ui.container.Tab
        chkShowTruncatedData          matlab.ui.control.CheckBox
        chkShowOutliers               matlab.ui.control.CheckBox
        pbDeleteDataRange             matlab.ui.control.Button
        pbPlotData                    matlab.ui.control.Button
        myAxesDown                    matlab.ui.control.UIAxes
        myAxesUp                      matlab.ui.control.UIAxes
        PreviewEffectTab              matlab.ui.container.Tab
        chkPP_Epoch                   matlab.ui.control.CheckBox
        txtEpochT_10                  matlab.ui.control.Label
        editPreviewURange             matlab.ui.control.EditField
        editPreviewLRange             matlab.ui.control.EditField
        pMenuPP_FilterType            matlab.ui.control.DropDown
        pbPP_PreviewEffect            matlab.ui.control.Button
        UIAxesPreviewE                matlab.ui.control.UIAxes
        OutliersDeletedEntitiesTab    matlab.ui.container.Tab
        pbPreprocessRefresh           matlab.ui.control.Button
        text193_4                     matlab.ui.control.Label
        text193_3                     matlab.ui.control.Label
        UITablePP_Removed             matlab.ui.control.Table
        UITablePP_Outliers            matlab.ui.control.Table
        CompareDataFilesTab           matlab.ui.container.Tab
        pMenuPPViewMode               matlab.ui.control.DropDown
        pbPPPlotData                  matlab.ui.control.Button
        pbPPClearPlots                matlab.ui.control.Button
        UIAxesPPProcessed             matlab.ui.control.UIAxes
        UIAxesPPRaw                   matlab.ui.control.UIAxes
        TruncateDataPanel             matlab.ui.container.Panel
        pbRecoverRawData              matlab.ui.control.Button
        TextArea_4                    matlab.ui.control.TextArea
        pbTruncateData                matlab.ui.control.Button
        editMaxLength                 matlab.ui.control.EditField
        txtMaxLength                  matlab.ui.control.Label
        txtStartT                     matlab.ui.control.Label
        editStartT                    matlab.ui.control.EditField
        Panel3                        matlab.ui.container.Panel
        editDegreePoly_PPD            matlab.ui.control.EditField
        text192_2                     matlab.ui.control.Label
        chkApplyDetrending_PPD        matlab.ui.control.CheckBox
        editFileSuffix                matlab.ui.control.EditField
        editFilePrefix                matlab.ui.control.EditField
        txtEpochT_12                  matlab.ui.control.Label
        txtEpochT_11                  matlab.ui.control.Label
        pbSaveDataFile                matlab.ui.control.Button
        pbRecoverTruncatedData        matlab.ui.control.Button
        TextArea_5                    matlab.ui.control.TextArea
        pbProcessData                 matlab.ui.control.Button
        chkRemoveOutliers             matlab.ui.control.CheckBox
        pMenuOutlierType              matlab.ui.control.DropDown
        OutlierMW                     matlab.ui.control.Label
        editHighBand                  matlab.ui.control.EditField
        text193                       matlab.ui.control.Label
        editLowBand                   matlab.ui.control.EditField
        text192                       matlab.ui.control.Label
        editMedianFilterOrder         matlab.ui.control.EditField
        text191                       matlab.ui.control.Label
        chkMedianFilter               matlab.ui.control.CheckBox
        chkApplyFilter                matlab.ui.control.CheckBox
        editOutlierMW                 matlab.ui.control.EditField
        DataModificationTab           matlab.ui.container.Tab
        TabGroup4                     matlab.ui.container.TabGroup
        tbDataModification_Down       matlab.ui.container.Tab
        ApplyandSaveButton            matlab.ui.control.Button
        editCG_Suffix                 matlab.ui.control.EditField
        txtSampleR_14                 matlab.ui.control.Label
        editCG_Prefix                 matlab.ui.control.EditField
        txtSampleR_13                 matlab.ui.control.Label
        ViewCG_Effecs                 matlab.ui.control.Button
        pMenuBinarisation             matlab.ui.control.DropDown
        chkBinarisation               matlab.ui.control.CheckBox
        chkNormalization              matlab.ui.control.CheckBox
        chkCutFile                    matlab.ui.control.CheckBox
        chkReduce                     matlab.ui.control.CheckBox
        chkIncrease                   matlab.ui.control.CheckBox
        chkAddNoise                   matlab.ui.control.CheckBox
        tabCG_ParameterSelection      matlab.ui.container.TabGroup
        NormalisationTab              matlab.ui.container.Tab
        ViewNormalization             matlab.ui.control.Button
        pMenuNormalization            matlab.ui.control.DropDown
        txtSampleR_21                 matlab.ui.control.Label
        IncreaseTab                   matlab.ui.container.Tab
        ViewInterpolation             matlab.ui.control.Button
        pMenuFineGrid                 matlab.ui.control.DropDown
        txtSampleR_9                  matlab.ui.control.Label
        txtSampleR_8                  matlab.ui.control.Label
        pMenuIP_Method                matlab.ui.control.DropDown
        ReduceTab                     matlab.ui.container.Tab
        pMenuScaleAggregate           matlab.ui.control.DropDown
        t_3                           matlab.ui.control.Label
        pMenuScaleSlideBy             matlab.ui.control.DropDown
        t_2                           matlab.ui.control.Label
        editScaleRange                matlab.ui.control.EditField
        pMenuScale                    matlab.ui.control.DropDown
        t                             matlab.ui.control.Label
        ViewReduce                    matlab.ui.control.Button
        AddNoiseTab_2                 matlab.ui.container.Tab
        NoteYoucansavethe5minutesNoisefileLabel  matlab.ui.control.Label
        editNoiseFileName             matlab.ui.control.EditField
        NoiseFileNameLabel            matlab.ui.control.Label
        pbSaveNoiseFile               matlab.ui.control.Button
        pbPreviewAddNoise             matlab.ui.control.Button
        NoisewaveAmplitudeLabel       matlab.ui.control.Label
        editNoiseBlendingCoeff        matlab.ui.control.EditField
        editWaveAmplitude             matlab.ui.control.EditField
        editNoiseWaveFrequecy         matlab.ui.control.EditField
        NoisewaveFrequencyLabel       matlab.ui.control.Label
        BlendingCoefficientLabel      matlab.ui.control.Label
        pMenuNoiseType                matlab.ui.control.DropDown
        NoiseTypeLabel                matlab.ui.control.Label
        CutfilesTab                   matlab.ui.container.Tab
        txtSampleR_19                 matlab.ui.control.Label
        ViewSegmentationButton        matlab.ui.control.Button
        txtSampleR_20                 matlab.ui.control.Label
        editCutFile_Overlap           matlab.ui.control.EditField
        pMenuDivisionType             matlab.ui.control.DropDown
        editDivision                  matlab.ui.control.EditField
        txtSampleR_18                 matlab.ui.control.Label
        editCutFile_End               matlab.ui.control.EditField
        txtSampleR_17                 matlab.ui.control.Label
        editCutFile_Init              matlab.ui.control.EditField
        tbResampling                  matlab.ui.container.Tab
        editSampleFS_DM               matlab.ui.control.EditField
        txtSampleR_23                 matlab.ui.control.Label
        editCutOffRatio               matlab.ui.control.EditField
        txtCutOffRatio                matlab.ui.control.Label
        chkEndpointEffect             matlab.ui.control.CheckBox
        text117_7                     matlab.ui.control.Label
        pMenuInterpolation_DM         matlab.ui.control.DropDown
        chkResamplingFreq             matlab.ui.control.CheckBox
        editResamplingFreq            matlab.ui.control.EditField
        chkSaveTimeStamp              matlab.ui.control.CheckBox
        editFileLocation_RS           matlab.ui.control.EditField
        text45_5                      matlab.ui.control.Label
        text117_5                     matlab.ui.control.Label
        editResample_sufix            matlab.ui.control.EditField
        text117_4                     matlab.ui.control.Label
        editResample_prefix           matlab.ui.control.EditField
        pbApplySave_RS                matlab.ui.control.Button
        pbViewRS                      matlab.ui.control.Button
        text117_3                     matlab.ui.control.Label
        pMenuTimeType                 matlab.ui.control.DropDown
        pMenuFileNames_RS             matlab.ui.control.DropDown
        text117_2                     matlab.ui.control.Label
        pbLoadData_RS                 matlab.ui.control.Button
        pMenuFileExtention_RS         matlab.ui.control.DropDown
        text45_4                      matlab.ui.control.Label
        SaveCGImages                  matlab.ui.control.Button
        txtSampleR_22                 matlab.ui.control.Label
        editCG_ImageName              matlab.ui.control.EditField
        PanelChk                      matlab.ui.container.Panel
        TestParameter                 matlab.ui.container.Tab
        pMenuRunMode                  matlab.ui.control.DropDown
        pbRunExperiment               matlab.ui.control.Button
        TabGroupTestParameters        matlab.ui.container.TabGroup
        PlotsTab                      matlab.ui.container.Tab
        tableNoEvent                  matlab.ui.control.Table
        axisNoEvent                   matlab.ui.control.UIAxes
        Stationarity_And_NonlinearityTab  matlab.ui.container.Tab
        uiPanelRATest                 matlab.ui.container.Panel
        txtReverseAMethod_T1          matlab.ui.control.Label
        pMenuRAMethod                 matlab.ui.control.DropDown
        text177                       matlab.ui.control.Label
        uiPanelAutoCorrelation        matlab.ui.container.Panel
        editAutoCorrNumLags_T1        matlab.ui.control.EditField
        TextAreaTestInfo              matlab.ui.control.TextArea
        editAutoCorrNumLags           matlab.ui.control.EditField
        text243                       matlab.ui.control.Label
        uiPanelMWindow                matlab.ui.container.Panel
        editMWindowT2                 matlab.ui.control.EditField
        text164                       matlab.ui.control.Label
        editMWindowT2_T1              matlab.ui.control.EditField
        editMWindowT1                 matlab.ui.control.EditField
        text163                       matlab.ui.control.Label
        editMWindowT1_T1              matlab.ui.control.EditField
        editMWindowM                  matlab.ui.control.EditField
        text162                       matlab.ui.control.Label
        editMWindowM_T1               matlab.ui.control.EditField
        uiPanelAutoCov                matlab.ui.container.Panel
        editAutoCovLagK               matlab.ui.control.EditField
        text13                        matlab.ui.control.Label
        editAutoCovLagK_T1            matlab.ui.control.EditField
        Normality_TestsTab            matlab.ui.container.Tab
        uiPanelContCM                 matlab.ui.container.Panel
        pMenuCM_Methods               matlab.ui.control.DropDown
        text72_4                      matlab.ui.control.Label
        text23_2                      matlab.ui.control.Label
        text21_4                      matlab.ui.control.Label
        editCCM_DistSS                matlab.ui.control.EditField
        editCCM_Max                   matlab.ui.control.EditField
        editCCM_Min                   matlab.ui.control.EditField
        editCCM_DistSS_T1             matlab.ui.control.EditField
        editCCM_Max_T1                matlab.ui.control.EditField
        editCCM_Min_T1                matlab.ui.control.EditField
        text22_4                      matlab.ui.control.Label
        uiPanelSW                     matlab.ui.container.Panel
        pMenuSW_Measures              matlab.ui.control.DropDown
        text72_3                      matlab.ui.control.Label
        editSW_P_T1                   matlab.ui.control.EditField
        editSW_P                      matlab.ui.control.EditField
        text72_2                      matlab.ui.control.Label
        uiPanelKS                     matlab.ui.container.Panel
        editKSLevelOfSig_T1           matlab.ui.control.EditField
        editKSLevelOfSig              matlab.ui.control.EditField
        text72                        matlab.ui.control.Label
        Conditional_EntropiesTab      matlab.ui.container.Tab
        uiPanelApEnLw                 matlab.ui.container.Panel
        text158_4                     matlab.ui.control.Label
        editApLWEnR                   matlab.ui.control.EditField
        editApLWEnM                   matlab.ui.control.EditField
        editApLWEnR_T1                matlab.ui.control.EditField
        editApLWEnM_T1                matlab.ui.control.EditField
        text157_2                     matlab.ui.control.Label
        uiPanelAvgSampleEn_Profile    matlab.ui.container.Panel
        editAvgSampEn_Profile_M       matlab.ui.control.EditField
        text190_3                     matlab.ui.control.Label
        editAvgSampEn_Profile_M_T1    matlab.ui.control.EditField
        uiPanelAvgApEn_Profile        matlab.ui.container.Panel
        editAvgApEn_Profile_M         matlab.ui.control.EditField
        text190_2                     matlab.ui.control.Label
        editAvgApEn_Profile_M_T1      matlab.ui.control.EditField
        uipanelFE_CAFE                matlab.ui.container.Panel
        chkFE_CAFE_Type               matlab.ui.control.CheckBox
        editFE_CAFE_P                 matlab.ui.control.EditField
        text183_2                     matlab.ui.control.Label
        text186_2                     matlab.ui.control.Label
        editFE_CAFE_R                 matlab.ui.control.EditField
        chkFE_CAFE_Centering          matlab.ui.control.CheckBox
        pMenuFE_CAFE_Type             matlab.ui.control.DropDown
        editFE_CAFE_R_T1              matlab.ui.control.EditField
        pMenuFE_CAFE_Centering        matlab.ui.control.DropDown
        editFE_CAFE_M                 matlab.ui.control.EditField
        Local_2                       matlab.ui.control.Label
        text184_2                     matlab.ui.control.Label
        text182_2                     matlab.ui.control.Label
        editFE_CAFE_P_T1              matlab.ui.control.EditField
        editFE_CAFE_M_T1              matlab.ui.control.EditField
        uiPanelFixedSampEn            matlab.ui.container.Panel
        text158_3                     matlab.ui.control.Label
        editFSampEn_nWindow           matlab.ui.control.EditField
        editFSampEn_nWindow_T1        matlab.ui.control.EditField
        text158_2                     matlab.ui.control.Label
        editFSampEn_fNumStep          matlab.ui.control.EditField
        editFSampEn_fNumStep_T1       matlab.ui.control.EditField
        text21_3                      matlab.ui.control.Label
        editFSampEn_R                 matlab.ui.control.EditField
        editFSampEn_M                 matlab.ui.control.EditField
        editFSampEn_R_T1              matlab.ui.control.EditField
        editFSampEn_M_T1              matlab.ui.control.EditField
        text22_3                      matlab.ui.control.Label
        uiPanelComplexityIndex        matlab.ui.container.Panel
        text204_2                     matlab.ui.control.Label
        text203_2                     matlab.ui.control.Label
        editCI_Tau                    matlab.ui.control.EditField
        editCI_R                      matlab.ui.control.EditField
        editCI_M                      matlab.ui.control.EditField
        editCI_Tau_T1                 matlab.ui.control.EditField
        editCI_R_T1                   matlab.ui.control.EditField
        editCI_M_T1                   matlab.ui.control.EditField
        text202_2                     matlab.ui.control.Label
        uiPanelSEntropy_RH            matlab.ui.container.Panel
        text21_2                      matlab.ui.control.Label
        editSE_RH_R                   matlab.ui.control.EditField
        editSE_RH_M                   matlab.ui.control.EditField
        editSE_RH_R_T1                matlab.ui.control.EditField
        editSE_RH_M_T1                matlab.ui.control.EditField
        text22_2                      matlab.ui.control.Label
        uiPanelCorrectedCE            matlab.ui.container.Panel
        editCorrectedCE_M             matlab.ui.control.EditField
        text190                       matlab.ui.control.Label
        editCorrectedCE_NumInt        matlab.ui.control.EditField
        editCorrectedCE_NumInt_T1     matlab.ui.control.EditField
        editCorrectedCE_M_T1          matlab.ui.control.EditField
        text189                       matlab.ui.control.Label
        uiPanelMSE                    matlab.ui.container.Panel
        text204                       matlab.ui.control.Label
        text203                       matlab.ui.control.Label
        editMSE_Tau                   matlab.ui.control.EditField
        editMSE_R                     matlab.ui.control.EditField
        editMSE_M                     matlab.ui.control.EditField
        editMSE_Tau_T1                matlab.ui.control.EditField
        editMSE_R_T1                  matlab.ui.control.EditField
        editMSE_M_T1                  matlab.ui.control.EditField
        text202                       matlab.ui.control.Label
        uiPanelConditionalEn          matlab.ui.container.Panel
        text188                       matlab.ui.control.Label
        editCE_NumInt                 matlab.ui.control.EditField
        editCE_M                      matlab.ui.control.EditField
        editCE_NumInt_T1              matlab.ui.control.EditField
        editCE_M_T1                   matlab.ui.control.EditField
        text187                       matlab.ui.control.Label
        uiPanelApEn                   matlab.ui.container.Panel
        text158                       matlab.ui.control.Label
        editApEnR                     matlab.ui.control.EditField
        editApEnM                     matlab.ui.control.EditField
        editApEnR_T1                  matlab.ui.control.EditField
        editApEnM_T1                  matlab.ui.control.EditField
        text157                       matlab.ui.control.Label
        uipanelFE                     matlab.ui.container.Panel
        chkFE_L                       matlab.ui.control.CheckBox
        editFE_Tau                    matlab.ui.control.EditField
        text183                       matlab.ui.control.Label
        text186                       matlab.ui.control.Label
        editFE_T                      matlab.ui.control.EditField
        chkFE_MF                      matlab.ui.control.CheckBox
        pMenuFE_L                     matlab.ui.control.DropDown
        editFE_T_T1                   matlab.ui.control.EditField
        pMenuFE_MF                    matlab.ui.control.DropDown
        editFE_M                      matlab.ui.control.EditField
        Local                         matlab.ui.control.Label
        text184                       matlab.ui.control.Label
        text182                       matlab.ui.control.Label
        editFE_Tau_T1                 matlab.ui.control.EditField
        editFE_M_T1                   matlab.ui.control.EditField
        uiPanelCosEn                  matlab.ui.container.Panel
        editCosEn_TAU_T1              matlab.ui.control.EditField
        editCosEn_M_T1                matlab.ui.control.EditField
        editCosEn_cosR_T1             matlab.ui.control.EditField
        editCosEn_TAU                 matlab.ui.control.EditField
        text115                       matlab.ui.control.Label
        editCosEn_M                   matlab.ui.control.EditField
        text114                       matlab.ui.control.Label
        editCosEn_cosR                matlab.ui.control.EditField
        text1001                      matlab.ui.control.Label
        uiPanelSEntropy1              matlab.ui.container.Panel
        text23                        matlab.ui.control.Label
        text21                        matlab.ui.control.Label
        editSE_TAU                    matlab.ui.control.EditField
        editSE_R                      matlab.ui.control.EditField
        editSE_M                      matlab.ui.control.EditField
        editSE_TAU_T1                 matlab.ui.control.EditField
        editSE_R_T1                   matlab.ui.control.EditField
        editSE_M_T1                   matlab.ui.control.EditField
        text22                        matlab.ui.control.Label
        uiPanelAncillaryMethods       matlab.ui.container.Tab
        uiPanelCPEI                   matlab.ui.container.Panel
        chkUseIntraQuarderRange       matlab.ui.control.CheckBox
        text216_5                     matlab.ui.control.Label
        editCPEI_epz_T1               matlab.ui.control.EditField
        editCPEI_epz                  matlab.ui.control.EditField
        uiPanelAFN                    matlab.ui.container.Panel
        editAFN_TMax_T1               matlab.ui.control.EditField
        text225_2                     matlab.ui.control.Label
        editAFN_TMax                  matlab.ui.control.EditField
        txt300_4                      matlab.ui.control.Label
        editAFN_BinSize_T1            matlab.ui.control.EditField
        editAFN_BinSize               matlab.ui.control.EditField
        uiPanelMI                     matlab.ui.container.Panel
        editMI_TMax_T1                matlab.ui.control.EditField
        text225                       matlab.ui.control.Label
        editMI_TMax                   matlab.ui.control.EditField
        txt300                        matlab.ui.control.Label
        editMI_Partitions_T1          matlab.ui.control.EditField
        editMI_Partitions             matlab.ui.control.EditField
        uiPanelFracFNN                matlab.ui.container.Panel
        text222_2                     matlab.ui.control.Label
        text223                       matlab.ui.control.Label
        editFracFNN_Rt_T1             matlab.ui.control.EditField
        editFracFNN_Rt                matlab.ui.control.EditField
        text222                       matlab.ui.control.Label
        editFracFNN_Tau_T1            matlab.ui.control.EditField
        editFracFNN_Tau               matlab.ui.control.EditField
        text220                       matlab.ui.control.Label
        editFracFNN_MaxDim            matlab.ui.control.EditField
        editFracFNN_MinDim            matlab.ui.control.EditField
        uiPanelOtherE                 matlab.ui.container.Tab
        uiPanelCompositMSE            matlab.ui.container.Panel
        txt300_8                      matlab.ui.control.Label
        editCMSE_scale_T1             matlab.ui.control.EditField
        editCMSE_scale                matlab.ui.control.EditField
        uiPanelModifiedMSE            matlab.ui.container.Panel
        txt300_7                      matlab.ui.control.Label
        editMMSE_scale_T1             matlab.ui.control.EditField
        editMMSE_scale                matlab.ui.control.EditField
        uiPanelMScalsePhaseEntropy    matlab.ui.container.Panel
        txt300_6                      matlab.ui.control.Label
        editMPhEnScale_T1             matlab.ui.control.EditField
        editMPhEnScale                matlab.ui.control.EditField
        txt300_5                      matlab.ui.control.Label
        editMPhEnK_T1                 matlab.ui.control.EditField
        editMPhEnK                    matlab.ui.control.EditField
        uiPanelDistributionEn         matlab.ui.container.Panel
        text231                       matlab.ui.control.Label
        editDE_B                      matlab.ui.control.EditField
        editDE_B_T1                   matlab.ui.control.EditField
        editDE_M                      matlab.ui.control.EditField
        text228                       matlab.ui.control.Label
        editDE_Tau                    matlab.ui.control.EditField
        text227                       matlab.ui.control.Label
        editDE_Tau_T1                 matlab.ui.control.EditField
        editDE_M_T1                   matlab.ui.control.EditField
        uiPanelPhaseEntropy           matlab.ui.container.Panel
        txt300_2                      matlab.ui.control.Label
        editPhEnK_T1                  matlab.ui.control.EditField
        editPhEnK                     matlab.ui.control.EditField
        uiPanelSlopeEn                matlab.ui.container.Panel
        text143                       matlab.ui.control.Label
        text142                       matlab.ui.control.Label
        editSlopeEnDelta              matlab.ui.control.EditField
        editSlopeEnGamma              matlab.ui.control.EditField
        editSlopeEnM                  matlab.ui.control.EditField
        text140                       matlab.ui.control.Label
        editSlopeEnDelta_T1           matlab.ui.control.EditField
        editSlopeEnGamma_T1           matlab.ui.control.EditField
        editSlopeEnM_T1               matlab.ui.control.EditField
        uiPanelBubbleEn               matlab.ui.container.Panel
        editBubbleEnM                 matlab.ui.control.EditField
        text145                       matlab.ui.control.Label
        editBubbleEnM_T1              matlab.ui.control.EditField
        uiPanelRCMSESigma             matlab.ui.container.Panel
        editRCMSEstdM                 matlab.ui.control.EditField
        text139                       matlab.ui.control.Label
        text138                       matlab.ui.control.Label
        text136                       matlab.ui.control.Label
        editRCMSEstdScale             matlab.ui.control.EditField
        editRCMSEstdTau               matlab.ui.control.EditField
        editRCMSEstdR                 matlab.ui.control.EditField
        text135                       matlab.ui.control.Label
        editRCMSEstdScale_T1          matlab.ui.control.EditField
        editRCMSEstdTau_T1            matlab.ui.control.EditField
        editRCMSEstdR_T1              matlab.ui.control.EditField
        editRCMSEstdM_T1              matlab.ui.control.EditField
        uiPanelRCMFESigma             matlab.ui.container.Panel
        text87                        matlab.ui.control.Label
        text86                        matlab.ui.control.Label
        editRCMFEstdN                 matlab.ui.control.EditField
        text44                        matlab.ui.control.Label
        text28                        matlab.ui.control.Label
        editRCMFEstdScale             matlab.ui.control.EditField
        editRCMFEstdTau               matlab.ui.control.EditField
        editRCMFEstdR                 matlab.ui.control.EditField
        editRCMFEstdM                 matlab.ui.control.EditField
        text116                       matlab.ui.control.Label
        editRCMFEstdN_T1              matlab.ui.control.EditField
        editRCMFEstdScale_T1          matlab.ui.control.EditField
        editRCMFEstdTau_T1            matlab.ui.control.EditField
        editRCMFEstdR_T1              matlab.ui.control.EditField
        editRCMFEstdM_T1              matlab.ui.control.EditField
        uiPanelRCMDE1                 matlab.ui.container.Panel
        text85                        matlab.ui.control.Label
        editRCMDE_M                   matlab.ui.control.EditField
        editRCMDE_NoC                 matlab.ui.control.EditField
        editRCMDE_Tau                 matlab.ui.control.EditField
        editRCMDE_Scale               matlab.ui.control.EditField
        text34                        matlab.ui.control.Label
        text35                        matlab.ui.control.Label
        text89                        matlab.ui.control.Label
        editRCMDE_M_T1                matlab.ui.control.EditField
        editRCMDE_NoC_T1              matlab.ui.control.EditField
        editRCMDE_Tau_T1              matlab.ui.control.EditField
        editRCMDE_Scale_T1            matlab.ui.control.EditField
        uiPanelDimensionsAndE         matlab.ui.container.Tab
        uiPanelFDNldian               matlab.ui.container.Panel
        pMenuFDNldian                 matlab.ui.control.DropDown
        text218_14                    matlab.ui.control.Label
        editFDnldianWL                matlab.ui.control.EditField
        text218_4                     matlab.ui.control.Label
        editFDnldianWL_T1             matlab.ui.control.EditField
        text217_5                     matlab.ui.control.Label
        editFDnldianWS_T1             matlab.ui.control.EditField
        editFDnldianWS                matlab.ui.control.EditField
        uiPanelFDNldwan               matlab.ui.container.Panel
        pMenuFDNldwan                 matlab.ui.control.DropDown
        text218_15                    matlab.ui.control.Label
        editFDnldwanWL                matlab.ui.control.EditField
        text218_3                     matlab.ui.control.Label
        editFDnldwanWL_T1             matlab.ui.control.EditField
        text217_4                     matlab.ui.control.Label
        editFDnldwanWS_T1             matlab.ui.control.EditField
        editFDnldwanWS                matlab.ui.control.EditField
        uiPanelHRM_Measures           matlab.ui.container.Panel
        pMenuHRM                      matlab.ui.control.DropDown
        text214_6                     matlab.ui.control.Label
        editHRM_Tau                   matlab.ui.control.EditField
        editHRM_Tau_T1                matlab.ui.control.EditField
        uiPanelMultiS_FD              matlab.ui.container.Panel
        text217_3                     matlab.ui.control.Label
        editMultiScaleFD_M            matlab.ui.control.EditField
        text218_2                     matlab.ui.control.Label
        editMultiScaleFD_M_T1         matlab.ui.control.EditField
        text217_2                     matlab.ui.control.Label
        editMultiScaleFD_scale_T1     matlab.ui.control.EditField
        editMultiScaleFD_scale        matlab.ui.control.EditField
        uiPanelAF                     matlab.ui.container.Panel
        text46_2                      matlab.ui.control.Label
        editAlanFactor_nWindow        matlab.ui.control.EditField
        editAlanFactor_nWindow_T1     matlab.ui.control.EditField
        uiPanelCCM                    matlab.ui.container.Panel
        editCCM_Lag                   matlab.ui.control.EditField
        editCCM_Lag_T1                matlab.ui.control.EditField
        text112_2                     matlab.ui.control.Label
        uiPanelLLE                    matlab.ui.container.Panel
        text213_3                     matlab.ui.control.Label
        editLLE_Maxiter               matlab.ui.control.EditField
        text214_2                     matlab.ui.control.Label
        editLLE_Maxiter_T1            matlab.ui.control.EditField
        text213_2                     matlab.ui.control.Label
        editLLE_MeanPeriod            matlab.ui.control.EditField
        editLLE_MeanPeriod_T1         matlab.ui.control.EditField
        editLLE_Tau_T1                matlab.ui.control.EditField
        editLLE_Tau                   matlab.ui.control.EditField
        editLLE_M_T1                  matlab.ui.control.EditField
        text209_2                     matlab.ui.control.Label
        editLLE_M                     matlab.ui.control.EditField
        uiPanelDFA                    matlab.ui.container.Panel
        text169_2                     matlab.ui.control.Label
        editDFAEmbededDim             matlab.ui.control.EditField
        text169                       matlab.ui.control.Label
        editDFAEmbededDim_T1          matlab.ui.control.EditField
        text168                       matlab.ui.control.Label
        editDFAOrder_T1               matlab.ui.control.EditField
        editDFAOrder                  matlab.ui.control.EditField
        uiPanelRQA                    matlab.ui.container.Panel
        textRQAOutput                 matlab.ui.control.Label
        pMenuRQAOutput                matlab.ui.control.DropDown
        text214                       matlab.ui.control.Label
        editRQA_Minline_T1            matlab.ui.control.EditField
        text213                       matlab.ui.control.Label
        editRQA_Minline               matlab.ui.control.EditField
        editRQA_R_T1                  matlab.ui.control.EditField
        text212                       matlab.ui.control.Label
        editRQA_R                     matlab.ui.control.EditField
        editRQA_Tau_T1                matlab.ui.control.EditField
        editRQA_Tau                   matlab.ui.control.EditField
        editRQA_M_T1                  matlab.ui.control.EditField
        text209                       matlab.ui.control.Label
        editRQA_M                     matlab.ui.control.EditField
        uiPanelM_LZC                  matlab.ui.container.Panel
        editMLZCScal                  matlab.ui.control.EditField
        text208                       matlab.ui.control.Label
        text207                       matlab.ui.control.Label
        uiPanelEPP                    matlab.ui.container.Panel
        editEPPLag_T1                 matlab.ui.control.EditField
        pMenuEEPOutput                matlab.ui.control.DropDown
        textEPPOutput                 matlab.ui.control.Label
        text200                       matlab.ui.control.Label
        editEPPLag                    matlab.ui.control.EditField
        uiPanelCorrDim                matlab.ui.container.Panel
        editCorrDimM                  matlab.ui.control.EditField
        text218                       matlab.ui.control.Label
        editCorrDimM_T1               matlab.ui.control.EditField
        text217                       matlab.ui.control.Label
        editCorrDimLag_T1             matlab.ui.control.EditField
        editCorrDimLag                matlab.ui.control.EditField
        uiPanelHFD                    matlab.ui.container.Panel
        text46                        matlab.ui.control.Label
        editHiguchiFD_KMax            matlab.ui.control.EditField
        editHiguchiFD_KMax_T1         matlab.ui.control.EditField
        uiPanelOrdinalE               matlab.ui.container.Tab
        uiPaneledgeEntropy            matlab.ui.container.Panel
        text80_2                      matlab.ui.control.Label
        editEdgePE_R_T1               matlab.ui.control.EditField
        editEdgePE_R                  matlab.ui.control.EditField
        editEdgePE_T                  matlab.ui.control.EditField
        text180_10                    matlab.ui.control.Label
        text78_2                      matlab.ui.control.Label
        editEdgePE_M_T1               matlab.ui.control.EditField
        editEdgePE_T_T1               matlab.ui.control.EditField
        editEdgePE_M                  matlab.ui.control.EditField
        uiPanelRenyiPE                matlab.ui.container.Panel
        editRPE_RO                    matlab.ui.control.EditField
        text170_11                    matlab.ui.control.Label
        editRPE_RO_T1                 matlab.ui.control.EditField
        text181_5                     matlab.ui.control.Label
        editRPE_M_T1                  matlab.ui.control.EditField
        editRPE_Tau_T1                matlab.ui.control.EditField
        editRPE_M                     matlab.ui.control.EditField
        editRPE_Tau                   matlab.ui.control.EditField
        text180_9                     matlab.ui.control.Label
        uiPanelPermutationTE          matlab.ui.container.Panel
        editPTE_TO                    matlab.ui.control.EditField
        text170_10                    matlab.ui.control.Label
        editPTE_TO_T1                 matlab.ui.control.EditField
        text181_4                     matlab.ui.control.Label
        editPTE_M_T1                  matlab.ui.control.EditField
        editPTE_Tau_T1                matlab.ui.control.EditField
        editPTE_M                     matlab.ui.control.EditField
        editPTE_Tau                   matlab.ui.control.EditField
        text180_8                     matlab.ui.control.Label
        uiPanelAAPE                   matlab.ui.container.Panel
        editAAPEA_T1                  matlab.ui.control.EditField
        editAAPEA                     matlab.ui.control.EditField
        text180_5                     matlab.ui.control.Label
        text181_3                     matlab.ui.control.Label
        editAAPEM_T1                  matlab.ui.control.EditField
        editAAPET_T1                  matlab.ui.control.EditField
        editAAPEM                     matlab.ui.control.EditField
        editAAPET                     matlab.ui.control.EditField
        text180_4                     matlab.ui.control.Label
        uiPanelMPme1                  matlab.ui.container.Panel
        text170_2                     matlab.ui.control.Label
        editMPeT                      matlab.ui.control.EditField
        editMPeT_T1                   matlab.ui.control.EditField
        editMPeM                      matlab.ui.control.EditField
        editMPeM_T1                   matlab.ui.control.EditField
        text94                        matlab.ui.control.Label
        uiPanelPe                     matlab.ui.container.Panel
        editPEScale                   matlab.ui.control.EditField
        text170_9                     matlab.ui.control.Label
        editPEScale_T1                matlab.ui.control.EditField
        text181                       matlab.ui.control.Label
        editPEM_T1                    matlab.ui.control.EditField
        editPET_T1                    matlab.ui.control.EditField
        editPEM                       matlab.ui.control.EditField
        editPET                       matlab.ui.control.EditField
        text180                       matlab.ui.control.Label
        uiPanelImprovedPE             matlab.ui.container.Panel
        text80                        matlab.ui.control.Label
        editImPEScale_T1              matlab.ui.control.EditField
        editImPEScale                 matlab.ui.control.EditField
        editImPET                     matlab.ui.control.EditField
        text180_3                     matlab.ui.control.Label
        text78                        matlab.ui.control.Label
        editImPEM_T1                  matlab.ui.control.EditField
        editImPET_T1                  matlab.ui.control.EditField
        editImPEM                     matlab.ui.control.EditField
        uiPanelShannnonGEntropies     matlab.ui.container.Tab
        uiPanelShannonEx              matlab.ui.container.Panel
        text216_6                     matlab.ui.control.Label
        editSEx_M_T1                  matlab.ui.control.EditField
        editSEx_NumInt_T1             matlab.ui.control.EditField
        editSEx_M                     matlab.ui.control.EditField
        editSEx_NumInt                matlab.ui.control.EditField
        text215_4                     matlab.ui.control.Label
        uiPanelToneEntropy            matlab.ui.container.Panel
        pMenuToneEntropy              matlab.ui.control.DropDown
        text180_6                     matlab.ui.control.Label
        editToneE_M                   matlab.ui.control.EditField
        editToneE_M_T1                matlab.ui.control.EditField
        uiPanelKLD                    matlab.ui.container.Panel
        text216_3                     matlab.ui.control.Label
        editKLD_M_T1                  matlab.ui.control.EditField
        editKLD_Shift_T1              matlab.ui.control.EditField
        editKLD_M                     matlab.ui.control.EditField
        editKLD_Shift                 matlab.ui.control.EditField
        text215_3                     matlab.ui.control.Label
        uiPanelEoD                    matlab.ui.container.Panel
        text216_2                     matlab.ui.control.Label
        editEoD_M_T1                  matlab.ui.control.EditField
        editEoD_Shift_T1              matlab.ui.control.EditField
        editEoD_M                     matlab.ui.control.EditField
        editEoD_Shift                 matlab.ui.control.EditField
        text215_2                     matlab.ui.control.Label
        uiPanelTEntropy               matlab.ui.container.Panel
        editTentropyQ                 matlab.ui.control.Label
        editTEntropyQ                 matlab.ui.control.EditField
        editTEntropyQ_T1              matlab.ui.control.EditField
        uiPanelREntropy               matlab.ui.container.Panel
        editREntropyT_T1              matlab.ui.control.EditField
        editREntropyQ                 matlab.ui.control.EditField
        editREntropyQ_T1              matlab.ui.control.EditField
        text112                       matlab.ui.control.Label
        uiPanelEntropyOfEntropy       matlab.ui.container.Panel
        text170_8                     matlab.ui.control.Label
        editEE_Max                    matlab.ui.control.EditField
        editEE_Max_T1                 matlab.ui.control.EditField
        text170_7                     matlab.ui.control.Label
        editEE_Min                    matlab.ui.control.EditField
        editEE_Scale                  matlab.ui.control.EditField
        text170_6                     matlab.ui.control.Label
        editEE_Scale_T1               matlab.ui.control.EditField
        editEE_BinSize                matlab.ui.control.EditField
        editEE_BinSize_T1             matlab.ui.control.EditField
        editEE_Min_T1                 matlab.ui.control.EditField
        text94_3                      matlab.ui.control.Label
        uiPanelAverageEntropy         matlab.ui.container.Panel
        text170_5                     matlab.ui.control.Label
        editAE_Max                    matlab.ui.control.EditField
        editAE_Max_T1                 matlab.ui.control.EditField
        text170_4                     matlab.ui.control.Label
        editAE_Min                    matlab.ui.control.EditField
        editAE_Scale                  matlab.ui.control.EditField
        text170_3                     matlab.ui.control.Label
        editAE_Scale_T1               matlab.ui.control.EditField
        editAE_BinSize                matlab.ui.control.EditField
        editAE_BinSize_T1             matlab.ui.control.EditField
        editAE_Min_T1                 matlab.ui.control.EditField
        text94_2                      matlab.ui.control.Label
        uiPanelEntropyMoChan          matlab.ui.container.Panel
        text181_2                     matlab.ui.control.Label
        editMoChan_M_T1               matlab.ui.control.EditField
        editMoChan_T_T1               matlab.ui.control.EditField
        editMoChan_M                  matlab.ui.control.EditField
        editMoChan_T                  matlab.ui.control.EditField
        text180_2                     matlab.ui.control.Label
        uiPanelShannonEn              matlab.ui.container.Panel
        text216                       matlab.ui.control.Label
        editShEn_M_T1                 matlab.ui.control.EditField
        editShEn_numInt_T1            matlab.ui.control.EditField
        editShEn_M                    matlab.ui.control.EditField
        editShEn_numInt               matlab.ui.control.EditField
        text215                       matlab.ui.control.Label
        EntropyHub_EntropiesTab       matlab.ui.container.Tab
        uiPanelIncrEn                 matlab.ui.container.Panel
        text214_5                     matlab.ui.control.Label
        editIncrEN_Logx_T1            matlab.ui.control.EditField
        text213_7                     matlab.ui.control.Label
        editIncrEN_Logx               matlab.ui.control.EditField
        editIncrEN_R_T1               matlab.ui.control.EditField
        text212_4                     matlab.ui.control.Label
        editIncrEN_R                  matlab.ui.control.EditField
        editIncrEN_Tau_T1             matlab.ui.control.EditField
        editIncrEN_Tau                matlab.ui.control.EditField
        editIncrEN_M_T1               matlab.ui.control.EditField
        text209_5                     matlab.ui.control.Label
        editIncrEN_M                  matlab.ui.control.EditField
        uiPanelGridEn                 matlab.ui.container.Panel
        text214_4                     matlab.ui.control.Label
        editGridEN_Logx_T1            matlab.ui.control.EditField
        text213_6                     matlab.ui.control.Label
        editGridEN_Logx               matlab.ui.control.EditField
        editGridEN_Tau_T1             matlab.ui.control.EditField
        editGridEN_Tau                matlab.ui.control.EditField
        editGridEN_M_T1               matlab.ui.control.EditField
        text209_4                     matlab.ui.control.Label
        editGridEN_M                  matlab.ui.control.EditField
        uiPaneAttnEN                  matlab.ui.container.Panel
        editAttnE_Logx_T1             matlab.ui.control.EditField
        text213_5                     matlab.ui.control.Label
        editAttnE_Logx                matlab.ui.control.EditField
        uiPanelCosiEN                 matlab.ui.container.Panel
        text214_3                     matlab.ui.control.Label
        editCosiEN_Logx_T1            matlab.ui.control.EditField
        text213_4                     matlab.ui.control.Label
        editCosiEN_Logx               matlab.ui.control.EditField
        editCosiEN_R_T1               matlab.ui.control.EditField
        text212_2                     matlab.ui.control.Label
        editCosiEN_R                  matlab.ui.control.EditField
        editCosiEN_Tau_T1             matlab.ui.control.EditField
        editCosiEN_Tau                matlab.ui.control.EditField
        editCosiEN_M_T1               matlab.ui.control.EditField
        text209_3                     matlab.ui.control.Label
        editCosiEN_M                  matlab.ui.control.EditField
        Time_Frequency_DomainTab      matlab.ui.container.Tab
        Other_Complexity_MeasuresTab  matlab.ui.container.Tab
        uiPanelEDD_Kalauzi            matlab.ui.container.Panel
        pbPlotTest_5                  matlab.ui.control.Button
        pbPlotTest_4                  matlab.ui.control.Button
        editK_EDD_order               matlab.ui.control.EditField
        text218_5                     matlab.ui.control.Label
        editK_EDD_order_T1            matlab.ui.control.EditField
        text217_6                     matlab.ui.control.Label
        editK_EDD_WS_T1               matlab.ui.control.EditField
        editK_EDD_WS                  matlab.ui.control.EditField
        uiPanelPJSC                   matlab.ui.container.Panel
        text180_7                     matlab.ui.control.Label
        text216_4                     matlab.ui.control.Label
        editPJSC_M_T1                 matlab.ui.control.EditField
        editPJSC_Tau_T1               matlab.ui.control.EditField
        editPJSC_M                    matlab.ui.control.EditField
        editPJSC_Tau                  matlab.ui.control.EditField
        TestParametersPanel           matlab.ui.container.Panel
        txtInfoProcessing             matlab.ui.control.Label
        SaveDataTablePanel            matlab.ui.container.Panel
        pbSaveTableData               matlab.ui.control.Button
        editTableDataFName            matlab.ui.control.EditField
        text129_2                     matlab.ui.control.Label
        editTableDirectory            matlab.ui.control.EditField
        pbTableDirectory              matlab.ui.control.Button
        pbPlotTest                    matlab.ui.control.Button
        text129                       matlab.ui.control.Label
        editTestStepSize              matlab.ui.control.EditField
        TabBottom_ProcessResult       matlab.ui.container.Tab
        uiPanelEpochSelection         matlab.ui.container.Panel
        TextArea_2                    matlab.ui.control.TextArea
        chkSelEpoch                   matlab.ui.control.CheckBox
        editSelEpochList              matlab.ui.control.EditField
        chkAllEpoch                   matlab.ui.control.CheckBox
        chkSingleData                 matlab.ui.control.CheckBox
        Panel4                        matlab.ui.container.Panel
        pMenuViewBy                   matlab.ui.control.DropDown
        text233_2                     matlab.ui.control.Label
        chkShowError                  matlab.ui.control.CheckBox
        pMenuSaveExcelType            matlab.ui.control.DropDown
        chkSaveTime                   matlab.ui.control.CheckBox
        chkSaveAsMat                  matlab.ui.control.CheckBox
        chkSaveInSExcel               matlab.ui.control.CheckBox
        editExperimentName            matlab.ui.control.EditField
        pMenuViewType                 matlab.ui.control.DropDown
        text233                       matlab.ui.control.Label
        pbViewPlot                    matlab.ui.control.Button
        pbSaveResults                 matlab.ui.control.Button
        pbResultDirectory             matlab.ui.control.Button
        text232                       matlab.ui.control.Label
        editResultDir                 matlab.ui.control.EditField
        UIAxesViewResults             matlab.ui.control.UIAxes
        AnalysisTab                   matlab.ui.container.Tab
        text176_2                     matlab.ui.control.Label
        ListBox                       matlab.ui.control.ListBox
        ListBoxLabel                  matlab.ui.control.Label
        TG_AnalysisDown               matlab.ui.container.TabGroup
        GPP_Kalauzi_SymmetricTab      matlab.ui.container.Tab
        txtNAI                        matlab.ui.control.Label
        text218_16                    matlab.ui.control.Label
        text218_8                     matlab.ui.control.Label
        editDataA_GPP_step            matlab.ui.control.EditField
        GPPTypeButtonGroup            matlab.ui.container.ButtonGroup
        rbDA_GPP_Asym                 matlab.ui.control.RadioButton
        rbDA_GPP_Sym                  matlab.ui.control.RadioButton
        pbViewPlot_GPP                matlab.ui.control.Button
        editDataA_GPP_minOrder        matlab.ui.control.EditField
        text218_7                     matlab.ui.control.Label
        text217_8                     matlab.ui.control.Label
        editDataA_GPP_maxOrder        matlab.ui.control.EditField
        FDNldianTab                   matlab.ui.container.Tab
        FDTypeButtonGroup             matlab.ui.container.ButtonGroup
        rdFDTypeNldian                matlab.ui.control.RadioButton
        rdFDTypeNldwan                matlab.ui.control.RadioButton
        pbPlotTest_6                  matlab.ui.control.Button
        editDataA_FDnldianWL          matlab.ui.control.EditField
        text218_6                     matlab.ui.control.Label
        text217_7                     matlab.ui.control.Label
        editDataA_FDnldianWS          matlab.ui.control.EditField
        DiffusiuonEntropyTab          matlab.ui.container.Tab
        pbPlotTest_7                  matlab.ui.control.Button
        editDsNumberE                 matlab.ui.control.EditField
        text217_9                     matlab.ui.control.Label
        DetrendTab                    matlab.ui.container.Tab
        editSufix_Detrend             matlab.ui.control.EditField
        text217_13                    matlab.ui.control.Label
        editPrefix_Detrend            matlab.ui.control.EditField
        text217_12                    matlab.ui.control.Label
        pbApplySave_Detrend           matlab.ui.control.Button
        text217_11                    matlab.ui.control.Label
        pbViewDetrend                 matlab.ui.control.Button
        editDegreePolynomial          matlab.ui.control.EditField
        text217_10                    matlab.ui.control.Label
        TabGroup3                     matlab.ui.container.TabGroup
        tabDA_Fig1                    matlab.ui.container.Tab
        panelDA_fig1                  matlab.ui.container.Panel
        tabDA_Data1                   matlab.ui.container.Tab
        pbSaveDA_Data1                matlab.ui.control.Button
        text218_10                    matlab.ui.control.Label
        editDA_File2                  matlab.ui.control.EditField
        uiTable_DA1                   matlab.ui.control.Table
        tabDA_Data2                   matlab.ui.container.Tab
        pbSaveDA_Data2                matlab.ui.control.Button
        text218_11                    matlab.ui.control.Label
        editDA_File3                  matlab.ui.control.EditField
        uiTable_DA2                   matlab.ui.control.Table
        tabDA_Fig2                    matlab.ui.container.Tab
        panelDA_fig2                  matlab.ui.container.Panel
        tabDA_Data3                   matlab.ui.container.Tab
        pbSaveDA_Data3                matlab.ui.control.Button
        text218_13                    matlab.ui.control.Label
        editDA_File5                  matlab.ui.control.EditField
        uiTable_DA3                   matlab.ui.control.Table
        tabDA_Fig3                    matlab.ui.container.Tab
        panelDA_fig3                  matlab.ui.container.Panel
        uipanel2                      matlab.ui.container.Panel
        btmOpenHelpFile               matlab.ui.control.Button
        pMenuHelp                     matlab.ui.control.DropDown
        DataTypeButtonGroup           matlab.ui.container.ButtonGroup
        NonTimeSeriesButton           matlab.ui.control.RadioButton
        TimeSeriesButton              matlab.ui.control.RadioButton
        editSampleR                   matlab.ui.control.EditField
        txtSampleR                    matlab.ui.control.Label
        editEpochT                    matlab.ui.control.EditField
        txtEpochT                     matlab.ui.control.Label
        pMenuAppMode                  matlab.ui.control.DropDown
        text176                       matlab.ui.control.Label
        UIAxesCG_Original             matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        Menu_2                        matlab.ui.container.Menu
        Menu2_2                       matlab.ui.container.Menu
        ContextMenu3                  matlab.ui.container.ContextMenu
        Menu_3                        matlab.ui.container.Menu
        Menu2_3                       matlab.ui.container.Menu
        ContextMenu4                  matlab.ui.container.ContextMenu
        Menu_4                        matlab.ui.container.Menu
        Menu2_4                       matlab.ui.container.Menu
        ContextMenu5                  matlab.ui.container.ContextMenu
        Menu_5                        matlab.ui.container.Menu
        Menu2_5                       matlab.ui.container.Menu
        ContextMenu6                  matlab.ui.container.ContextMenu
        Menu_6                        matlab.ui.container.Menu
        Menu2_6                       matlab.ui.container.Menu
        ContextMenu7                  matlab.ui.container.ContextMenu
        Menu_7                        matlab.ui.container.Menu
        Menu2_7                       matlab.ui.container.Menu
        ContextMenu8                  matlab.ui.container.ContextMenu
        Menu_8                        matlab.ui.container.Menu
        Menu2_8                       matlab.ui.container.Menu
        ContextMenu9                  matlab.ui.container.ContextMenu
        Menu_9                        matlab.ui.container.Menu
        Menu2_9                       matlab.ui.container.Menu
    end


    methods (Access = private)


        function checkUnCheckAllStatistic(app, chkVal)

            staticData = app.uitableSMeasures.Data;

            %staticData(:,2)={chkVal==1}
            mc = ?STATISTIC_INDEX;

            %% total number of statitistics we going to calculate
            totalNumStatistics = size(mc.PropertyList,1);
            %d={};

            for i=1:totalNumStatistics
                %d{i,1}=mc.PropertyList(i).Name;
                staticData{i,2}=chkVal==1;
                if mc.PropertyList(i).DefaultValue==-1
                    staticData{i,2}='';
                end

                if ~STATISTIC_INDEX.isImplemented(i)
                    staticData{i,2}=false;
                end

            end


            %              if data{STATISTIC_INDEX.AutoCorrelation,2}== true && ~ UTIL_GUI.chkDependency('Econometrics')
            %                 data{STATISTIC_INDEX.AutoCorrelation,2}= false;
            %                 app.uitableSMeasures.Data = data;
            %
            %              end


            %              if (staticData{STATISTIC_INDEX.ShannonEntropy_SE,2}== true || staticData{STATISTIC_INDEX.CorrectedConditionalEntropy_CCE,2}== true || staticData{STATISTIC_INDEX.ConditionalEntropy_CE,2}== true)  && ~ UTIL_GUI.chkDependency('Communications')
            %                 staticData{STATISTIC_INDEX.ShannonEntropy_SE,2}= false;
            %                 staticData{STATISTIC_INDEX.CorrectedConditionalEntropy_CCE,2}= false;
            %                 staticData{STATISTIC_INDEX.ConditionalEntropy_CE,2}= false;
            %
            %
            %               end


            %              if staticData{STATISTIC_INDEX.SpectralEntropy_SpEn,2}== true && ~ UTIL_GUI.chkDependency('Signal Processing')
            %                 staticData{STATISTIC_INDEX.SpectralEntropy_SpEn,2}= false;
            %
            %              end

            %              if staticData{STATISTIC_INDEX.CorrDim_D2,2}== true && ~ UTIL_GUI.chkDependency('Predictive Maintenance')
            %                 staticData{STATISTIC_INDEX.CorrDim_D2,2}= false;
            %
            %              end


            app.uitableSMeasures.Data = staticData;

        end

        function chkDontROuliers_Callback(app, hObject, eventdata, handles)
            % hObject    handle to chkDontROuliers (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of chkDontROuliers
            app.chkROutlierPerE.Value = 0;
            app.chkDontROuliers.Value = 1;
            app.chkROutlierPerD.Value = 0;
        end

        function chkROutlierPerD_Callback(app, hObject, eventdata, handles)
            % hObject    handle to chkROutlierPerD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.chkROutlierPerE.Value = 0;
            app.chkROutlierPerD.Value = 1;
            app.chkRemoveOutliers.Value = 0;
        end

        function chkROutlierPerE_Callback(app, hObject, eventdata, handles)
            % hObject    handle to chkROutlierPerE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of chkROutlierPerE
            app.chkROutlierPerE.Value = 1;
            app.chkROutlierPerD.Value = 0;
            app.chkRemoveOutliers.Value = 0;
        end

        %

        %{
        You may achieve this functionality using the 'SelectionChangedFcn'
        property of the 'uitabgroup'.
        The following code provides a simple example of this functionality:
        %}
        %tabgroup = f.Children;
        %tabgroup.SelectionChangedFcn = @doNothing;
        %         function doNothing(tabgroup, eventData)
        %             tabgroup.SelectedTab = eventData.OldValue;
        %         end


        function edit154_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit154 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit155_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit155 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit157_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit157 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit215_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editMPeScale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit216_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit216 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit217_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit217 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit231_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit231 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit234_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit234 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit250_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit250 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit251_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit251 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit252_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit252 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit253_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit253 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit254_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit254 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit255_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit255 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit304_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit304 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit97_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit97 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit98_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit98 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function edit99_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit99 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editDbFileExtension_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editDbFileExtension (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editDimensionNo_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editDimensionNo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editEpochNo_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editEpochNo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editExpVersion_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editExpVersion (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editFNNMaxM_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editFNNMaxM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editFNNMaxM_T1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editFNNMaxM_T1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editFileNo_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editFileNo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editHigunchiFD_KMax_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editHiguchiFD_KMax (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editMPe2M_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editMPe2M (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editMPe2T_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editMPe2T (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editNumDir_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editNumDir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editPe2M_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editPe2M (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editPe2Scale_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editPe2Scale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editPe2T_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editPe2T (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editPe3M_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editPe3M (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editPe3Scale_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editPe3Scale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editPe3T_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editPe3T (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editProgressInfoExcel_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editProgressInfoExcel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editProgressInfo_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editProgressInfo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editRCMDE_2M_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editRCMDE_2M (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end

        end

        function editRCMDE_2NoC_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editRCMDE_2NoC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editRCMDE_2Scale_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editRCMDE_2Scale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editRCMDE_2Tau_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editRCMDE_2Tau (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editSE_2M_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editSE_2M (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editSE_2R_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editSE_2R (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editSE_2TAU_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editSE_2TAU (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editScaleNo_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editScaleNo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editTObservationT_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editTObservationT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editTObservationTime_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editTObservationTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editTestScaleIDFrom_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editTestScaleIDFrom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function editTestScaleIDTo_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to editTestScaleIDTo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: editTestEpochIDFrom controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end


        %%deepak001


        %         function pbLoadParamater_Callback(app, hObject, eventdata, handles)
        %             % hObject    handle to pbLoadParamater (see GCBO)
        %             % eventdata  reserved - to be defined in a future version of MATLAB
        %             % handles    structure with handles and user data (see GUIDATA)
        %             UTIL_GUI.loadParameters(app);
        %         end

        function pbProcessData_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to pbProcessData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function pbRunTest_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pbRunTest (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pbRunExperiment_Callback(hObject, eventdata, handles);
        end


        function popupmenu24_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu24 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu25_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu25 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function popupmenu26_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu26 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function pushbutton6_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            getPedroNonLinearity(data);
        end

        function rbPlotMean_Callback(app, hObject, eventdata, handles)
            % hObject    handle to rbPlotMean (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of rbPlotMean

            app.rbPlotStd.Value = 0;
            app.rbPlotMedian.Value = 0;
            app.rbPlotMean.Value = 1;
        end

        function rbPlotMedian_Callback(app, hObject, eventdata, handles)
            % hObject    handle to rbPlotMedian (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of rbPlotMedian
            app.rbPlotStd.Value = 0;
            app.rbPlotMedian.Value = 1;
            app.rbPlotMean.Value = 0;
        end

        function rbPlotResults_Callback(app, hObject, eventdata, handles)
            % hObject    handle to rbPlotResults (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of rbPlotResults

            app.rbSaveInSExcel.Value = 0;
            app.rbDonotSaveInExcel.Value = 0;
            % app.uiPanelPlotOptions..Visible = 'on')
        end

        function rbPlotStd_Callback(app, hObject, eventdata, handles)
            % hObject    handle to rbPlotStd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of rbPlotStd
            app.rbPlotStd.Value = 1;
            app.rbPlotMedian.Value = 0;
            app.rbPlotMean.Value = 0;
        end

        function [OutlierInfo, data, currData] = removeOutlierEpoch(app,  data, currData, currSIndex, endIndex, OutlierInfo)

            %% need to redefine getOutliersListTF for multi dimensional array

            if app.chkROutlierPerE.Value ==1

                addpath('../com/algorithms/');
                OutlierList = getOutliersListTF(currData)+currSIndex-1;
                OutlierList=[OutlierList nan(size(OutlierList))];
                outerMWidth = str2num(app.editOutlierMW.Value);
                for oIndex=OutlierList(:,1)'
                    lIndex = max(1,oIndex-outerMWidth);
                    uIndex = min(size(data,1),oIndex+outerMWidth);
                    try
                        replaceVal = median([data(lIndex:oIndex-1,1); data(oIndex+1:uIndex,1)]);
                    catch
                        x=1;
                    end
                    try
                        OutlierInfo=[OutlierInfo;oIndex data(oIndex,1) replaceVal];
                    catch
                        msgbox('Data index running out of data range.....');
                    end
                    data(oIndex,1)=replaceVal;
                    %OutlierList(oIndex,2)=replaceVal;

                end

            end
            currData = data(currSIndex:endIndex,:);
        end


        function setParamTestCase(app)
            msgbox('Running in Test mode. Select only ONE measure from list.');
            mc = ?STATISTIC_INDEX;
            mcTest = ?STATISTIC_INDEX_TEST;
            %% total number of statitistics we going to calculate
            totalNumStatistics = size(mc.PropertyList,1);
            totalTestStat = size(mcTest.PropertyList,1);
            staticData = app.uitableSMeasures.Data;
            % if TRUE_FALSE==false
            %     for i=1:totalNumStatistics
            %         staticData{i,2}=false;
            %         if mc.PropertyList(i).DefaultValue==-1
            %             staticData{i,2}='';
            %         end
            %     end
            %
            % else

            for i=1:totalNumStatistics
                staticData{i,2}='';

            end


            for i=1:totalTestStat
                try
                    staticData{ mcTest.PropertyList(i).DefaultValue,2}=false;
                catch
                    x=1;
                end
            end
            % end

            app.uitableSMeasures.Data = staticData;
            UTIL_GUI.setPanelsVisibility(app);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ProcessData_OpeningFcn(app)
            %             % This function has no output args, see OutputFcn.
            %             % hObject    handle to figure
            %             % eventdata  reserved - to be defined in a future version of MATLAB
            %             % handles    structure with handles and user data (see GUIDATA)
            %             % varargin   command line arguments to ProcessData (see VARARGIN)
            %
            %             % Choose default command line output for ProcessData


            % move to CEPS/scripts
            global dbFileParam
            try
                currPath = pwd;
                currPath = strsplit(currPath,'CEPS');
                currPath=strcat(currPath{1},'CEPS/scripts');
                cd(currPath);
                clear currPath
            catch
            end
            drawnow limitrate
            %             leftLocDown =[];
            %             rightLocDown =[];
            %
            %             showRaw1 = false;
            %             showTruncated1 = false;
            %             showProcessed1 = false;
            %
            %             showRaw2 = false;
            %             showTruncated2 = false;
            %             showProcessed2 = false;

            %   app.output = hObject;
            addpath('../com/Constants');
            addpath('../com/utils');
            %addpath('../com/algorithms/')
            %addpath('../com/ExternalPackages/DataShare');
            addpath('../com/ExternalPackages/javaObjectHandler');
            % Update handles structure
            % guidata(hObject, handles);

            % UIWAIT makes ProcessData wait for user response (see UIRESUME)
            %uiwait(app.figure1);
            format short
            cd('../com/ExternalPackages');
            rootDoc = xml2struct('../../com/Parameters.xml');
            cd('../../scripts');
            dbFileParam=rootDoc.AllParameters.dbFileParam;
            dbFileInfo = {};
            for fIndex=1:size(dbFileParam,2)

                dbFileInfo{fIndex,1}=dbFileParam(1,fIndex).Attributes.fName;
            end
            %app.pMenuFileParam.ItemsData = dbFileInfo;


            UTIL_GUI.loadParameters(app);

            %d = {'Male',52,true;'Male',40,true;'Female',25,t};
            %uit.Data = d;
            %bgColor=cell(size(totalNumStatistics,1),1)
            %app.uitableSMeasures.reset
            %deepak001


            %%color of a cell in a table.

            %S = uistyle('BackgroundColor', 'red');
            %addStyle(app.uitableSMeasures, S, 'cell', [13 2]);

            %s = uistyle('BackgroundColor','red');
            %addStyle(app.uitableSMeasures,s,'cell',[2 2])


            app.uitableSMeasures.ColumnEditable=[STATISTIC_INDEX.getAllImplementedList()]';
            drawnow limitrate
            cla(app.myAxesUp,'reset');
            cla(app.myAxesDown,'reset');

            %% close any time bar if open
            try
                close(f);
            catch
            end

            %% save the result in current script directory by default..
            app.editResultDir.Value = pwd;
            %%app.chkROutlierPerD,'TooltipString','Select this to consider whole dataset.........')
            %warning('off','MATLAB:HandleGraphics:ObsoletedProperty:JavaFrame');
            %jframe=get(hObject,'javaframe');
            %jIcon=javax.swing.ImageIcon('../fig/CEPS_Clear_OutLine.png');
            %jframe.setFigureIcon(jIcon);

            listItems={};
            listItems{1,1}='-- All Columns --';
            listItems{2,1}='-- Range --';
            for i=1:100
                listItems{i+2,1}=num2str(i);
            end
            app.pMenuDimension.Items = listItems;
            app.pMenuDimension.Value = app.pMenuDimension.Items{3};

            listItems{1,1}='-- No Epoch --';
            listItems{2,1}='-- All Epochs --';
            listItems{3,1}='-- Range --';
            for i=1:100
                listItems{i+3,1}=num2str(i);
            end
            app.pMenuEpochs.Items = listItems;
            app.pMenuEpochs.Value = app.pMenuEpochs.Items{1};


            listItems{1,1}='-- Range --';


            for i=1:100
                listItems{i+1,1}=num2str(i);
            end
            app.pMenuScale.Items = listItems;
            app.pMenuScale.Value = app.pMenuScale.Items{1};

            UTIL_GUI.controlFlow(app,CONS_CONTROL_FLOW.loadData);

            app.TabGroupButtom.SelectedTab= app.TabGroupButtom.Children(2);
            pause(0.01)
            drawnow
            disp('DOne....')


            data ={};
            bgColor=[];
            rowCount=1;
            addpath('../com/ExternalPackages')
            s=xml2struct('../com/DevelopmentList.xml');
            fn=fieldnames(s.Measurements);
            for i=1:size(fn,1)
                nodeStruct=eval(strcat('s.Measurements.',fn{i}));
                %html rendering is not supported
                %data{rowCount,1}=strcat('<html><span style="color: #FF0000; font-weight:bold">',fn{i},'</span></html>');
                data{rowCount,1}=fn{i};
                data{rowCount,2}='';
                bgColor=[bgColor;[1,1,1]];
                rowCount=rowCount+1;
                childFn = fieldnames(nodeStruct);
                for j=1:size(childFn,1)
                    data{rowCount,1}=childFn{j};
                    node = eval(strcat('nodeStruct.',childFn{j}));

                    data{rowCount,2}=node.Text;
                    if isempty(node.Text)
                        bgColor=[bgColor;[0.5,1,1]];
                    elseif strtrim (node.Text)=='T'
                        bgColor=[bgColor;[1,0.5,1]];
                    elseif strtrim (node.Text)=='O'
                        bgColor=[bgColor;[1,1,0.5]];
                    elseif strtrim (node.Text)=='D'
                        bgColor=[bgColor;[0.5,0.5,1]];
                    elseif strtrim (node.Text)=='A'
                        bgColor=[bgColor;[1,0.5,0.5]];
                    else
                        bgColor=[bgColor;[0,0,0]];
                    end
                    rowCount=rowCount+1;
                end
            end
            % app.uiTableVerification.reset;
            drawnow();
            app.uiTableVerification.Data = data;
            app.uiTableVerification.ColumnWidth= {200,50};
            app.uiTableVerification.BackgroundColor=bgColor;
            app.uiTableVerification;


            %uit = uitable(app.uiPanelChkStatistic);
            mc = ?STATISTIC_INDEX;
            %% total number of statitistics we going to calculate
            totalNumStatistics = size(mc.PropertyList,1);
            d={};
            bgColor=zeros(size(totalNumStatistics,1),3);
            for i=1:totalNumStatistics
                d{i,1}=mc.PropertyList(i).Name;
                d{i,2}=true;
                bgColor(i,:)=[1,1,1];
                if mc.PropertyList(i).DefaultValue==-1
                    d{i,2}='';
                    bgColor(i,:)=[1,1,0.1];
                end
                if ~STATISTIC_INDEX.isImplemented(i)
                    d{i,2}=false;
                    bgColor(i,:)=[0.5,0.5,0.5];
                end
                %bgColor{i,1}='#000000';
                %bgColor{i,1}=[1,1,1];

            end
            app.uitableSMeasures.Data = d;

            app.uitableSMeasures.ColumnWidth= {200,50};
            app.uitableSMeasures.BackgroundColor=bgColor;


            drawnow();
            disp('Display update Done....')


        end

        % Callback function
        function chkAllEpoch_Callback(app, event)
            % hObject    handle to chkAllEpoch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of chkAllEpoch
            app.chkAllEpoch.Value = 1;
            app.chkSelEpoch.Value = 0;
        end

        % Value changed function: chkAllMeasures
        function chkAllMeasures_Callback(app, event)
            % % hObject    handle to chkAllMeasures (see GCBO)
            % % eventdata  reserved - to be defined in a future version of MATLAB
            % % handles    structure with handles and user data (see GUIDATA)
            %
            % % Hint: get(hObject.Value  returns toggle state of chkAllMeasures
            % %% check or uncheck all of the checkbox for measurement selection
            chkVal = app.chkAllMeasures.Value ;
            checkUnCheckAllStatistic(app,chkVal);
            UTIL_GUI.setPanelsVisibility(app);
            UTIL_GUI.getParamPanelTabView(app);

            data=app.uitableSMeasures.Data;


            %              if data{STATISTIC_INDEX.AutoCorrelation,2}== true && ~ UTIL_GUI.chkDependency('Econometrics')
            %                 data{STATISTIC_INDEX.AutoCorrelation,2}= false;
            %                 app.uitableSMeasures.Data = data;
            %
            %              end

            %              if (data{STATISTIC_INDEX.ShannonEntropy_SE,2}== true || data{STATISTIC_INDEX.CorrectedConditionalEntropy_CCE,2}== true || data{STATISTIC_INDEX.ConditionalEntropy_CE,2}== true)  && ~ UTIL_GUI.chkDependency('Communications')
            %                 data{STATISTIC_INDEX.ShannonEntropy_SE,2}= false;
            %                 data{STATISTIC_INDEX.CorrectedConditionalEntropy_CCE,2}= false;
            %                 data{STATISTIC_INDEX.ConditionalEntropy_CE,2}= false;
            %                 app.uitableSMeasures.Data = data;
            %
            %              end
            %
            %
            %
            %              if data{STATISTIC_INDEX.SpectralEntropy_SpEn,2}== true && ~ UTIL_GUI.chkDependency('Signal Processing')
            %                 data{STATISTIC_INDEX.SpectralEntropy_SpEn,2}= false;
            %                  app.uitableSMeasures.Data = data;
            %
            %              end
            % %
            %              if data{STATISTIC_INDEX.CorrDim_D2,2}== true && ~ UTIL_GUI.chkDependency('Predictive Maintenance')
            %                 data{STATISTIC_INDEX.CorrDim_D2,2}= false;
            %                  app.uitableSMeasures.Data = data;
            %
            %               end


        end

        % Value changed function: chkFE_L
        function chkFE_L_Callback(app, event)
            % hObject    handle to chkFE_MF_T1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of chkFE_MF_T1
            if app.chkFE_L.Value ==true
                app.editFE_T.Enable = 'off';
                app.editFE_T_T1.Enable = 'off';
                app.editTestStepSize.Enable = 'off';
                app.editTestStepSize.Value = '1';

            else
                app.editFE_T.Enable = 'on';
                app.editFE_T_T1.Enable = 'on';
                app.editTestStepSize.Enable = 'on';
            end
        end

        % Value changed function: chkMedianFilter
        function chkMedianFilter_Callback(app, event)
            % hObject    handle to chkMedianFilter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of chkMedianFilter


            % --- Executes on button press in pbPreprocessData.

        end

        % Value changed function: chkSelEpoch
        function chkSelEpoch_Callback(app, event)
            % hObject    handle to chkSelEpoch (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject.Value  returns toggle state of chkSelEpoch
            app.chkAllEpoch.Value = 0;
            app.chkSelEpoch.Value = 1;
            app.chkSingleData.Value=0;
        end

        % Value changed function: editRQA_M
        function editRQA_M_Callback(app, event)
            % hObject    handle to editRQA_M (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of editRQA_M as text
            %        str2double(get(hObject.Value); returns contents of editRQA_M as a double

            sqrtM = sqrt(str2num(app.editRQA_M.Value));
            app.editRQA_R.Value = num2str(sqrtM);
        end

        % Value changed function: pMenuAppMode
        function pMenuAppMode_Callback(app, event)
            % hObject    handle to pMenuAppMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject.Value); returns pMenuAppMode contents as cell array
            %        contents{get(hObject.Value } returns selected item from pMenuAppMode
            controlIndex = find(strcmp(app.pMenuAppMode.Items,app.pMenuAppMode.Value) );
            %  UTIL_GUI.controlFlow(app, controlIndex);
            %%move all to hidden

            %ind = UTIL_GUI.getMyTabIndex(app.TabHiddenMain,app.pMenuAppMode.Value);
            %app.TabHiddenMain.Children(ind).Parent = app.TabGroupButtom;
            %app.TabGroupButtom.SelectedTab=app.TabGroupButtom.Children(2);


            UTIL_GUI.controlFlow(app,  controlIndex);

            if controlIndex==CONS_CONTROL_FLOW.loadData
                app.TabGroupSelection.SelectedTab= app.TabGroupSelection.Children(1);


                %             elseif controlIndex==CONS_CONTROL_FLOW.paramTest_runPipeLineMode
                %                    if app.pMenuRunMode
                %                 app.TabGroupSelection.SelectedTab= app.TabGroupSelection.Children(2)
                %                 app.TabGroupTestParameters.Parent = app.TestParameter;
                %                 drawnow
                %             elseif   controlIndex==CONS_CONTROL_FLOW.runPipeLineAppMode
                %                 app.TabGroupSelection.SelectedTab= app.TabGroupSelection.Children(2)
                %                 app.TabGroupTestParameters.Parent = app.RunPipeline;
                %                 drawnow

            end
            app.TabGroupButtom.SelectedTab=app.TabGroupButtom.Children( controlIndex+1);
        end

        % Value changed function: pMenuDbFileExtension
        function pMenuDbFileExtension_Callback(app, event)
            % hObject    handle to pMenuDbFileExtension (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject.Value); returns pMenuDbFileExtension contents as cell array
            %        contents{get(hObject.Value } returns selected item from pMenuDbFileExtension


            if not(isempty(app.editDataDir.Value))
                UTIL_LOAD_DATA.loadDataDirectory(app);
            end
        end

        % Value changed function: pMenuFE_MF
        function pMenuFE_MF_Callback(app, event)
            % hObject    handle to pMenuFE_MF (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject.Value); returns pMenuFE_MF contents as cell array
            %        contents{get(hObject.Value } returns selected item from pMenuFE_MF
            app.editFE_T.Value = num2str(CONS_FUZZY_ENTROPY.getRnValue(find(strcmp(app.pMenuFE_MF.Items,app.pMenuFE_MF.Value))));
            app.editFE_T_T1.Value = num2str(CONS_FUZZY_ENTROPY.getRnValue(find(strcmp(app.pMenuFE_MF.Items,app.pMenuFE_MF.Value))));
        end

        % Callback function
        function pMenuFigureNo_Callback(app, event)
            % hObject    handle to pMenuFigureNo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject.Value); returns pMenuFigureNo contents as cell array
            %        contents{get(hObject.Value } returns selected item from pMenuFigureNo

            %             global leftLocUp
            %             global rightLocUp
            %             global leftLocDown
            %             global rightLocDown
            %
            %             leftLocUp=[];
            %             rightLocUp=[];
            %             leftLocDown=[];
            %             rightLocDown=[];
            %             pbDataMode_Callback(app, event);
        end

        % Callback function
        function pMenuFileParam_Callback(app, event)
            % hObject    handle to pMenuFileParam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject.Value); returns pMenuFileParam contents as cell array
            %        contents{get(hObject.Value } returns selected item from pMenuFileParam
            UTIL_GUI.loadParameters(app);
        end

        % Callback function
        function pMenuFilesLPP_Callback(app, event)
            % hObject    handle to pMenuFilesLPP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject.Value); returns pMenuFilesLPP contents as cell array
            %        contents{get(hObject.Value } returns selected item from pMenuFilesLPP
            %             global showRaw1
            %             global showTruncated1
            %             global showProcessed1
            %
            %
            %             global showRaw2
            %             global showTruncated2
            %             global showProcessed2
            %
            %             global leftLocUp
            %             global rightLocUp
            %             global leftLocDown
            %             global rightLocDown

            global RawDataSet


            selIndex = find(strcmp(app.pMenuFilesLPP.Items,app.pMenuFilesLPP.Value))-1;

            if selIndex==-1
                return;
            end
            pbClearPlot_Callback(app, event);
            try
                selData = RawDataSet{selIndex,1};
            catch
                x=1
            end
            dimensionList{1,1} = 'Select Column/s';
            for dim=1: size(selData,2)
                dimensionList{1,dim+1}=num2str(dim);
            end
            app.pMenuDimensionPP.Items = dimensionList;
            app.pMenuDimensionPP.Value=app.pMenuDimensionPP.Items(2);
            %
            %
            %             if find(strcmp(app.pMenuFigureNo.Items,app.pMenuFigureNo.Value)) ==1
            %
            %                 showRaw1 = false;
            %                 showTruncated1=false;
            %                 showProcessed1=false;
            %
            %
            %                 leftLocDown =[];
            %                 rightLocDown =[];
            %
            %
            %                 cla(app.myAxesUp,'reset')
            %
            %                 if find(strcmp(app.pbDataMode.Items,app.pbDataMode.Value)) ==1
            %                     showRaw1 = true;
            %                 elseif find(strcmp(app.pbDataMode.Items,app.pbDataMode.Value)) ==2
            %                     showTruncated1=true;
            %                 else
            %                     showProcessed1=true;
            %                 end
            %
            %             else
            %                 showRaw2 = false;
            %                 showTruncated2=false;
            %                 showProcessed2=false;
            %
            %
            %                 leftLocUp =[];
            %                 rightLocUp =[];
            %
            %
            %                 cla(app.myAxesDown,'reset')
            %
            %                 if find(strcmp(app.pbDataMode.Items,app.pbDataMode.Value)) ==1
            %                     showRaw2 = true;
            %                 elseif find(strcmp(app.pbDataMode.Items,app.pbDataMode.Value)) ==2
            %                     showTruncated2=true;
            %                 else
            %                     showProcessed2=true;
            %                 end
            %
            %             end
            %             UTIL_PROCESS_DATA.plotDataFigures(app);
        end

        % Callback function
        function pMenuViewPanel_Callback(app, event)
            % hObject    handle to pMenuViewPanel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject.Value); returns pMenuViewPanel contents as cell array
            %        contents{get(hObject.Value } returns selected item from pMenuViewPanel
            selectedIndex = app.pMenuViewPanel.Value ;
            setPanelsVisibility(app);

            contents = cellstr(app.pMenuViewPanel.Value);
            %%msgbox(num2str(selectedIndex))
            allMethodPannels=properties(METHODS_PANELS);

            %% hide all method panels
            for mPanel = allMethodPannels'
                panelName = METHODS_PANELS.getPanelNameByConst(char(mPanel));
                eval(strcat('app.',panelName)).Visible = 0;
            end

            if selectedIndex==1
                return
            end

            %{
            %% show only the selected method panel
            try
                set(eval(strcat('app.',METHODS_PANELS.getPanelNameByDesc(contents{selectedIndex}))),'Parent',app.uiPanelMParam,'Parent'));
                set(eval(strcat('app.',METHODS_PANELS.getPanelNameByDesc(contents{selectedIndex}))),'Position',app.uiPanelMParam,'Position'));
                set(eval(strcat('app.',METHODS_PANELS.getPanelNameByDesc(contents{selectedIndex}))).Visible = 'on');
            catch
                x=1
            end
            %}
        end

        % Callback function
        function pbClearPlot_Callback(app, event)
            % hObject    handle to pbClearPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global leftLocUp
            global rightLocUp
            global legendIndex
            %
            %             global leftLocDown;
            %             global rightLocDown;
            %


            %             global showRaw1
            %             global showTruncated1
            %             global showProcessed1
            %
            %             global showRaw2
            %             global showTruncated2
            %             global showProcessed2
            %


            %if find(strcmp(app.pMenuFigureNo.Items,app.pMenuFigureNo.Value)) ==1
            %    cla(app.myAxesUp)
            %                 leftLocDown=[];
            %                 rightLocDown=[];


            % else
            cla(app.myAxesDown);
            cla(app.myAxesUp);
            leftLocUp=[];
            rightLocUp=[];
            legendIndex=[];
            %
            %                 showRaw2 = false;
            %                 showTruncated2 = false;
            %                 showProcessed2 = false;
            %             end
        end

        % Callback function
        function pbDataMode_Callback(app, event)
            % hObject    handle to pbDataMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject.Value); returns pbDataMode contents as cell array
            %        contents{get(hObject.Value } returns selected item from pbDataMode

            global showRaw1
            global showTruncated1
            global showProcessed1


            global showRaw2
            global showTruncated2
            global showProcessed2
            %
            %
            %             %% if no file is selected return
            if find(strcmp(app.pMenuFilesLPP.Items,app.pMenuFilesLPP.Value)) ==1
                return
            end
            if find(strcmp(app.pbDataMode.Items,app.pbDataMode.Value)) ==1
                if find(strcmp(app.pMenuFigureNo.Items,app.pMenuFigureNo.Value)) ==1
                    showRaw1=true;
                else
                    showRaw2=true;
                end
            elseif find(strcmp(app.pbDataMode.Items,app.pbDataMode.Value)) ==2
                if find(strcmp(app.pMenuFigureNo.Items,app.pMenuFigureNo.Value ))==1
                    showTruncated1=true;
                else
                    showTruncated2=true;
                end
            else
                if find(strcmp(app.pMenuFigureNo.Items,app.pMenuFigureNo.Value)) ==1
                    showProcessed1=true;
                else
                    showProcessed2=true;
                end
            end
            %
            %             UTIL_PROCESS_DATA.plotDataFigures(app)
        end

        % Button pushed function: pbPlotTest
        function pbPlotTest_Callback(app, event)


            %             if sum ([strcmp(app.editTestFileIDFrom.Value,app.editTestFileIDTo.Value)==0 strcmp(app.editTestEpochIDFrom.Value,app.editTestEpochIDTo.Value)==0 strcmp(app.editTestDimensionIDFrom.Value,app.editTestDimensionIDTo.Value)==0 strcmp(app.editTestScaleFrom.Value,app.editTestScaleTo.Value)==0]) >=2
            %                 msgbox('Please choose only one range in file/Epoch/Column/Scale..');
            %                 return;
            %             end

            isSuccessed = UTIL_TEST_PARAM.runTestParam(app);

            %% show the plot tab
            if isSuccessed
                app.TabGroupTestParameters.SelectedTab=app.TabGroupTestParameters.Children(1);
            end


        end

        % Button pushed function: pbProcessData
        function pbProcessData_Callback(app, event)
            % hObject    handle to pbProcessData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global RawDataSet
            global PreprocessedDataSet
            global dataDeletedIndex
            %global TruncatedDataSet
            global ALL_StatisticCol

            %selFileIndex=find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value))-1;

            flagRemoveO= app.chkRemoveOutliers.Value ==1;
            flagMedianFilter= app.chkMedianFilter.Value ==1;
            flagApplyFilter= app.chkApplyFilter.Value==1;
            flagPerEpoch=find(strcmp(app.pMenuOutlierType.Items,app.pMenuOutlierType.Value));

            lowPass = str2double(app.editLowBand.Value);
            highPass = str2double(app.editHighBand.Value);
            medianFOrder = str2double(app.editMedianFilterOrder.Value);

            % removing dataset over whole dataset
            if flagPerEpoch
                outlierWindowL = str2double(app.editSampleR.Value)*str2double(app.editEpochT.Value);
            else
                outlierWindowL=size(data,1);

            end

            outlierM_Width=str2num(app.editOutlierMW.Value);


            applyList=[];
            if find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value))==1
                applyList=1:size(RawDataSet,1);
            else
                applyList=find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value))-2 ;
            end
            %msgbox('Previous pre-process effect is recovered and pre-processing over all dimension applied.')
            for i=1:size(applyList,2)
                %PreprocessedDataSet{applyList(i),1}=RawDataSet{applyList(i),1};
                if ~isempty(dataDeletedIndex) & size(dataDeletedIndex,1)>=applyList(i) & ~isempty(RawDataSet{applyList(i),1})
                    PreprocessedDataSet{applyList(i),1}=UTIL_PROCESS_DATA.getTruncatedDataSet(RawDataSet{applyList(i),1},dataDeletedIndex{applyList(i),1});
                else
                    PreprocessedDataSet{applyList(i),1}=RawDataSet{applyList(i),1};
                end


                %[truncatedIndex,newPPData,OutlierInfoCol] = UTIL_PROCESS_DATA.processData(app,RawDataSet{applyList(i),1})

                [newPPData,OutlierInfoCol] = UTIL_PROCESS_DATA.processData(app,PreprocessedDataSet{applyList(i),1},flagRemoveO,flagMedianFilter,flagApplyFilter,outlierM_Width,outlierWindowL,medianFOrder,lowPass,highPass);
                if ~isempty(OutlierInfoCol) & ~isempty(ALL_StatisticCol) & size(ALL_StatisticCol,1)>=applyList(i) & ~isempty(ALL_StatisticCol{applyList(i),CONS_INDEX.outliers})
                    OutlierInfoCol=UTIL_GUI.combineOutliers(PreprocessedDataSet{applyList(i),1},ALL_StatisticCol{applyList(i),CONS_INDEX.outliers},OutlierInfoCol);
                end
                % TruncatedDataSet{applyList(i),1} = newTData
                %dataDeletedIndex{applyList(i),1}=truncatedIndex;

                %% apply Detendring

                if app.chkApplyDetrending_PPD.Value==1

                    n=str2num(app.editDegreePoly_PPD.Value);
                    newPPData = detrend(newPPData,n);
                   
                end

                PreprocessedDataSet{applyList(i),1} = newPPData;
                if ~isempty(OutlierInfoCol)
                    ALL_StatisticCol{applyList(i),CONS_INDEX.outliers}=OutlierInfoCol;
                end
            end
            cla(app.myAxesUp);
            cla(app.myAxesDown);


        end

        % Button pushed function: pbResultDirectory
        function pbResultDirectory_Callback(app, event)
            % hObject    handle to pbResultDirectory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %%change1 30_05_2020
            flag=true;
            if ~exist('../Results', 'dir')
                flag = mkdir('../Results');
            end

            if flag==false
                msgbox('Cannot create Data directory');
                resultDir = uigetdir('../');
            else
                resultDir = uigetdir('../Results');
            end

            %%if Results directory not found open in current project/script
            app.editResultDir.Value = resultDir;
        end

        % Button pushed function: pbRunExperiment
        function pbRunExperiment_Callback(app, event)
            UTIL_RUN_PIPELINE.runPipeline(app);
        end

        % Button pushed function: pbSaveResults
        function pbSaveResults_Callback(app, event)
            % hObject    handle to pbSaveResults (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ALL_StatisticCol


            %% get the path to store results
            resultPath = app.editResultDir.Value;
            %% get selected statistic
            chkSelMeasures = app.uitableSMeasures.Data;
            %selData = cell2mat(chkSelMeasures(:,2));
            selData = UTIL_GUI.getStatisticSelData(app);
            dbDirectoryPath=app.editDataDir.Value;


            selExt = app.pMenuDbFileExtension.Value;


            %   ALL_StatisticCol{fIndex,CONS_INDEX.functionalValues}=StatisticCol;
            %     ALL_StatisticCol{fIndex,CONS_INDEX.outliers}=OutlierInfo;
            %     %ALL_StatisticCol{fIndex,CONS_INDEX.kMaxHiguchFD}=kMaxHiguchiFD;
            %     ALL_StatisticCol{fIndex,CONS_INDEX.computationTime} = ComputationTime;
            %     ALL_StatisticCol{fIndex,CONS_INDEX.fastLombIndex} = FastLombCol;
            %     ALL_StatisticCol{fIndex,CONS_INDEX.fftIndex} = FFTCol;

            if app.chkSaveInSExcel.Value ==1
                %saveDataInMulSheetExcel(handles,StatisticCol,resultPath,FileName,totalEpoch)
                dbFilesCols  = dir(strcat(dbDirectoryPath,'/*.',selExt));

                chkIfFileExist=true;

                if sum(selData) == 0
                    msgbox('None of the Measurements are selected');
                    return
                    %% check if some measures beyond timeseries are selected or not
                elseif sum(selData(:,1))-sum(selData(STATISTIC_INDEX.getTimeSeriesList(),1)) >=1

                    if find(strcmp(app.pMenuSaveExcelType.Items,app.pMenuSaveExcelType.Value))==1
                        isOK = UTIL_SAVE_RESULTS.saveInSingleSheetExcel(app,dbFilesCols,'Results',chkIfFileExist);
                    else
                        isOK = UTIL_SAVE_RESULTS.saveInExcelPerFile(app,dbFilesCols,'Results',chkIfFileExist);
                    end
                    if isOK==false
                        return;
                    end


                    UTIL_SAVE_RESULTS.saveParamInExcel(app);

                    %%for the first time check if the file exist or not.
                    chkIfFileExist=false;
                end


                %% saving time series results  (frequencies ) in seperate excel sheet....
                if selData(STATISTIC_INDEX.FFT)==1
                    %(handles,dbFilesCols,ResultType,resultCol)
                    isOK = UTIL_SAVE_RESULTS.saveTimeSeriesResults(app,dbFilesCols,'FFT',ALL_StatisticCol(:,CONS_INDEX.fftIndex),chkIfFileExist);
                    if isOK==false
                        return;
                    end
                    chkIfFileExist=false;
                    %saveTimeInExcel(handles,dbFilesCols,ALL_StatisticCol(:,CONS_INDEX.computationTime))
                end
                if selData(STATISTIC_INDEX.FastLomb)==1
                    isOK = UTIL_SAVE_RESULTS.saveTimeSeriesResults(app,dbFilesCols,'FastLomb',ALL_StatisticCol(:,CONS_INDEX.fastLombIndex),chkIfFileExist);
                    if isOK==false
                        return;
                    end
                    chkIfFileExist=false;
                    %saveTimeInExcel(handles,dbFilesCols,ALL_StatisticCol(:,CONS_INDEX.computationTime))
                end

                %                 if selData(STATISTIC_INDEX.SpectralEntropy_SpEn)==1
                %                     %saveTimeSeriesResults(handles,dbFilesCols,'Results_SpectralEn_',ALL_StatisticCol(:,CONS_INDEX.spectralEntropyIndex))
                %                     %saveTimeSeriesResults(handles,dbFilesCols,ResultType,resultCol)
                %                     isOK = UTIL_SAVE_RESULTS.saveTimeSeriesResults(app,dbFilesCols,'SpectralEn',ALL_StatisticCol(:,CONS_INDEX.SpectralEntropy_SpEn),chkIfFileExist);
                %                     if isOK==false
                %                         return;
                %                     end
                %                     chkIfFileExist=false;
                %                     %saveTimeInExcel(handles,dbFilesCols,ALL_StatisticCol(:,CONS_INDEX.computationTime))
                %                 end


                %                  if selData(STATISTIC_INDEX.LargestLyapunovExp_LLE)==1
                %                     %saveTimeSeriesResults(handles,dbFilesCols,'Results_SpectralEn_',ALL_StatisticCol(:,CONS_INDEX.spectralEntropyIndex))
                %                     %saveTimeSeriesResults(handles,dbFilesCols,ResultType,resultCol)
                %                     isOK = UTIL_SAVE_RESULTS.saveTimeSeriesResults(app,dbFilesCols,'LLE',ALL_StatisticCol(:,CONS_INDEX.LargestLyapunovExp_LLE),chkIfFileExist);
                %                     if isOK==false
                %                         return;
                %                     end
                %                     chkIfFileExist=false;
                %                     %saveTimeInExcel(handles,dbFilesCols,ALL_StatisticCol(:,CONS_INDEX.computationTime))
                %                 end
                %

                %%if chkIFFileExist is not done then no results are saved
                %%so no need to save time.
                if app.chkSaveTime.Value ==1 && chkIfFileExist==false
                    %% computation time is always save once data is written so no nned to check if the file exist or not
                    %singleResultSelData = UTIL_GUI.getStatisticSelData(app);
                    UTIL_SAVE_RESULTS.saveInSingleSheetExcel(app,dbFilesCols,'ComputationTime',false);
                    %saveTimeInExcel(handles,dbFilesCols,ALL_StatisticCol(:,CONS_INDEX.computationTime))
                end

            end

            if app.chkSaveAsMat.Value
                save(strcat(resultPath,'/',app.editExperimentName.Value,'.mat'),'ALL_StatisticCol','selData');
            end


            %%save parameter in xml file
            UTIL_SAVE_RESULTS.saveParamInXMLFile(app,resultPath);
        end

        % Button pushed function: pbSelectFiles
        function pbSelectFiles_Callback(app, event)
            % hObject    handle to pbSelectData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %% change1 30_05_2020
            %% open in current
            global RawDataSet
            global PreprocessedDataSet
            global dataDeletedIndex

            global FileNameList

            %app.pbSelectFiles.Enable=false;
            %             % In the callback:  to avoid double click
            %             if ~strcmpi(get(app.pbSelectFiles, 'SelectionType'), 'normal')
            %                 return;
            %             end

            disp('1 OK')


            %%% David Please uncomment this

            flag=true;
            if ~exist('../Data', 'dir')
                disp('mkdir ../Data')
                flag = mkdir('../Data');
            end


            location='../';
            if flag==false
                msgbox('Cannot create Data directory');


            else
                disp('uigetdir ../Data')
                location='../Data';

            end

            dbDir = uigetdir(location);


            %% Done David

            %dbDir='../Data/SampleData/RR_Data';


            % figure(app.UIFigure); % magic command :)
            %%if Data directory not found open in current project/script


            try
                if strcmp(app.editDataDir.Value,dbDir)==true && strcmp(app.editFileExt.Value,app.pMenuDbFileExtension.Value)
                    msgbox("Data file/s already loaded.");
                    return
                end
                disp('3 OK')
                app.editDataDir.Value = dbDir;
                app.editTableDirectory.Value = dbDir;
                app.editFileExt.Value=app.pMenuDbFileExtension.Value;
            catch
                app.editDataDir.Value = app.editDataDir.Value;
                app.editFileExt.Value=app.pMenuDbFileExtension.Value;
                disp('3....error and return OK')
                return
            end

            %%clear previous records
            RawDataSet={};
            PreprocessedDataSet={};
            dataDeletedIndex={};
            FileNameList={};

            disp('4  OK')
            %%load all files from the directory....
            UTIL_LOAD_DATA.loadDataDirectory(app);
            disp('5 loadData  OK')


            selExt = app.pMenuDbFileExtension.Value;
            dbDirectoryPath = app.editDataDir.Value;
            dbFilesCols  = dir(strcat(dbDirectoryPath,'/*.',selExt));


            %%%load file Names in display list
            fileInfoList = {};
            %% total number of statitistics we going to calculate
            fileN = size(dbFilesCols,1);

            for fIndex=1:fileN
                %d{i,1}=mc.PropertyList(i).Name;
                fileInfoList{fIndex,1}=dbFilesCols(fIndex).name;
                fileInfoList{fIndex,2}=true;
            end
            disp('7 fileInfo   OK')
            app.uiTableFilesName.Data = fileInfoList;
            %             app.pMenuFileNames.Items=['Select data file'];
            %             app.pMenuDimensions.Items=['Select Dimension'];
            %             app.pMenuEpochs.Items=['Select Epoch'];
            %app.pMenuPreveiwFileName.Items=['Select data file';fileInfoList(:,1)];
            if isempty(fileInfoList)
                disp('8 Final (empty file)   OK')
                return;

            end
            app.pMenuDataFileName.Items=['Select data file';fileInfoList(:,1)];
            app.chkAllFiles.Value = 1;
            disp('9 Final   OK')
        end

        % Button pushed function: pbViewPlot
        function pbViewPlot_Callback(app, event)
            % hObject    handle to pbViewPlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ALL_StatisticCol

            FILE_INDEX=1;
            MEASURE_INDEX=2;
            EPOCH_INDEX=3;

            SCALE_INDEX=4;
            DIM_INDEX=5;

            viewIndex = find(strcmp(app.pMenuViewBy.Items,app.pMenuViewBy.Value));


            COL_PLOT=-1;
            xAxisName = "";
            if viewIndex==1 %%file
                app.pMenuFileNames.Value=app.pMenuFileNames.Items{1};
                COL_PLOT=FILE_INDEX;
                xAxisName="File No";
            elseif viewIndex==2 %%epoch
                app.pMenuEpochs.Value=app.pMenuEpochs.Items{1};
                COL_PLOT = EPOCH_INDEX;
                xAxisName="Epoch No";
            elseif viewIndex==3 %%dimension
                app.pMenuDimension.Value=app.pMenuDimension.Items{1};
                COL_PLOT = DIM_INDEX;
                xAxisName="Dimension";
            elseif viewIndex==4  %% Scales
                app.pMenuScale.Value=app.pMenuScale.Items{1};
                COL_PLOT=SCALE_INDEX;
                xAxisName="Scale";
            end

            %             selFileNo = find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value)) -2;
            %             selEpochNo = find(strcmp(app.pMenuEpochs.Items,app.pMenuEpochs.Value)) -2;
            %             selDimensionNo = find(strcmp(app.pMenuDimension.Items,app.pMenuDimension.Value)) -2;
            %             selScaleNo = find(strcmp(app.pMenuScale.Items,app.pMenuScale.Value)) -2;


            selScaleNo = UTIL_GUI.getScaleList(app);
            selDimensionNo = UTIL_GUI.getDimensionList(app);
            selFileNo =UTIL_GUI.getFileIDList(app);
            selEpochNo = UTIL_GUI.getEpochList(app);


            %
            %             xAxisName = "";
            %             if sum([selFileNo==0 selEpochNo==0 selDimensionNo==0 selScaleNo==0]  ) ~=1
            %                 msgbox("Only one combo box can be selected for ALL Files/Epochs/Columns/Scales !");
            %                 return;
            %             else
            %                 if selFileNo==0, xAxisName="File No"; elseif ...
            %                         selEpochNo==0, xAxisName="Epoch No"; elseif ...
            %                         selDimensionNo==0, xAxisName="Column"; elseif ...
            %                         selScaleNo==0, xAxisName="Scale";
            %                 end
            %             end
            selData = UTIL_GUI.getStatisticSelData(app);
            selMeasureIndex = find(selData==1)';
            chkSelMeasures = app.uitableSMeasures.Data;
            measureName = chkSelMeasures(selMeasureIndex,1);
            allMeasureData = ALL_StatisticCol(:,CONS_INDEX.functionalValues);
            %file mIndex epochID scaleN dimID tmpValPerE
            DataToPlot=[];
            allData=[];
            for fIndex=1:size(allMeasureData,1)
                for mIndex=1:size(selMeasureIndex,2)
                    tmpVal = allMeasureData{fIndex,1}(:,selMeasureIndex(1,mIndex));
                    epochN = size(tmpVal,1);
                    for epochID=1:epochN
                        tmpValPerE = tmpVal{epochID,1};
                        [scaleN, dimN]= size(tmpValPerE);
                        for dimID=1:dimN
                            DataToPlot = [DataToPlot;ones(scaleN,1)*fIndex ones(scaleN,1)*mIndex ones(scaleN,1)*epochID,[1:scaleN]', ones(scaleN,1)*dimID, tmpValPerE(:,dimID)];
                        end

                    end
                end
            end


            if isempty(DataToPlot)
                msgbox('Data is not processed yet!');
                return
            end


            maxDim = max(DataToPlot(:,DIM_INDEX));
            maxMeasure=max(DataToPlot(:,MEASURE_INDEX));
            maxScale = max(DataToPlot(:,SCALE_INDEX));
            maxEpoch = max(DataToPlot(:,EPOCH_INDEX));
            maxFile = max(DataToPlot(:,FILE_INDEX));

            maxCol=[maxFile,-1,maxEpoch,maxScale,maxDim];

            queryData = DataToPlot;

            if ~isempty(selFileNo)
                queryData = queryData(ismember(queryData(:,FILE_INDEX),selFileNo'),:);
            end

            if ~isempty(selDimensionNo)

                queryData = queryData(ismember(queryData(:,DIM_INDEX),selDimensionNo'),:);
            end

            if ~isempty(selScaleNo)
                queryData(ismember(queryData(:,DIM_INDEX),selScaleNo'),:);

            end


            if ~isempty(selEpochNo)
                queryData(ismember(queryData(:,EPOCH_INDEX),selEpochNo'),:);

            end


            %
            %             try
            %                 queryData = DataToPlot(((DataToPlot(:,FILE_INDEX)==selFileNo | selFileNo'==0 |isempty(selFileNo)) & ...
            %                     (isempty(selDimensionNo) | DataToPlot(:,DIM_INDEX)==selDimensionNo | selDimensionNo==0 ) & ...
            %                     (DataToPlot(:,SCALE_INDEX)==selScaleNo | selScaleNo==0 |isempty(selScaleNo)) & ...
            %                     (DataToPlot(:,EPOCH_INDEX)==selEpochNo | selEpochNo==0 |isempty(selEpochNo))) ==1,:);
            %             catch
            %                 msgbox('One of parameters: file, dimension, scale or epoch missing..')
            %                 return
            %             end

            %             if selFileNo==0,COL_PLOT=FILE_INDEX; elseif ...
            %                     selEpochNo==0, COL_PLOT = EPOCH_INDEX; elseif ...
            %                     selScaleNo==0, COL_PLOT=SCALE_INDEX; elseif  ...
            %                     selDimensionNo==0, COL_PLOT = DIM_INDEX; else COL_PLOT=-1;
            %             end

            if find(strcmp(app.pMenuViewType.Items,app.pMenuViewType.Value)) ==1
                myFun = 'nanmean(paramData)';
                myFunErr = 'nanstd(paramData)';
            else
                myFun = 'nanmedian(paramData)';
                myFunErr = 'iqr(paramData)';
            end


            isError = true;
            try

                cd('../com/ExternalPackages/maxdistcolor');
                fun = @(maxMeasure)Lab_to_DIN99(srgb_to_Lab(maxMeasure));
                distColorList = maxdistcolor(maxMeasure,fun);
                isError = false;
                throwME(MException("Go To Finally","Finally"));
            catch e %e is an MException struct
                if isError
                    getReport(e);

                end
                % more error handling...

                %% moving back
                cd('../../../scripts');
            end
            cla(app.UIAxesViewResults);
            for mIndex=1:maxMeasure

                currData = queryData(queryData(:,MEASURE_INDEX)==mIndex,:);
                tmpVal=[];
                for i=1:maxCol(1,COL_PLOT)
                    paramData = currData(currData(:,COL_PLOT)==i,end);
                    tmpVal=[tmpVal;i eval(myFun) eval(myFunErr)];

                end

                hold(app.UIAxesViewResults,'on');
                if app.chkShowError.Value==1
                    errorbar(app.UIAxesViewResults,tmpVal(:,1),tmpVal(:,2),tmpVal(:,3)','--o','color',distColorList(mIndex,:));
                else
                    plot(app.UIAxesViewResults,tmpVal(:,1),tmpVal(:,2),'--o','color',distColorList(mIndex,:));
                end

                legend(app.UIAxesViewResults,measureName);
            end
            xlabel(app.UIAxesViewResults,xAxisName);

        end

        % Callback function
        function pbZoomIn_Callback(app, event)
            % hObject    handle to pbZoomIn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            %
            %             global upFigStart
            %             global downFigStart
            %
            %             global upScale
            %             global downScale
            %             index = find(strcmp(app.pMenuFigureNo.Items,app.pMenuFigureNo.Value));
            %
            %             if index==1
            %                 downScale = min(10,downScale/2);
            %                 downFigStart =max( upper( downFigStart - downScale/2),1);
            %             elseif index==2
            %                 upScale = min(10,upScale/2);
            %                 upFigStart =max( upper( upFigStart - upScale/2),1);
            %             end
        end

        % Cell edit callback: uitableSMeasures
        function uitableSMeasures_CellEditCallback(app, event)
            % hObject    handle to uitableSMeasures (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            %chkSelMeasures = app.uitableSMeasures.Data;
            %selData = cell2mat(chkSelMeasures(:,2));
            %selData = chkSelMeasures(:,2)

            %

            data=app.uitableSMeasures.Data;
            %MODE_INDEX=find(strcmp(app.pMenuAppMode.Items,app.pMenuAppMode.Value));


            MODE_INDEX=find(strcmp(app.pMenuRunMode.Items,app.pMenuRunMode.Value));


            %
            %              try
            %
            %               if ((data{STATISTIC_INDEX.ShannonEntropy_SE,2}== true) || (data{STATISTIC_INDEX.CorrectedConditionalEntropy_CCE,2}== true) || (data{STATISTIC_INDEX.ConditionalEntropy_CE,2}== true)) && ~ UTIL_GUI.chkDependency('Communications',true)
            %                 data{STATISTIC_INDEX.ShannonEntropy_SE,2}= false;
            %                 data{STATISTIC_INDEX.CorrectedConditionalEntropy_CCE,2}= false;
            %                 data{STATISTIC_INDEX.ConditionalEntropy_CE,2}= false;
            %                 app.uitableSMeasures.Data = data;
            %                 return
            %
            %               end
            %
            %              catch
            %              end

            try

                %               if ( data{STATISTIC_INDEX.SpectralEntropy_SpEn,2}== true ) && ~ UTIL_GUI.chkDependency('Signal Processing',true)
                %                 data{STATISTIC_INDEX.SpectralEntropy_SpEn,2}= false;
                %                 app.uitableSMeasures.Data = data;
                %                 return
                %               end
            catch
            end

            %               try
            %
            %                if (data{STATISTIC_INDEX.CorrDim_D2,2}== true) && ~ UTIL_GUI.chkDependency('Predictive Maintenance',true)
            %                 data{STATISTIC_INDEX.CorrDim_D2,2}= false;
            %                 app.uitableSMeasures.Data = data;
            %                 return
            %                end
            %               catch
            %               end


            % %% if running in test mode, make sure only one statistic is choosen
            %  if MODE_INDEX ==CONS_CONTROL_FLOW.paramTestAppMode
            if MODE_INDEX==2
                %checkUnCheckAllStatistic(app,true)

                for i=1:size(data,1)
                    if isa(data{i,2},'logical')
                        data{i,2}=false;
                    end
                end

                %% issue1

                data{event.Indices(1,1),2}=true;
                %% using java object handler to control the scroll of table
                %jscrollpane=javaObjectEDT(findjobj(hObject));
                %viewport    = javaObjectEDT(jscrollpane.getViewport);
                %P = viewport.getViewPosition();
                %% jtable  = javaObjectEDT( viewport.getView );
                app.uitableSMeasures.Data = data;
                %drawnow() %This is necessary to ensure the view position is set after matlab hijacks it
                %viewport.setViewPosition(P);
                app.chkAllMeasures.Value = 0;
                app.chkAllMeasures.Enable = 'off';

                %%% get the right parameter panel
                STATISTIC_INDEX.getSubHeading(3);
                app.TabGroupTestParameters

            end

            %select appropriate tab
            subHeader = STATISTIC_INDEX.getSubHeading(event.Indices(1,1));
            showIndex=UTIL_GUI.getMyTabIndex(app.TabGroupTestParameters,subHeader);
            if showIndex >0
                app.TabGroupTestParameters.SelectedTab=app.TabGroupTestParameters.Children(showIndex);
            end


            if ~STATISTIC_INDEX.isImplemented(event.Indices(1,1))
                data{event.Indices(1,1),2}=false;
                app.uitableSMeasures.Data = data;
                return;
            end
            UTIL_GUI.setPanelsVisibility(app);
            UTIL_GUI.getParamPanelTabView(app);


            %% move Panel selection to respective group
            %setPanelSelection(handles,eventdata.Indices(1,1));
            %% call back the click action in group selection menu box
            %% pMenuViewPanel_Callback([], eventdata, handles);
        end

        % Selection change function: TabGroupButtom
        function TabGroupButtomSelectionChanged(app, event)
            selectedTab = app.TabGroupButtom.SelectedTab;

        end

        % Value changed function: editEpochT
        function editEpochTValueChanged(app, event)
            value = app.editEpochT.Value;

        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Time Series')


                app.txtSampleR.Text = 'Sampling Rate (/sec)';
                app.txtEpochT.Text ='Epoch Length (sec)';
                app.txtEpochT.Visible=true;
                app.editEpochT.Visible=true;
                app.txtStartT.Text ='Truncate from start, up to sec';
                app.txtMaxLength.Text ='Truncate to end, from sec';
            else
                app.txtSampleR.Text ='No. of Data points per Epoch';
                app.txtEpochT.Text ='No. of Units per Epoch';
                app.txtEpochT.Visible=false;
                app.editEpochT.Visible=false;
                app.editEpochT.Value='1';
                app.txtStartT.Text ='Epoch Number Start From';
                app.txtMaxLength.Text ='Max. Number of Epochs';
            end
        end

        % Callback function
        function pMenuDimensionPPValueChanged(app, event)

            %pbDataMode_Callback(app, event);
        end

        % Callback function
        function pbRecoverRawButtonPushed(app, event)
            global RawDataSet
            global dataDeletedIndex
            global PreprocessedDataSet


            %TruncatedDataSet=RawDataSet;
            %PreprocessedDataSet=RawDataSet;


            if find(strcmp(app.pMenuFilesTruncated.Items,app.pMenuFilesTruncated.Value))==1
                PreprocessedDataSet=RawDataSet;
                dataDeletedIndex={};
            else
                selFileIndex=find(strcmp(app.pMenuFilesTruncated.Items,app.pMenuFilesTruncated.Value))-1;
                PreprocessedDataSet{selFileIndex,1}=RawDataSet{selFileIndex,1} ;
                dataDeletedIndex{selFileIndex,1}=[];
            end

            %% clear history... outlier list/ truncated data..

        end

        % Button pushed function: pbPlotData
        function pbPlotDataButtonPushed(app, event)
            UTIL_PROCESS_DATA.plotDataFigures(app);
        end

        % Cell edit callback: uiTableFilesName
        function uiTableFilesNameCellEdit(app, event)


            if app.uiTableFilesName.Value == 1
                data = app.uiTableFilesName.Data;
            end


        end

        % Cell selection callback: uiTableFilesName
        function uiTableFilesNameCellSelection(app, event)
            %%check uncheck selected file
            selIndex=event.Indices(1,1);
            app.uiTableFilesName.Data{selIndex,2}=~app.uiTableFilesName.Data{event.Indices(1,1),2};
            selIndexList = cell2mat(app.uiTableFilesName.Data(:,2));
            selFilesNameList = app.uiTableFilesName.Data(selIndexList==1,1);
            app.pMenuDataFileName.Items=['Select Data File';selFilesNameList];


            if app.uiTableFilesName.Data{selIndex,2}==true
                app.editDataFileName.Value=app.uiTableFilesName.Data{selIndex,1};
                selectedFile= app.uiTableFilesName.Data{selIndex,1};
                app.pMenuDataFileName.Value=selectedFile;

                selExt = app.pMenuDbFileExtension.Value;
                dbDirectoryPath=app.editDataDir.Value;
                data=UTIL_LOAD_DATA.loadDataFiles(app,selExt,dbDirectoryPath,selectedFile);
                app.UITableFileData.Data = data;
            else
                app.pMenuDataFileName.Value='Select Data File';
                app.editDataFileName.Value='';
                app.UITableFileData.Data = [];
            end


        end

        % Value changed function: chkAllFiles
        function chkAllFilesValueChanged(app, event)
            for i=1:size(app.uiTableFilesName.Data,1)

                app.uiTableFilesName.Data{i,2}=app.chkAllFiles.Value==1;


            end

        end

        % Callback function
        function pbViewDataFileButtonPushed(app, event)
            selExt = app.pMenuDbFileExtension.Value;
            dbDirectoryPath=app.editDataDir.Value;

            data=UTIL_LOAD_DATA.loadDataFiles(app,selExt,dbDirectoryPath,app.editDataFileName.Value);
            %                 if strcmp(selExt,'csv')
            %                     %% reading the csv files with one heading line..
            %                     data = csvread( strcat(dbDirectoryPath,'/',app.editDataFileName.Value),1,0);
            %                 elseif strcmp(selExt,'xlsx')
            %                     data = xlsread(strcat(dbDirectoryPath,'/',app.editDataFileName.Value));
            %                 elseif strcmp(selExt,'mat')
            %                     data = load(strcat(dbDirectoryPath,'/',app.editDataFileName.Value));
            %
            %                 else
            %                     data = load(strcat(dbDirectoryPath,'/',app.editDataFileName.Value));
            %                 end
            app.UITableFileData.Data = data;

            %                [rowN,colN]=size(data);

            %%app.txtFileInfo.Value=sprintf("File Name : %s \nRows    #: %s \nColumns #: %s",app.editDataFileName.Value,num2str(rowN),num2str(colN));
        end

        % Cell selection callback: uitableSMeasures
        function uitableSMeasuresCellSelection(app, event)
            indices = event.Indices;
        end

        % Button pushed function: pbLoadData
        function pbLoadDataButtonPushed(app, event)
            global RawDataSet
            global PreprocessedDataSet
            global TruncatedDataSet
            global FileNameList
            global dataDimensionList


            removeCols=UTIL_GUI.getIdListFromString(app.editRemoveCols.Value);
            removeRows=UTIL_GUI.getIdListFromString(app.editRemoveRows.Value);

            selExt = app.pMenuDbFileExtension.Value;
            dbDirectoryPath=char(app.editDataDir.Value);
            %% if only one file to consider
            if app.chkLoadAllSelectedFiles.Value==0
                if ~isempty(app.editDataFileName.Value)
                    data = UTIL_LOAD_DATA.loadDataFiles(app,selExt,dbDirectoryPath,app.editDataFileName.Value);
                    %                 selTableData = app.uiTableSelectedFiles.Data;
                    %dataN = size(selTableData,1)

                    %% remove columns ID out of range
                    removeCols(:,removeCols>size(data,2))=[];
                    try
                        data(:,removeCols)=[];
                    catch
                    end
                    removeRows(:,removeRows>size(data,1))=[];
                    try
                        data(removeRows,:)=[];
                    catch
                    end
                    if isempty(data)
                        msgbox(strcat("Cannot load Data file :", app.editDataFileName.Value,"Dataset is empty after removing rows/columns is empty"));
                    else

                        if strcmp(app.pMenuDbFileExtension.Value,'mat')
                            %%do nothing

                        else

                            if isa(data,'double')
                                data = UTIL_LOAD_DATA.truncateEmptyCells(data);

                            else
                                data = UTIL_LOAD_DATA.truncateEmptyCells(str2double(data));

                            end
                        end

                        if app.chkLoadData_Uneven.Value ==1


                            if size(data,2) <2
                                msgbox(strcat(fileName,' has only one column, required at least two columns'));
                                return

                            end


                            if find(strcmp(app.pMenuTimeType_LD.Items,app.pMenuTimeType_LD.Value))==2
                                currSum=data(1,1);
                                for i=2:size(data,1)
                                    currSum = currSum+data(i,1);
                                    data(i,1)=currSum;
                                end
                            else
                                if sum(data(2:end,1)<data(1:end-1,1)) > 1
                                    msgbox('Time interval is not cummulative, so please choose interval')
                                    return
                                end
                            end

                            sampleFs=str2num(app.editSampleFS_LD.Value);

                            if app.chkResamplingFreq_LD.Value ==1
                                desiredFs = str2num(app.editResamplingFreq_LD.Value);
                            else
                                desiredFs =  size(data,1)/data(end,1);
                            end


                            methodID = find(strcmp(app.pMenuInterpolation_LD.Items,app.pMenuInterpolation_LD.Value));
                            allMethodList = {'linear', 'pchip', 'spline'};


                            if app.chkEndpointEffect_LD.Value ==1
                                [p,q] = rat(desiredFs/sampleFs);
                                % ensure an odd length filter
                                n = 10*q+1;
                                % use .25 of Nyquist range of desired sample rate
                                cutoffRatio = str2double(app.editCutOffRatio_LD.Value);
                                % construct lowpass filter
                                lpFilt = p * fir1(n, cutoffRatio * 1/q);
                                [data, Ty] = resample(data(:,2),data(:,1),desiredFs,allMethodList{methodID},p,q,lpFilt);
                            else
                                [data, Ty] = resample(data(:,2),data(:,1),desiredFs,allMethodList{methodID});
                            end


                        end


                        RawDataSet{size(RawDataSet,1)+1,1}=data;
                        FileNameList{size(FileNameList,1)+1,1}=app.editDataFileName.Value;
                        UTIL_LOAD_DATA.addLoadedFile(app,app.editDataFileName.Value,data);
                        %                         app.pMenuFilesUPP.Items{size(app.pMenuFilesUPP.Items,1)+1}=app.editDataFileName.Value;
                        %                         app.pMenuFilesTruncated.Items{size(app.pMenuFilesTruncated.Items,1)+1}=app.editDataFileName.Value;
                        %                         app.pMenuFilesLPP.Items{size(app.pMenuFilesLPP.Items,1)+1}=app.editDataFileName.Value;
                        %                         app.editDataFileName.Value='';
                        %% FileNameList{}
                    end
                end
            else


                isChecked = cell2mat(app.uiTableFilesName.Data(:,2));

                allSelIndexes = find(isChecked==1);
                allSelFileNames = app.uiTableFilesName.Data(allSelIndexes,1);
                for index=1:size(allSelIndexes,1)
                    data=UTIL_LOAD_DATA.loadDataFiles(app,selExt,dbDirectoryPath,allSelFileNames{index,1});
                    FileNameList{size(FileNameList,1)+1,1}=allSelFileNames{index,1};
                    %%remove rows and column ID out of range
                    tmpRemoveCols = removeCols;
                    tmpRemoveRows=removeRows;

                    try
                        data(:,removeCols)=[];

                    catch
                        msgbox('Cannot remove selected Columns');
                    end
                    try
                        data(removeRows,:)=[];
                    catch
                        msgbox('Cannot remove selected Rows');
                    end
                    fileName = allSelFileNames{index,1};
                    if isempty(data)
                        msgbox(strcat("Cannot load Data file :", fileName,"Dataset is empty after removing rows/columns is empty"));
                    else

                        if strcmp(app.pMenuDbFileExtension.Value,'mat')
                            %%do nothing

                        else
                            if isa(data,'double')
                                data = UTIL_LOAD_DATA.truncateEmptyCells(data);

                            else
                                data = UTIL_LOAD_DATA.truncateEmptyCells(str2double(data));

                            end
                        end


                        if app.chkLoadData_Uneven.Value ==1

                            if size(data,2) <2
                                msgbox(strcat(fileName,' has only one column, required at least two columns'));
                                continue;

                            end


                            if find(strcmp(app.pMenuTimeType_LD.Items,app.pMenuTimeType_LD.Value))==2
                                currSum=data(1,1);
                                for i=2:size(data,1)
                                    currSum = currSum+data(i,1);
                                    data(i,1)=currSum;
                                end
                            else
                                if sum(data(2:end,1)<data(1:end-1,1)) > 1
                                    msgbox('Time interval is not cummulative, so please choose interval')
                                    continue
                                end
                            end

                            sampleFs=str2num(app.editSampleFS_LD.Value);

                            if app.chkResamplingFreq_LD.Value ==1
                                desiredFs = str2num(app.editResamplingFreq_LD.Value);
                            else
                                desiredFs =  size(data,1)/data(end,1);
                            end


                            methodID = find(strcmp(app.pMenuInterpolation_LD.Items,app.pMenuInterpolation_LD.Value));
                            allMethodList = {'linear', 'pchip', 'spline'};


                            if app.chkEndpointEffect_LD.Value ==1
                                [p,q] = rat(desiredFs/sampleFs);
                                % ensure an odd length filter
                                n = 10*q+1;
                                % use .25 of Nyquist range of desired sample rate
                                cutoffRatio = str2double(app.editCutOffRatio_LD.Value);
                                % construct lowpass filter
                                lpFilt = p * fir1(n, cutoffRatio * 1/q);
                                [data, Ty] = resample(data(:,2),data(:,1),desiredFs,allMethodList{methodID},p,q,lpFilt);
                            else
                                [data, Ty] = resample(data(:,2),data(:,1),desiredFs,allMethodList{methodID});
                            end

                        end


                        RawDataSet{size(RawDataSet,1)+1,1}=data;
                        UTIL_LOAD_DATA.addLoadedFile(app,allSelFileNames{index,1},data);
                        %% add the file name in data preprocessing combo box
                        %                        app.pMenuFilesUPP.Items{size(app.pMenuFilesUPP.Items,1)+1}=allSelFileNames{index,1};

                        %app.pMenuFileNames.Items{size(app.pMenuFileNames.Items,1)+1}=allSelFileNames{index,1};
                        %app.pMenuFilesTruncated.Items{size(app.pMenuFilesTruncated.Items,1)+1}=allSelFileNames{index,1};
                        %app.pMenuFilesLPP.Items{size(app.pMenuFilesLPP.Items,1)+1}=allSelFileNames{index,1};


                    end
                end
            end

            PreprocessedDataSet=RawDataSet;
            TruncatedDataSet = RawDataSet;


            %             fileNames{1,1} = 'Select data';
            %             for index=1:size(app.uiTableSelectedFiles.Data,1)
            %                 fileNames{index+1,1}=app.uiTableSelectedFiles.Data{index,1};
            %             end
            %             app.pMenuUnloadFiles.Items=['Select file for unload';app.uiTableSelectedFiles.Data(:,1)];
            %

            %% just for test


            %             app.pMenuFilesLPP.Items=fileNames;
            %             fileNames{1,1}='Apply for all files';
            %
            %
            %             app.pMenuFilesUPP.Items = fileNames;
            %             app.pMenuFilesTruncated.Items=fileNames;
            %             app.pMenuFilesLPP.Items = fileNames;
            %
            %             app.pMenuFilesLPP.Value = app.pMenuFilesLPP.Items{1};
            %             app.pMenuFilesUPP.Value = app.pMenuFilesUPP.Items{1};
            %             app.pMenuFilesTruncated.Value=app.pMenuFilesTruncated.Items{1};

            %app.pMenuFileNames.Items={'--All Files--'};
            for fIndex=1:size(PreprocessedDataSet,1)
                dataDimensionList{fIndex,1}=1:size(PreprocessedDataSet{fIndex,1},2);
                app.pMenuFileNames.Items= [app.pMenuFileNames.Items FileNameList{fIndex}];
            end
            x=1;
        end

        % Cell selection callback: uiTableSelectedFiles
        function uiTableSelectedFilesCellSelection(app, event)

        end

        % Button pushed function: pbUnloadDataFile
        function pbUnloadDataFileButtonPushed(app, event)
            global RawDataSet
            global PreprocessedDataSet
            global dataDeletedIndex
            global dataDimensionList


            if find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value))==1
                for i=1:size(app.uiTableSelectedFiles.Data,1)
                    nUloadedFiles = size(app.uiTableFilesName.Data,1);
                    app.uiTableFilesName.Data{nUloadedFiles+1,1}=app.uiTableSelectedFiles.Data{i,1};
                    app.uiTableFilesName.Data{nUloadedFiles+1,2}=false;

                end
                app.uiTableSelectedFiles.Data={};
                RawDataSet={};
                PreprocessedDataSet={};
                dataDeletedIndex={};
                app.pMenuFileNames.Items = ["--All Files--","-- Range --"];

                dataDimensionList={};
                %                 app.pMenuFilesTruncated.Items = "Select data";
                %                 app.pMenuFilesUPP.Items = "Select data";
                %                 app.pMenuFilesLPP.Items = "Select data";
            else
                selIndex = find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value)==1)
                % if ~strcmp(app.pMenuUnloadFiles.Value,'Select file for unload')

                % selIndex = find(strcmp(app.uiTableSelectedFiles.Data(:,1),app.pMenuUnloadFiles.Value)==1);
                %selIndex = find(strcmp(app.uiTableSelectedFiles.Data(:,1),app.pMenuFileNames.Value)==1);
                RawDataSet(selIndex-1,:)=[];
                PreprocessedDataSet(selIndex-1,:)=[];

                dataDimensionList(selIndex-1,:)=[];
                %TruncatedDataSet(selIndex,:)=[];
                nUloadedFiles = size(app.uiTableFilesName.Data,1);
                app.uiTableFilesName.Data{nUloadedFiles+1,1}=char(app.pMenuFileNames.Items(:,selIndex));
                app.uiTableFilesName.Data{nUloadedFiles+1,2}=false;
                app.uiTableSelectedFiles.Data(selIndex-1,:)=[];
                app.pMenuFileNames.Items(:,selIndex)=[];
                %                     app.pMenuFilesUPP.Items(:,selIndex+1)=[];
                %                     pMenuFilesTruncated.Items(:,selIndex+1)=[];
                %                     app.pMenuFilesLPP.Items(:,selIndex+1)=[];
                %                     app.pMenuUnloadFiles.Items(:,selIndex+1)=[];
                % end

            end
            %app.editLoadedFileName.Value='';
            %     app.pMenuUnloadFiles.Value='Select file for unload';
        end

        % Value changed function: pMenuDataFileName
        function pMenuDataFileNameValueChanged(app, event)
            selectedFile = app.pMenuDataFileName.Value;

            if ~strcmp(selectedFile,app.pMenuDataFileName.Items{1})
                app.editDataFileName.Value=selectedFile;


                selExt = app.pMenuDbFileExtension.Value;
                dbDirectoryPath=app.editDataDir.Value;
                data=UTIL_LOAD_DATA.loadDataFiles(app,selExt,dbDirectoryPath,selectedFile);
                app.UITableFileData.Data = data;
            end


        end

        % Cell edit callback: uiTableSelectedFiles
        function uiTableSelectedFilesCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Button pushed function: pbPP_PreviewEffect
        function pbPP_PreviewEffectButtonPushed(app, event)
            global RawDataSet;

            selFileIndex=find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value))-2 ;
            selDimension = find(strcmp(app.pMenuDimension.Items,app.pMenuDimension.Value))-2 ;
            if find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value))<=2
                msgbox("Please Choose one File to Preview Effect.");
                return
            end

            if find(strcmp(app.pMenuDimension.Items,app.pMenuDimension.Value))<=2
                msgbox("Please Choose one Dimension to Preview Effect.");
                return
            end

            % pMenuPP_FilterType


            cla(app.UIAxesPreviewE);


            startVal = str2num(app.editPreviewLRange.Value);
            endVal = str2num(app.editPreviewURange.Value);
            lPass = str2num(app.editLowBand.Value);
            hPass = str2num(app.editHighBand.Value);

            if app.chkPP_Epoch.Value==1
                outlierWindowL = str2num(app.editEpochT.Value)*str2num(app.editSampleR.Value);
            else
                outlierWindowL=size(RawDataSet{selFileIndex,1},1);
            end


            if find(strcmp(app.pMenuPP_FilterType.Items,app.pMenuPP_FilterType.Value))==1
                msgbox('Please select one of the options');
                %% outlier
            elseif find(strcmp(app.pMenuPP_FilterType.Items,app.pMenuPP_FilterType.Value))==2
                %%Outlier Median

                for chkVal=startVal:endVal

                    %data= UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),false,true,false,false,-1,-1,-1,medianFOrdeflagRemoveO,flagMedianFilter,flagApplyFilter,flagPerEpoch,lowPass,highPass,medianFOrder);
                    data=UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),true,false,false,chkVal,outlierWindowL,-1,-1,-1);
                    plot(app.UIAxesPreviewE,data);
                    hold(app.UIAxesPreviewE,'on');

                end
                myLegend=strcat('MedianF ',string(startVal:endVal));


            elseif find(strcmp(app.pMenuPP_FilterType.Items,app.pMenuPP_FilterType.Value))==3
                %%Outlier Median

                for chkVal=startVal:endVal

                    %data= UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),false,true,false,false,-1,-1,-1,medianFOrdeflagRemoveO,flagMedianFilter,flagApplyFilter,flagPerEpoch,lowPass,highPass,medianFOrder);
                    data=UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),false,true,false,-1,-1,chkVal,-1,-1);
                    plot(app.UIAxesPreviewE,data);
                    hold(app.UIAxesPreviewE,'on');

                end
                myLegend=strcat('MedianF ',string(startVal:endVal));


            elseif find(strcmp(app.pMenuPP_FilterType.Items,app.pMenuPP_FilterType.Value))==4
                %%Outlier Median

                for chkVal=startVal:endVal

                    %data= UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),false,true,false,false,-1,-1,-1,medianFOrdeflagRemoveO,flagMedianFilter,flagApplyFilter,flagPerEpoch,lowPass,highPass,medianFOrder);
                    data=UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),false,false,true,-1,-1,-1,chkVal,hPass);
                    plot(app.UIAxesPreviewE,data);
                    hold(app.UIAxesPreviewE,'on');

                end
                myLegend=strcat('LowPass ',string(startVal:endVal));


            elseif find(strcmp(app.pMenuPP_FilterType.Items,app.pMenuPP_FilterType.Value))==5
                %%Outlier Median

                for chkVal=startVal:endVal

                    %data= UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),false,true,false,false,-1,-1,-1,medianFOrdeflagRemoveO,flagMedianFilter,flagApplyFilter,flagPerEpoch,lowPass,highPass,medianFOrder);
                    data=UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),false,false,true,-1,-1,-1,lPass,chkVal);
                    plot(app.UIAxesPreviewE,data);
                    hold(app.UIAxesPreviewE,'on');

                end
                myLegend=strcat('LowPass ',string(startVal:endVal));
           elseif find(strcmp(app.pMenuPP_FilterType.Items,app.pMenuPP_FilterType.Value))==6

                for chkVal=startVal:endVal

                    %data= UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),false,true,false,false,-1,-1,-1,medianFOrdeflagRemoveO,flagMedianFilter,flagApplyFilter,flagPerEpoch,lowPass,highPass,medianFOrder);
                    %data=UTIL_PROCESS_DATA.processData(app,RawDataSet{selFileIndex,1}(:,selDimension),false,false,true,-1,-1,-1,lPass,chkVal);


                    y = detrend(RawDataSet{selFileIndex,1}(:,selDimension),chkVal);

            
                    plot(app.UIAxesPreviewE,y);
                    hold(app.UIAxesPreviewE,'on');

                end
                myLegend=strcat('Detrending P. Degree(n) ',string(startVal:endVal));


            end
            legend(app.UIAxesPreviewE,myLegend);

        end

        % Button pushed function: pbSaveTableData
        function pbSaveTableDataButtonPushed(app, event)

            filename=strcat(app.editTableDirectory.Value,'/',app.editTableDataFName.Value);
            dataToWrite = app.tableNoEvent.Data;
            % dataToWrite(cellfun(@isnan,dataToWrite)) = {[]};
            xlswrite(filename,[app.tableNoEvent.ColumnName';dataToWrite]);
        end

        % Button pushed function: pbTableDirectory
        function pbTableDirectoryButtonPushed(app, event)
            app.editTableDirectory.Value = uigetdir('../');
        end

        % Button pushed function: pbDeleteDataRange
        function pbDeleteDataRangeButtonPushed(app, event)

            global leftLocUp
            global rightLocUp
            global dataDeletedIndex
            global PreprocessedDataSet
            global RawDataSet

            selFileIndex = UTIL_GUI.getFileIDList(app);
            %selVIndex = find(strcmp(app.pMenuDimensionPP.Items,app.pMenuDimensionPP.Value))-1;


            if  size(selFileIndex,2)>1 || isempty(selFileIndex)
                msgbox('Please select File ');
                return
            end
            try
                dataDeletedIndex{selFileIndex,1}=[dataDeletedIndex{selFileIndex,1};[ceil(leftLocUp) ceil(rightLocUp)]];
                dataDeletedIndex{selFileIndex,1} = UTIL_PROCESS_DATA.mergeOverlapping(dataDeletedIndex{selFileIndex,1});

            catch
                dataDeletedIndex{selFileIndex,1}=[ceil(leftLocUp) ceil(rightLocUp)];
            end
            PreprocessedDataSet{selFileIndex,1}=UTIL_PROCESS_DATA.getTruncatedDataSet(RawDataSet{selFileIndex,1},dataDeletedIndex{selFileIndex,1});
            msgbox(strcat('Dataset is truncated for file ',app.pMenuFileNames.Value,'. Previous Pre-process data is lost for the file.'));
        end

        % Button pushed function: pbTruncateData
        function pbTruncateDataButtonPushed(app, event)
            global RawDataSet;
            global PreprocessedDataSet
            global ALL_StatisticCol
            global dataDeletedIndex;


            SAMPLING_RATE = str2double(app.editSampleR.Value);
            START_TIME_IND_SECOND =  str2double(app.editStartT.Value);
            startIndex = (START_TIME_IND_SECOND-1)*SAMPLING_RATE+1;


            applyList=UTIL_GUI.getFileIDList(app);


            for i=1:size(applyList,2)
                RawData= RawDataSet{applyList(i),1};
                currTruncatedIndex=[];
                if startIndex >1
                    currTruncatedIndex=[1 startIndex-1];
                end
                MAX_DATA_LENGTH_SECOND = str2double(app.editMaxLength.Value);
                %% truncate date to startIndex
                currTruncatedData = RawData(startIndex:end,:);
                %% truncate data to maximum lenght
                maxIndex = min(MAX_DATA_LENGTH_SECOND*SAMPLING_RATE,size(currTruncatedData,1));

                if size(RawData,1)>maxIndex
                    currTruncatedIndex=[currTruncatedIndex;[maxIndex+1 size(RawData,1)]];
                end
                dataDeletedIndex{applyList(i),1}=currTruncatedIndex;
                PreprocessedDataSet{applyList(i),1}=UTIL_PROCESS_DATA.getTruncatedDataSet(RawData,currTruncatedIndex);
                ALL_StatisticCol{applyList(i),CONS_INDEX.outliers}={};
            end
            pbClearPlot_Callback(app, event);


        end

        % Value changed function: chkAllEpoch
        function chkAllEpochValueChanged(app, event)

            app.chkAllEpoch.Value = 1;
            app.chkSelEpoch.Value = 0;
            app.chkSingleData.Value=0;
        end

        % Value changed function: chkSingleData
        function chkSingleDataValueChanged(app, event)
            app.chkAllEpoch.Value = 0;
            app.chkSelEpoch.Value = 0;
            app.chkSingleData.Value=1;

        end

        % Value changed function: pMenuFileNames
        function pMenuFileNamesValueChanged(app, event)
            %   global dataDimensionList


            fileIndex = find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value))-2;

            if  fileIndex==0
                app.editFileRange.Enable = true;
            else
                app.editFileRange.Enable = false;
            end
            %             if fileIndex<1
            %                 %% do nothing..
            %                 app.pMenuDimension.Items={'--All Columns--','-- Range --','1'};
            %                % app.editTestFileIDTo_2.Value=strcat('1-',num2str(size(PreprocessedDataSet,1)));
            %             else
            %                 %fileIndex = find(strcmp(app.pMenuFileNames.Items,app.pMenuFileNames.Value))-2;
            %                 %app.editTestFileIDTo_2.Value=strcat(fileIndex);
            %                 app.pMenuDimension.Items={'--All Columns--'};
            %                 app.pMenuDimension.Items{2}='-- Range --';
            %                 for i=1:50
            %                     app.pMenuDimension.Items{i+2}=num2str(i);
            %                 end
            % %                 for i=1:size(dataDimensionList{fileIndex,1},1)
            % %                     app.pMenuDimension.Items{i+2}=num2str(i);
            % %                 end
            %
            %            end


            if find(strcmp(app.pMenuAppMode.Items,app.pMenuAppMode.Value)) ==2
                pbClearPlot_Callback(app, event);
                %pbPlotDataButtonPushed(app, event);
            end

        end

        % Callback function
        function chkDeleteRangeValueChanged(app, event)
            pbClearPlot_Callback(app, event);
            app.pbDeleteDataRange.Enable=app.chkDeleteRange.Value==1;

        end

        % Value changed function: chkShowTruncatedData
        function chkShowTruncatedDataValueChanged(app, event)
            app.pbDeleteDataRange.Enable=app.chkShowTruncatedData.Value==1;

            pbPlotDataButtonPushed(app, event);
        end

        % Button pushed function: pbRecoverRawData
        function pbRecoverRawDataButtonPushed(app, event)
            global RawDataSet;
            global PreprocessedDataSet
            global ALL_StatisticCol
            global dataDeletedIndex;


            applyList = UTIL_GUI.getFileIDList(app);


            msg=[];
            for i=1:size(applyList,2)


                dataDeletedIndex{applyList(i),1}=[];
                PreprocessedDataSet{applyList(i),1}=RawDataSet{applyList(i),1};
                ALL_StatisticCol{applyList(i),CONS_INDEX.outliers}={};
                currFileName = app.pMenuFileNames.Items(applyList(i)+2);
                msg=[msg; strcat('Raw data recovered from file :',currFileName)];
            end
            pbClearPlot_Callback(app, event);
            msgbox(msg);
        end

        % Button pushed function: pbRecoverTruncatedData
        function pbRecoverTruncatedDataButtonPushed(app, event)

            global dataDeletedIndex
            global PreprocessedDataSet
            global RawDataSet

           selFileIndex = UTIL_GUI.getFileIDList(app);

            if  size(selFileIndex,2)>1 || isempty(selFileIndex)
                msgbox('Please select File ');
                return
            end
            selDimensionIndex = UTIL_GUI.getDimensionList(app);


            beforeProcessData=UTIL_PROCESS_DATA.getTruncatedDataSet(RawDataSet{selFileIndex,1},dataDeletedIndex{selFileIndex,1});

            
            PreprocessedDataSet{selFileIndex,1}(:,selDimensionIndex)=beforeProcessData(:,selDimensionIndex);
            msgbox(strcat('Truncated Data for Column ',num2str(selDimensionIndex),' is Recovered...'));


        end

        % Button pushed function: pbPreprocessRefresh
        function pbPreprocessRefreshButtonPushed(app, event)
            global dataDeletedIndex
            global PreprocessedDataSet
            global ALL_StatisticCol


            selFileIndex = UTIL_GUI.getFileIDList(app); ;
            selDimensionIndex = UTIL_GUI.getDimensionList(app);

         
            try
                outlierRec=[];
                for fIndex=selFileIndex
                    %% for deletion we do not need dimension

                    OutlierInfoCol = ALL_StatisticCol{fIndex,CONS_INDEX.outliers}

                    currDimList=selDimensionIndex;


                    if ~isempty(OutlierInfoCol)
                        for v=currDimList
                            tmpValue=OutlierInfoCol(OutlierInfoCol(:,1)==v,:);
                            outlierRec=[outlierRec;fIndex*ones(size(tmpValue,1),1) tmpValue];
                        end
                    end

                end
            catch
            end


            deletedRec=[];
            if ~isempty(dataDeletedIndex)
                for fIndex=selFileIndex
                    %% for deletion we do not need dimension
                    deletedRec = [deletedRec;ones(size(dataDeletedIndex{fIndex,1},1),1)*fIndex dataDeletedIndex{fIndex,1}];

                    if selDimensionIndex==0
                        currDimList=1:size(PreprocessedDataSet{fIndex,1},2);
                        %% deepak
                    end
                end
            end


            deletedInfo={};

            for i=1:size(deletedRec,1)
                deletedInfo{i,1}=app.pMenuFileNames.Items{deletedRec(i,1)+2};
                deletedInfo{i,2}=deletedRec(i,2);
                deletedInfo{i,3}=deletedRec(i,3);
            end
            outlierInfo={};
            for i=1:size(outlierRec,1)
                outlierInfo{i,1}=app.pMenuFileNames.Items{outlierRec(i,1)+1};
                outlierInfo{i,2}=outlierRec(i,2);
                outlierInfo{i,3}=outlierRec(i,3);
                outlierInfo{i,4}=outlierRec(i,4);
                outlierInfo{i,5}=outlierRec(i,5);
            end


            app.UITablePP_Outliers.Data = outlierInfo;

            app.UITablePP_Removed.Data = deletedInfo;
        end

        % Value changed function: pMenuViewBy
        function pMenuViewByValueChanged(app, event)


            viewIndex = find(strcmp(app.pMenuViewBy.Items,app.pMenuViewBy.Value));


            if viewIndex==1
                app.pMenuFileNames.Value=app.pMenuFileNames.Items{1};

            elseif viewIndex==2
                app.pMenuEpochs.Value=app.pMenuEpochs.Items{1};

            elseif viewIndex==3
                app.pMenuDimension.Value=app.pMenuDimension.Items{1};

            elseif viewIndex==4
                app.pMenuScale.Value=app.pMenuScale.Items{1};

            end
        end

        % Button pushed function: pbPPClearPlots
        function pbPPClearPlotsButtonPushed(app, event)
            global PPplotList

            PPplotList=[];
            cla(app.UIAxesPPRaw);
            cla(app.UIAxesPPProcessed);
        end

        % Button pushed function: pbPPPlotData
        function pbPPPlotDataButtonPushed(app, event)
            global PPplotList
            global PreprocessedDataSet
            global RawDataSet
            global dataDeletedIndex

            myLegend=[];

            fIndex = UTIL_GUI.getFileIDList(app);
            dIndex = UTIL_GUI.getDimensionList(app);

            if size(fIndex,2) > 1 || size(dIndex,2) > 1
                msgbox('Please select a data file and a data column');
                return
            end

            if isempty(PPplotList)
                PPplotList=[PPplotList;fIndex dIndex];

            elseif sum(PPplotList(:,1)==fIndex)>0 && sum(PPplotList(:,2)==dIndex)>0
                %%don nothing
            else

                PPplotList=[PPplotList;fIndex dIndex];
            end

            cla(app.UIAxesPPRaw);
            cla(app.UIAxesPPProcessed);

            %% get color list

            maxPlot = size(PPplotList,1);


            cd('../com/ExternalPackages/maxdistcolor');
            fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
            myColorList = maxdistcolor(maxPlot,fun);

            %% moving back
            cd('../../../scripts');


            hold(app.UIAxesPPRaw,'on');
            hold(app.UIAxesPPProcessed,'on');
            myLegend=[];

            for i=1:size(PPplotList,1)
                fIndex = PPplotList(i,1);
                dIndex = PPplotList(i,2);
                myLegend=[myLegend strcat(app.pMenuFileNames.Items(fIndex+2),'-col',num2str(dIndex))];
                if find(strcmp(app.pMenuPPViewMode.Items,app.pMenuPPViewMode.Value))==2
                    currRawData = UTIL_PROCESS_DATA.getTruncatedDataSet(RawDataSet{fIndex,1},dataDeletedIndex{fIndex,1});

                else
                    currRawData = RawDataSet{fIndex,1};
                end
                plot(app.UIAxesPPRaw,currRawData(:,dIndex),'color',myColorList(i,:));
                plot(app.UIAxesPPProcessed,PreprocessedDataSet{fIndex,1}(:,dIndex),'color',myColorList(i,:));

            end
            % legend(app.UIAxesPPRaw,myLegend);
            hold(app.UIAxesPPRaw,'off');
            hold(app.UIAxesPPProcessed,'off');
            legend(app.UIAxesPPRaw,myLegend);
        end

        % Button pushed function: btmOpenHelpFile
        function btmOpenHelpFileButtonPushed(app, event)

            selIndex = find(strcmp(app.pMenuHelp.Items,app.pMenuHelp.Value));
            if selIndex ==1
                help_file_name='../doc/CEPS_2_Manual v1.pdf';
            elseif selIndex ==2
                help_file_name='../doc/CEPS_2_Primer_v1.pdf';
            elseif selIndex==3
                help_file_name='../doc/Usability_feedback_form.docx';
            else
                help_file_name='../doc/ReadMe.txt';

            end
            if isunix
                system(['evince ' help_file_name ' &']);
            elseif ispc
                if selIndex<=3
                    open(help_file_name);
                else
                    eval(['!notepad',' ',help_file_name]);
                end
            else
                msgbox(['Open ' help_file_name 'in the operating system'],'Product Help');
            end
        end

        % Button pushed function: pbSaveDataFile
        function pbSaveDataFileButtonPushed(app, event)
            global PreprocessedDataSet
            global f
            fileNameCol = app.pMenuFileNames.Items;
            f= waitbar(0,'0','Name','Saving Data in txt .....');

            for i=3:size(fileNameCol,2)
                progressTxt = strcat(num2str(i),'/',num2str(size(fileNameCol,2)));
                f = waitbar(i/size(fileNameCol,2),f,progressTxt,'Name','Saving data in txt file.....');
                dotLocations = find(fileNameCol{i} == '.')
                if isempty(dotLocations)
                    % No dots at all found so just take entire name.
                    nameBeforeFirstDot = fileNameCol{i};
                else
                    % Take up to , but not including, the first dot.
                    nameBeforeFirstDot = fileNameCol{i}(1:dotLocations(1)-1);
                end
                dataToWrite=PreprocessedDataSet{i-2,1};


                currFileName = strcat('../outputs/',app.editFilePrefix.Value,'_',nameBeforeFirstDot,'_',app.editFileSuffix.Value,'.txt');
                fid = fopen(currFileName,'wt');
                for ii = 1:size(dataToWrite,1)
                    fprintf(fid,'%g\t',dataToWrite(ii,:));
                    fprintf(fid,'\n');
                end
                fclose(fid);

            end
            close(f);
        end

        % Button pushed function: pbPreviewAddNoise
        function pbPreviewAddNoiseButtonPushed(app, event)
            global PreprocessedDataSet

            selFileIndex = UTIL_GUI.getFileIDList(app);
            selDimensionIndex = UTIL_GUI.getDimensionList(app);

            selFileNo = size(selFileIndex ,2) ;
            selDimensionNo= size(selDimensionIndex,2);
            selNoiseTypeNo = find(strcmp(app.pMenuNoiseType.Items,app.pMenuNoiseType.Value)) ;


            if selDimensionNo<=2
                msgbox("Please choose a Column");
                return;
            end


            if selFileNo<=2
                msgbox("Please choose a file");
                return;
            end

            if selNoiseTypeNo==1
                msgbox("Please choose a NoiseType");
                return;
            end

            data = PreprocessedDataSet{selFileNo-2,1};
            noisyData = data(:,selDimensionNo-2);


            %plot(app.UIAxesCG_Original,data);


            [noisyData,info]=UTIL_PROCESS_DATA.addNoise(app,noisyData);
            %cla(app.UIAxesCoarseGrain);

            %plot(app.UIAxesCoarseGrain,noisyData);

            app.PanelChk.AutoResizeChildren='off';

            for i=1:size(app.PanelChk.Children,1)
                cla(app.PanelChk.Children(i))
            end

            ax1= subplot(2,1,1, 'Parent',app.PanelChk)
            plot(ax1,data)
            legend(ax1,'original Data')
            ax2= subplot(2,1,2, 'Parent',app.PanelChk)
            plot(ax2,noisyData)
            legend(ax2,info);
            title(ax1, 'Adding Noise in dataset')
        end

        % Value changed function: pMenuEpochs
        function pMenuEpochsValueChanged(app, event)
            %value = app.pMenuEpochs.Value;

            epochID = find(strcmp(app.pMenuEpochs.Items,app.pMenuEpochs.Value))-3;

            if  epochID==0
                app.editEpochRange.Enable = true;
            else
                app.editEpochRange.Enable = false;
            end

        end

        % Value changed function: pMenuScale
        function pMenuScaleValueChanged(app, event)
            scaleID = find(strcmp(app.pMenuScale.Items,app.pMenuScale.Value))-1;

            if  scaleID==0
                app.editScaleRange.Enable = true;
            else
                app.editScaleRange.Enable = false;
            end
        end

        % Callback function
        function ViewCoarseGrainEffectButtonPushed(app, event)
            global PreprocessedDataSet


            fileList = UTIL_GUI.getFileIDList(app);

            %columnList=UTIL_GUI.getDimensionList(app);

            scaleList=UTIL_GUI.getScaleList(app);
            slideBy = UTIL_GUI.getSlideBy(app);


            %clf(app.UIAxesCoarseGrain);

            functionID = find(strcmp(app.pMenuScaleAggregate.Items,app.pMenuScaleAggregate.Value));

            totalRange=((functionID==1 ) + (size(scaleList,2) >1) + (slideBy==-1));

            if totalRange > 1
                msgbox('Range is only for one of Scale or Slide By or Agregate');
                return;
            end


            currData = PreprocessedDataSet{fileList,1};


            cla(app.UIAxesCoarseGrain.cla)

            cd('../com/ExternalPackages/maxdistcolor');
            if (size(scaleList,2)>1 )  || ( slideBy~=-1 && functionID~=1 && size(scaleList,2)==1)

                currFun = app.pMenuScaleAggregate.Value;
                maxPlot=size(scaleList,2)+1;
                fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
                distColorList = maxdistcolor(maxPlot,fun);

                hold(app.UIAxesCoarseGrain,'on');
                plot(app.UIAxesCoarseGrain,currData,'color',distColorList(1,:));


                %%for all scale list..s
                legendText{1,1}='original Data';
                colorIndex =2;
                for s=scaleList
                    startIds=1:slideBy:(size(currData,1)-s+1);
                    tmpData =[];
                    legendText{1,colorIndex} = strcat('Scale - ',num2str(s));
                    for id=startIds
                        %% scale is s
                        tmpData = [tmpData eval(strcat(currFun,'(currData(id:id+s-1))'))];
                    end

                    plot(app.UIAxesCoarseGrain,tmpData,'color',distColorList(colorIndex,:));


                    colorIndex=colorIndex+1;
                end
                legend(app.UIAxesCoarseGrain,legendText);
                %% trying all slides with fix scalse
            elseif  slideBy==-1

                hold(app.UIAxesCoarseGrain,'on')

                maxPlot=scaleList+1;
                fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
                distColorList = maxdistcolor(maxPlot,fun);


                plot(app.UIAxesCoarseGrain,currData,'color',distColorList(1,:));
                scale=scaleList(1,1);
                currFun = app.pMenuScaleAggregate.Value;
                %% for all slide window from 1 to scaleList
                legendText{1,1}='original Data';
                for s=1:scaleList
                    startIds=1:s:(size(currData,1)-scale+1);
                    tmpData =[];
                    legendText{1,s+1} = strcat('SlideW - ',num2str(s));
                    for id=startIds
                        %% scale is scale
                        tmpData = [tmpData eval(strcat(currFun,'(currData(id:id+scale-1))'))];
                    end
                    plot(app.UIAxesCoarseGrain,tmpData,'color',distColorList(s+1,:));

                end
                legend(app.UIAxesCoarseGrain,legendText);

            elseif find(strcmp(app.pMenuScaleAggregate.Items,app.pMenuScaleAggregate.Value))==1

                maxPlot=size(app.pMenuScaleAggregate.Items,2);
                fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
                distColorList = maxdistcolor(maxPlot,fun);

                hold(app.UIAxesCoarseGrain,'on')
                plot(app.UIAxesCoarseGrain,currData,'color',distColorList(1,:));
                %%slide and scale if fix...
                scale=scaleList(1,1);
                slide=slideBy(1,1);
                startIds=1:slide:(size(currData,1)-scale+1);

                %%for all aggregation function except 'all' (first one)

                legendText{1,1}='original Data';
                for i=1:size(app.pMenuScaleAggregate.Items,2)-1
                    currFun = app.pMenuScaleAggregate.Items{i+1};
                    legendText{1,i+1}=currFun;
                    tmpData =[];
                    for id=startIds
                        tmpData = [tmpData eval(strcat(currFun,'(currData(id:id+scale-1))'))];
                    end
                    plot(app.UIAxesCoarseGrain,tmpData,'color',distColorList(i+1,:));


                end
                legend(app.UIAxesCoarseGrain,legendText);

            end
            cd('../../../scripts');


        end

        % Callback function: ApplyandSaveButton, MVerificationTab
        function ApplyCoarseGrainButtonPushed(app, event)


            global PreprocessedDataSet
            global FileNameList

            nFineGrid = str2num(app.pMenuFineGrid.Value);

            fileList = UTIL_GUI.getFileIDList(app);
            scaleList=UTIL_GUI.getScaleList(app);
            slideBy = UTIL_GUI.getSlideBy(app);


            columnList = UTIL_GUI.getDimensionList(app);

            isCourseGrain = app.chkReduce.Value==1;
            isAddNoise= app.chkAddNoise.Value==1;
            isNormalization = app.chkNormalization.Value==1;
            iscutFile = app.chkCutFile.Value ==1;
            isFineGrid = app.chkIncrease.Value==1;
            isBinarization = app.chkBinarisation.Value ==1;


            if not (isCourseGrain || isAddNoise || isNormalization || iscutFile || isFineGrid||isBinarization)
                msgbox('Please select at least one optinos.');
                return
            end

            if size(fileList,2)>1 && size(columnList,2)>1
                msgbox('Please select one of file and column...');
                return;
            end

            if isAddNoise
                if find(strcmp(app.pMenuNoiseType.Items,app.pMenuNoiseType.Value))==1
                    msgbox('Please select one of noise you want to add.');
                    return
                end
            end

            interpolationList=[];
            if isFineGrid
                interpolationList = UTIL_GUI.getInterpolationList(app);
                if size(interpolationList,2)==0
                    msgbox('Please select interpolation method');
                    return;
                elseif size(interpolationList,2)> 1
                    msgbox('Please select only one Interpolation Method');
                    return;
                end
            end
            normalizationList=[];
            if isNormalization
                normalizationList = UTIL_GUI.getNormazationList(app);
                if size(normalizationList,2)==0
                    msgbox('Please select normalisation method');
                    return;
                elseif size(normalizationList,2)>1
                    msgbox('Please select only one normalisation Method');
                    return;
                end
            end
            totalRange = (size(interpolationList,2)>1)+(size(normalizationList,2)>1);

            scaleList=[];
            slideBy=[];
            functionID=[];


            if isCourseGrain
                scaleList=UTIL_GUI.getScaleList(app);
                slideBy = UTIL_GUI.getSlideBy(app);
                functionID = find(strcmp(app.pMenuScaleAggregate.Items,app.pMenuScaleAggregate.Value));
                if functionID == 1
                    msgbox('Please select only one Aggregation function');
                    return;
                end

                if isempty(scaleList)
                    msgbox('Scale is missing...');
                    return;
                elseif size(scaleList,2)>1
                    msgbox('Please select only single value for Scale');
                    return;
                end

                if isempty(functionID)
                    msgbox('Please select Aggregation function')
                    return;
                elseif size(functionID,2)>1
                    msgbox('Please select only single value for Aggregation function By');
                    return;
                end

                if isempty(slideBy)
                    msgbox('Please select Slide By')
                    return;
                elseif size(slideBy,2)>1
                    msgbox('Please select only single value for Slide By');
                    return;
                end


            end

            if isBinarization
                binarisationFID = find(strcmp(app.pMenuBinarisation.Items,app.pMenuBinarisation.Value));
                if binarisationFID ==1
                    msgbox('Please choose one the the binarisation method')
                    return;
                end
            end


            txtMessage ='';
            f = waitbar(0,'Progression..... ');
            fCount=1;
            for fileID = fileList


                progressTxt = strcat(num2str(fCount),'/',num2str(size(fileList,2)));
                f = waitbar(fCount/size(fileList,2),f,progressTxt,'Name','Saving results in txt file.....');

                dotLocations = find(FileNameList{fileID} == '.')
                if isempty(dotLocations)
                    % No dots at all found so just take entire name.
                    nameBeforeFirstDot = FileNameList{fileID};
                else
                    % Take up to , but not including, the first dot.
                    nameBeforeFirstDot = FileNameList{fileID}(1:dotLocations(1)-1);
                end


                clear currData

                currData{1,1}=  PreprocessedDataSet{fileID,1};


                %% normalization
                info='';
                if isNormalization


                    [currData,~]= UTIL_EXTRA.applyNormalization(app,normalizationList,currData,'');
                    info='_std'
                end

                if isFineGrid

                    [currData,~]=UTIL_EXTRA.applyFineGrane(app,interpolationList,nFineGrid,currData,'');
                    info = strcat(info,'_fGrid');
                end

                if isCourseGrain
                    [currData,~]=UTIL_EXTRA.applyCourseGrain(app,scaleList,slideBy,functionID,currData,'');
                    info = strcat(info,'_cGrain')
                end


                if isAddNoise
                    [tmpData,~]=UTIL_PROCESS_DATA.addNoise(app,cell2mat(currData));
                    currData{1,1}=tmpData;
                    info = strcat(info,'_nAdded')
                end

                if isBinarization

                    currData{1,1}=UTIL_EXTRA.applyBinarization(currData{1,1},binarisationFID);
                    info = strcat(info,'_Binarisation')
                end

                if iscutFile

                    try


                        [startLine,endLine,nDiv]=UTIL_EXTRA.getCutFileParameters(app,currData{1,1});

                    catch
                        msgbox({'Problem in selection of paremeters...','Please try different combinations'});
                        return
                    end


                else
                    startLine=[1];
                    %endLine=[size(cell2mat(currData),1)];
                    endLine = length(cell2mat(currData));

                    %PreprocessedDataSet{fileID,1}=currData;

                    % txtMessage = strcat(txtMessage,'\n',FileNameList{fileID,1});
                end


                currData= cell2mat(currData);
                for sIndex=1:size(startLine,2)

                    dataToSave = currData(startLine(1,sIndex):endLine(1,sIndex));
                    if iscutFile
                        currFileName = strcat('../outputs/',app.editCG_Prefix.Value,'_',nameBeforeFirstDot,info,'_',app.editCG_Suffix.Value,'_s',num2str(sIndex),'.txt');
                    else
                        currFileName = strcat('../outputs/',app.editCG_Prefix.Value,'_',nameBeforeFirstDot,info,'_',app.editCG_Suffix.Value,'.txt');
                    end
                    fid = fopen(currFileName,'wt');
                    if fid==-1
                        txtMessage = strcat(txtMessage,'\n ', currFileName, ' already exist.');
                        msgbox(txtMessage)
                    else

                        for ii = 1:size(dataToSave,1)
                            fprintf(fid,'%g\t',dataToSave(ii,:));
                            fprintf(fid,'\n');
                        end
                        fclose(fid);
                        fCount=fCount+1;
                        %msgbox(sprintf(txtMessage));

                    end

                end


            end
            close(f);

        end

        % Button pushed function: ViewCG_Effecs
        function ViewCG_EffecsButtonPushed(app, event)
            global PreprocessedDataSet


            nFineGrid = str2num(app.pMenuFineGrid.Value);

            fileList = UTIL_GUI.getFileIDList(app);

            columnList = UTIL_GUI.getDimensionList(app);

            isCourseGrain = app.chkReduce.Value==1;
            isAddNoise= app.chkAddNoise.Value==1;
            isNormalization = app.chkNormalization.Value==1;
            iscutFile = app.chkCutFile.Value ==1;
            isFineGrid = app.chkIncrease.Value==1;
            isBinarization = app.chkBinarisation.Value ==1;


            if not (isCourseGrain || isAddNoise || isNormalization || iscutFile || isFineGrid || isBinarization)
                msgbox('Please select at least one optinos.');
                return
            end


            %isCourseGrain =  (find(strcmp(app.pMenuCoarseGrain.Items,app.pMenuCoarseGrain.Value)))==2;

            app.PanelChk.AutoResizeChildren='off';

            for i=1:size(app.PanelChk.Children,1)
                cla(app.PanelChk.Children(i));
            end
            ax1= subplot(2,1,1, 'Parent',app.PanelChk);
            % plot(ax1,data)

            %app.UIAxesCoarseGrain.cla;
            %app.UIAxesCG_Original.cla;

            if size(fileList,2)>1 || size(columnList,2)>1
                msgbox('Please select one of file and column...');
                return;
            end

            if isAddNoise
                if find(strcmp(app.pMenuNoiseType.Items,app.pMenuNoiseType.Value))==1
                    msgbox('Please select one of noise you want to add.');
                    return
                end
            end


            ax2= subplot(2,1,2, 'Parent',app.PanelChk);
            %  plot(ax1,data)


            %%check for parameters.....


            maxPlot=1;
            interpolationList=[];
            if isFineGrid
                interpolationList = UTIL_GUI.getInterpolationList(app);
                if size(interpolationList,2)==0
                    msgbox('Please select interpolation method');
                elseif size(interpolationList,2)> 1
                    maxPlot = size(interpolationList,2);
                end
            end
            normalizationList=[];
            if isNormalization
                normalizationList = UTIL_GUI.getNormazationList(app);
                if size(normalizationList,2)==0
                    msgbox('Please select normalisation method');
                elseif size(normalizationList,2)>1
                    maxPlot=size(normalizationList,2);
                end
            end
            totalRange = (size(interpolationList,2)>1)+(size(normalizationList,2)>1);

            scaleList=[];
            slideBy=[];
            functionID=[];

            countRangeInCG=0;


            if isCourseGrain
                scaleList=UTIL_GUI.getScaleList(app);
                slideBy = UTIL_GUI.getSlideBy(app);
                functionID = find(strcmp(app.pMenuScaleAggregate.Items,app.pMenuScaleAggregate.Value));
                if functionID == 1
                    functionID = [2:size(app.pMenuScaleAggregate.Items,2)];
                    maxPlot = size(functionID,2);
                end

                if isempty(scaleList)
                    msgbox('Scale is missing...');
                    return;
                elseif size(scaleList,2)>1
                    maxPlot = size(scaleList,2);
                end

                if isempty(functionID)
                    msgbox('Please select Aggregation function')
                    return;
                elseif size(functionID,2)>1
                    maxPlot = size(functionID,2);
                end

                if isempty(slideBy)
                    msgbox('Please select Slide By')
                    return;
                elseif size(slideBy,2)>1
                    maxPlot = size(slideBy,2);
                end

                countRangeInCG = sum((size(functionID,2)>1)+(size(scaleList,2)>1)+(size(slideBy,2)>1));

                if countRangeInCG > 1
                    msgbox('Please use range only in one of the three options : Scale/s, Slide By &/or Aggregate');
                    return;
                end

            end


            totalRange = totalRange +countRangeInCG;

            if totalRange >1

                msgbox('Please use range only in one of the three options : normalisation/ interploation/ coursegrain  ');
                return;
            end


            cd('../com/ExternalPackages/maxdistcolor');
            %maxPlot=size(interpolationList,2) + size(normalizationList,2)+ size(scaleList,1);
            fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
            distColorList = maxdistcolor(maxPlot,fun);
            cd('../../../scripts');


            currData =PreprocessedDataSet{fileList};

            %plot(ax1,currData);


            plot(ax1,currData,'color','black');


            legendText=cell(maxPlot,1);

            dataCopies=cell(maxPlot,1);

            for i=1:maxPlot
                dataCopies{i,1}=currData;
            end


            %% normalization
            if isNormalization


                [dataCopies,legendText]= UTIL_EXTRA.applyNormalization(app,normalizationList,dataCopies,legendText);

            end

            if isFineGrid

                [dataCopies,legendText]=UTIL_EXTRA.applyFineGrane(app,interpolationList,nFineGrid,dataCopies,legendText);
            end

            if isCourseGrain
                [dataCopies,legendText]=UTIL_EXTRA.applyCourseGrain(app,scaleList,slideBy,functionID,dataCopies,legendText);
            end


            %% plot data..


            for i=1:maxPlot
                if isAddNoise
                    [dataCopies{i,1},~]=UTIL_PROCESS_DATA.addNoise(app,dataCopies{i,1});
                end
                plot(ax2,dataCopies{i,1},'color',distColorList(i,:));
                hold(ax2,'on')

            end
            try
                legend(ax2,legendText);
            catch

            end

            if isBinarization
                functionID = find(strcmp(app.pMenuBinarisation.Items,app.pMenuBinarisation.Value));
                if functionID ==1
                    msgbox('Please choose one the the binarisation method')
                    return;
                end

                if functionID ==2
                    chkValue = mean(currData);
                    binaryData = currData >= chkValue;
                    hold(ax2,'on')
                    yline(ax2,chkValue)
                elseif functionID == 3
                    chkValue1 = mean(currData)-std(currData);
                    chkValue2 = mean(currData)+std(currData);
                    binaryData = (currData >= chkValue1) & (currData <= chkValue2);
                    hold(ax2,'on')
                    yline(ax2,chkValue1,'color','green')
                    yline(ax2,chkValue2,'color','green')
                elseif functionID == 4

                    binaryData = currData(2:end,1)>=currData(1:end-1,1);


                end

                bar(ax1,binaryData);


            end


            if iscutFile

                try


                    [startLine,endLine,nDiv]=UTIL_EXTRA.getCutFileParameters(app,currData);

                    %plot(app.UIAxesCG_Original,currData,'color','black');
                    %plot(ax2,currData,'color','black');

                    % hold(app.UIAxesCG_Original,'on')
                    hold(ax1,'on')


                    height = (max(currData)-min(currData));
                    rectangle(ax1,'Position',[1,median(currData)-height/4,startLine(1)-1,height/2],'Curvature', 0.2,'FaceColor', [1, 0,0, 0.7],'EdgeColor', [0.2, 0.1, 0, 0.6]);
                    rectangle(ax1,'Position',[endLine(end),median(currData)-height/4,size(currData,1)-endLine(end),height/2],'Curvature', 0.2,'FaceColor', [1, 0,0, 0.7],'EdgeColor', [0.2, 0.1, 0, 0.6]);


                    gray = [0.7 0.7 0.7];

                    for v =1:nDiv

                        rectangle(ax1,'Position',[startLine(v),median(currData)-height/4,endLine(v)-startLine(v),height/2],'Curvature', 0.2,'FaceColor', [0, 1,0, 0.2],'EdgeColor', [0.2, 0.1, 0, 0.6]);

                        line(ax1,[startLine(v),endLine(v)],[median(currData)+height/4,median(currData)-height/4],'color',gray,'LineStyle','--')
                    end
                    legend(ax1,'off');
                catch
                    msgbox({'Problem in selection of paremeters...','Please try different combinations'});
                end

            end


        end

        % Size changed function: TestParameter
        function TestParameterSizeChanged(app, event)


        end

        % Value changed function: pMenuRunMode
        function pMenuRunModeValueChanged(app, event)


            if find(strcmp(app.pMenuRunMode.Items,app.pMenuRunMode.Value))==2

                app.chkAllMeasures.Enable = 'off';

                %setParamTestCase(app);
                UTIL_GUI.setMSelectionOptions(app,true);
                UTIL_GUI.setVisibleTestBox(app,1);

                app.TestParametersPanel.Visible='on';
                app.pbRunExperiment.Visible='off';
            else

                app.chkAllMeasures.Enable = 'on';
                UTIL_GUI.setVisibleTestBox(app,'off');
                UTIL_GUI.setMSelectionOptions(app,false);
                app.TestParametersPanel.Visible='off';
                app.pbRunExperiment.Visible='on';
            end

        end

        % Selection change function: TabGroupSelection
        function TabGroupSelectionSelectionChanged(app, event)

            if strcmp(app.TabGroupSelection.SelectedTab.Title,'M Verification')
                app.TabGroupButtom.SelectedTab= app.TabGroupButtom.Children(1);
            end

        end

        % Callback function
        function ViewCG_Effecs_2ButtonPushed(app, event)
            global PreprocessedDataSet

            fileList = UTIL_GUI.getFileIDList(app);
            columnList = UTIL_GUI.getDimensionList(app);

            if size(fileList,2)>1 || size(columnList,2)>1
                msgbox('Please select one of file and column...');
                return;
            end
            currData =PreprocessedDataSet{fileList};
            plot(app.UIAxesCG_Original,currData,'color','black');

            legend(app.UIAxesCG_Original,'Original Data');
        end

        % Button pushed function: ViewNormalization
        function ViewNormalizationButtonPushed(app, event)


            global PreprocessedDataSet

            fileList = UTIL_GUI.getFileIDList(app);
            columnList = UTIL_GUI.getDimensionList(app);


            normalizationList = UTIL_GUI.getNormazationList(app);


            % cla(app.UIAxesCoarseGrain)
            % cla(app.UIAxesCG_Original)


            if size(fileList,2)>1 || size(columnList,2)>1
                msgbox('Please select one of file and column...');
                return;
            end


            currData =PreprocessedDataSet{fileList};


            if size(normalizationList,2)<1
                return;
            end


            app.PanelChk.AutoResizeChildren='off';

            for i=1:size(app.PanelChk.Children,1)
                cla(app.PanelChk.Children(i))
            end

            ax1= subplot(2,1,1, 'Parent',app.PanelChk)
            plot(ax1,currData)
            legend(ax1,'original Data')


            cd('../com/ExternalPackages/maxdistcolor');
            maxPlot=size(normalizationList,2);
            fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
            distColorList = maxdistcolor(maxPlot,fun);
            cd('../../../scripts');

            dataCopies={};
            for i=1:maxPlot
                dataCopies{i,1}=currData;
            end

            %plot(app.UIAxesCG_Original,currData,'color',distColorList(1,:));

            %legend(app.UIAxesCoarseGrain,'Original Data');
            legendText={};


            %app.UIAxesCoarseGrain


            %% normilization


            %% loop for normalization
            ax2= subplot(2,1,2, 'Parent',app.PanelChk)

            [dataCopies,legendText] = UTIL_EXTRA.applyNormalization(app,normalizationList,dataCopies,legendText)
            %             for nIndex = normalizationList
            %
            %                 legendText{i,1}=app.pMenuNormalization.Items{nIndex};
            %
            %
            %
            %
            %                 X =tmpData;
            %                 % for nIndex=normalizationList
            %
            %                 %% X is used as data inside formula....
            %                 tmpData=eval(app.pMenuNormalization.Items{nIndex});
            %                 %plot(app.UIAxesCoarseGrain,tmpData,'color',distColorList(i+1,:));
            %                 plot(ax2,tmpData,'color',distColorList(i,:))
            %                 hold(ax2,'on');
            %                 i=i+1;
            %                 %end
            %
            %
            %             end


            for i=1:size(dataCopies,1)
                plot(ax2,dataCopies{i,1},'color',distColorList(i,:))
                hold(ax2,'on');
            end


            legend(ax2,legendText);

            title(ax1, 'Data Normalization');


        end

        % Callback function
        function SliderBlendingCoffValueChanged(app, event)
            value = app.SliderBlendingCoff.Value;
            app.editNoiseBlendingCoeff.Value=num2str(value);
        end

        % Value changed function: editNoiseBlendingCoeff
        function editNoiseBlendingCoeffValueChanged(app, event)

        end

        % Callback function
        function ViewSegmentationButtonPushed(app, event)

        end

        % Button pushed function: ViewInterpolation
        function ViewInterpolationButtonPushed(app, event)
            global PreprocessedDataSet

            nFineGrid = str2num(app.pMenuFineGrid.Value);

            fileList = UTIL_GUI.getFileIDList(app);

            columnList = UTIL_GUI.getDimensionList(app);


            interpolationList = UTIL_GUI.getInterpolationList(app);


            if size(fileList,2)>1 || size(columnList,2)>1
                msgbox('Please select one of file and column...');
                return;
            end


            cd('../com/ExternalPackages/maxdistcolor');
            maxPlot=size(interpolationList,2);
            fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
            distColorList = maxdistcolor(maxPlot,fun);
            cd('../../../scripts');


            app.PanelChk.AutoResizeChildren='off';
            for i=1:size(app.PanelChk.Children,1)
                cla(app.PanelChk.Children(i))
            end

            ax1= subplot(2,1,1, 'Parent',app.PanelChk)

            dataCopies={};

            for i=1:maxPlot
                dataCopies{i,1}=PreprocessedDataSet{fileList};
            end

            plot(ax1,dataCopies{i,1},'color','black');

            legend(app.UIAxesCG_Original,'Original Data');
            legendText={};

            %app.pMenuIP_Method.Value
            ax2= subplot(2,1,2, 'Parent',app.PanelChk)
            [dataCopies,legendText]=UTIL_EXTRA.applyFineGrane(app,interpolationList,nFineGrid,dataCopies,legendText)
            %
            %             for i=1:size(interpolationList,2)
            %
            %                 legendText{i,1}=app.pMenuIP_Method.Items{interpolationList(1,i)};
            %
            %                 %% apply interplation
            %                 tmpData = interpn(currData,nFineGrid,app.pMenuIP_Method.Items{interpolationList(1,i)})';
            %
            %                 %plot(app.UIAxesCoarseGrain,tmpData,'color',distColorList(i+1,:));
            %                 plot(ax2,tmpData,'color',distColorList(i+1,:))
            %                 hold(ax2,'on')
            %             end

            for i=1:maxPlot
                plot(ax2,dataCopies{i,1},'color',distColorList(i,:))
                hold(ax2,'on')
            end
            legend(ax2,legendText);
            title(ax1,'Increase File Size (Interpolation)')

        end

        % Value changed function: pMenuIP_Method
        function pMenuIP_MethodValueChanged(app, event)
            value = app.pMenuIP_Method.Value;

        end

        % Button pushed function: ViewReduce
        function ViewReduceButtonPushed(app, event)


            global PreprocessedDataSet


            fileList = UTIL_GUI.getFileIDList(app);

            columnList = UTIL_GUI.getDimensionList(app);
            scaleList=UTIL_GUI.getScaleList(app);
            slideBy = UTIL_GUI.getSlideBy(app);


            functionID = find(strcmp(app.pMenuScaleAggregate.Items,app.pMenuScaleAggregate.Value));
            maxPlot=1;
            if functionID == 1
                functionID = [2:size(app.pMenuScaleAggregate.Items,2)];
                maxPlot = size(functionID,2);
            end

            if isempty(scaleList)
                msgbox('Scale is missing...');
                return;
            elseif size(scaleList,2)>1
                maxPlot = size(scaleList,2);
            end

            if isempty(functionID)
                msgbox('Please select Aggregation function')
                return;
            elseif size(functionID,2)>1
                maxPlot = size(functionID,2);
            end

            if isempty(slideBy)
                msgbox('Please select Slide By')
                return;
            elseif size(slideBy,2)>1
                maxPlot = size(slideBy,2);
            end


            countRangeInCG = sum((functionID==1)+(size(scaleList,2)>1)+(slideBy==-1));

            if countRangeInCG > 1
                msgbox('Please select only one range in scale/s or Slide By or Aggregate function');
                return;
            end


            if size(fileList,2)>1 || size(columnList,2)>1
                msgbox('Please select one of file and column...');
                return;
            end


            %%course grain


            %currData = PreprocessedDataSet{fileList,1};

            for i=1:size(app.PanelChk.Children,1)
                cla(app.PanelChk.Children(i))
            end

            app.PanelChk.AutoResizeChildren='off';
            cla(app.PanelChk.Children)

            ax1= subplot(2,1,1, 'Parent',app.PanelChk)


            plot(ax1,PreprocessedDataSet{fileList,1});

            legend(ax1,'Original Data');
            legendText={};


            for i=1:maxPlot
                dataCopies{i,1}=PreprocessedDataSet{fileList,1};
            end

            %plot(app.UIAxesCG_Original,currData,'color','black');

            %%coursegrain by scale
            ax2= subplot(2,1,2, 'Parent',app.PanelChk);

            [dataCopies,legendText]=UTIL_EXTRA.applyCourseGrain(app,scaleList,slideBy,functionID,dataCopies,legendText)

            %             if size(scaleList,2)==1 && functionID > 1 && slideBy~=-1
            %
            %                 currFun = app.pMenuScaleAggregate.Value;
            %
            %                 %  plot(app.UIAxesCG_Original,currData,'color',distColorList(1,:));
            %
            %                 scale=scaleList(1,1);
            %                 slide=slideBy(1,1);
            %                 startIds=1:slide:(size(currData,1)-scale+1);
            %
            %                 tmpData=[];
            %
            %                 for id=startIds
            %                     %% scale is s
            %                     tmpData = [tmpData eval(strcat(currFun,'(currData(id:id+scale-1))'))];
            %                 end
            %
            %
            %                 %plot(app.UIAxesCoarseGrain,tmpData);
            %                 %hold(app.UIAxesCoarseGrain,'on');
            %
            %
            %
            %
            %
            %                 plot(ax2,tmpData)
            %                 hold(ax2,'on')
            %
            %
            %
            %             else
            %
            %
            %                 if size(scaleList,2)>1
            %                     %currData = PreprocessedDataSet{fileList,1};
            %                     currFun = app.pMenuScaleAggregate.Value;
            %
            %
            %
            %                     cd('../com/ExternalPackages/maxdistcolor');
            %                     maxPlot=size(scaleList,2)+1;
            %                     fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
            %                     distColorList = maxdistcolor(maxPlot,fun);
            %                     cd('../../../scripts');
            %
            %
            %
            %
            %
            %
            %                     %%for all scale list..s
            %                     legendText={};
            %                     colorIndex =1;
            %                     for s=scaleList
            %                         startIds=1:slideBy:(size(currData,1)-s+1);
            %                         tmpData =[];
            %                         legendText{1,colorIndex} = strcat('Scale - ',num2str(s));
            %                         for id=startIds
            %                             %% scale is s
            %                             tmpData = [tmpData eval(strcat(currFun,'(currData(id:id+s-1))'))];
            %                         end
            %
            %
            %                         %plot(app.UIAxesCoarseGrain,tmpData,'color',distColorList(colorIndex,:));
            %                         %hold(app.UIAxesCoarseGrain,'on');
            %
            %                         plot(ax2,tmpData,'color',distColorList(i+1,:))
            %                         hold(ax2,'on')
            %
            %                         colorIndex=colorIndex+1;
            %                     end
            %                     legend(ax2,legendText);
            %                     %% trying all slides with fix scalse
            %
            %                     %% coursegrain by slide
            %                 elseif  slideBy==-1
            %                     %currData = PreprocessedDataSet{fileList,1};
            %                     %                      if isempty(scaleList)
            %                     %                         msgbox('Scale is missing...');
            %                     %                         return;
            %                     %                     end
            %
            %                     maxPlot=scaleList+1;
            %                     cd('../com/ExternalPackages/maxdistcolor');
            %                     fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
            %                     distColorList = maxdistcolor(maxPlot,fun);
            %                     cd('../../../scripts');
            %
            %
            %
            %                     currFun = app.pMenuScaleAggregate.Value;
            %                     %% for all slide window from 1 to scaleList
            %                     legendText={};
            %                     for s=1:scaleList
            %                         startIds=1:s:(size(currData,1)-scaleList+1);
            %                         tmpData =[];
            %                         legendText{s,1} = strcat('SlideW - ',num2str(s));
            %                         for id=startIds
            %                             %% scale is scale
            %                             tmpData = [tmpData eval(strcat(currFun,'(currData(id:id+s-1))'))];
            %                         end
            %                         % plot(app.UIAxesCoarseGrain,tmpData,'color',distColorList(s+1,:));
            %                         %hold(app.UIAxesCoarseGrain,'on');
            %
            %                         plot(ax2,tmpData,'color',distColorList(si+1,:))
            %                         hold(ax2,'on')
            %
            %
            %                     end
            %                     %legend(app.UIAxesCoarseGrain,legendText);
            %                     legend(ax2,legendText);
            %
            %                 elseif functionID==1
            %
            %                     cd('../com/ExternalPackages/maxdistcolor');
            %                     maxPlot=size(app.pMenuScaleAggregate.Items,2);
            %                     fun = @(maxPlot)Lab_to_DIN99(srgb_to_Lab(maxPlot));
            %                     distColorList = maxdistcolor(maxPlot,fun);
            %                     cd('../../../scripts');
            %
            %                     %  plot(app.UIAxesCG_Original,currData,'color',distColorList(1,:));
            %
            %                     scale=scaleList(1,1);
            %                     slide=slideBy(1,1);
            %                     startIds=1:slide:(size(currData,1)-scale+1);
            %
            %                     %%for all aggregation function except 'all' (first one)
            %
            %                     legendText={};
            %                     for i=1:size(app.pMenuScaleAggregate.Items,2)-1
            %
            %                         currFun = app.pMenuScaleAggregate.Items{i+1};
            %                         legendText{i,1}=currFun;
            %                         tmpData =[];
            %                         for id=startIds
            %                             tmpData = [tmpData eval(strcat(currFun,'(currData(id:id+scale-1))'))];
            %                         end
            %
            %
            %                         %plot(app.UIAxesCoarseGrain,tmpData,'color',distColorList(i+1,:));
            %                         %hold(app.UIAxesCoarseGrain,'on');
            %                         plot(ax2,tmpData,'color',distColorList(i+1,:))
            %                         hold(ax2,'on')
            %
            %                     end
            %                     %legend(app.UIAxesCoarseGrain,legendText);
            %                     legend(ax2,legendText);
            %
            %                 end
            %             end

            for i=1:maxPlot
                plot(ax2,dataCopies{i,1});
                hold(ax2,'on');
            end
            legend(ax2,legendText);

            title(ax1,'Reduce File Size (Multi-Scalling)')

        end

        % Button down function: UIAxesCG_Original
        function UIAxesCG_OriginalButtonDown(app, event)

        end

        % Callback function
        function ClearViewEffectButtonPushed(app, event)
            cla(app.UIAxesCoarseGrain)

        end

        % Button down function: NormalisationTab
        function NormalisationTabButtonDown(app, event)

        end

        % Callback function
        function SaveCGImagesButtonPushed(app, event)
            fileName = app.editCG_ImageName.Value;
            exportgraphics(app.PanelChk,strcat(fileName,'.pdf'))

        end

        % Value changed function: chkIncrease
        function chkIncreaseValueChanged(app, event)
            value = app.chkIncrease.Value;
            if value ==1
                app.chkReduce.Value =0;
                app.chkCutFile.Value=0;

                app.tabCG_ParameterSelection.SelectedTab= app.tabCG_ParameterSelection.Children(2);


            end
        end

        % Value changed function: chkReduce
        function chkReduceValueChanged(app, event)
            value = app.chkReduce.Value;
            if value ==1
                app.chkIncrease.Value = 0;
                app.chkCutFile.Value=0;
                app.tabCG_ParameterSelection.SelectedTab= app.tabCG_ParameterSelection.Children(3);
            end
        end

        % Callback function
        function SaveCGImagesButtonPushed2(app, event)

        end

        % Button pushed function: SaveCGImages
        function SaveCGImagesButtonPushed3(app, event)

            fileName = app.editCG_ImageName.Value;
            if isempty(fileName)
                msgbox('Please provide filename.');
                return;
            end
            exportgraphics(app.PanelChk,strcat('../outputs/Images/',fileName,'.pdf'))
            msgbox('Image Save in ../outputs/Images folder.')

        end

        % Callback function
        function SaveCG_ImagesButtonPushed(app, event)
            msgbox('hi')
            fileName = app.editCG_ImageName.Value;
            exportgraphics(app.PanelChk,strcat('../outputs/Images/',fileName,'.pdf'))
            msgbox('Image Save in ../outputs/Images folder.')
        end

        % Button pushed function: ViewSegmentationButton
        function ViewSegmentationButtonPushed2(app, event)

            global PreprocessedDataSet
            for i=1:size(app.PanelChk.Children,1)
                cla(app.PanelChk.Children(i))
            end


            try
                %cla(app.UIAxesCG_Original)

                app.PanelChk.AutoResizeChildren='off';
                ax1= subplot(2,1,1, 'Parent',app.PanelChk)


                columnList = UTIL_GUI.getDimensionList(app);
                fileList= UTIL_GUI.getFileIDList(app);

                if size(fileList,2) <=0 || size(columnList ,2) <=0 || size(fileList,2) > 1

                    msgbox('Please select one of file and column...');

                    return;
                end

                ax1= subplot(2,1,1, 'Parent',app.PanelChk)


                currData = PreprocessedDataSet{fileList(1)};
                plot(ax1,currData)

                ax2= subplot(2,1,1, 'Parent',app.PanelChk)

                [startLine,endLine,nDiv]=UTIL_EXTRA.getCutFileParameters(app,currData);

                %plot(app.UIAxesCG_Original,currData,'color','black');
                plot(ax2,currData,'color','black');

                % hold(app.UIAxesCG_Original,'on')
                hold(ax2,'on')

                height = (max(currData)-min(currData));

                %rectangle(app.UIAxesCG_Original,'Position',[1,median(currData)-height/4,startLine(1)-1,height/2],'Curvature', 0.2,'FaceColor', [1, 0,0, 0.7],'EdgeColor', [0.2, 0.1, 0, 0.6]);
                %rectangle(app.UIAxesCG_Original,'Position',[endLine(end),median(currData)-height/4,size(currData,1)-endLine(end),height/2],'Curvature', 0.2,'FaceColor', [1, 0,0, 0.7],'EdgeColor', [0.2, 0.1, 0, 0.6]);

                rectangle(ax2,'Position',[1,median(currData)-height/4,startLine(1)-1,height/2],'Curvature', 0.2,'FaceColor', [1, 0,0, 0.7],'EdgeColor', [0.2, 0.1, 0, 0.6]);
                rectangle(ax2,'Position',[endLine(end),median(currData)-height/4,size(currData,1)-endLine(end),height/2],'Curvature', 0.2,'FaceColor', [1, 0,0, 0.7],'EdgeColor', [0.2, 0.1, 0, 0.6]);


                gray = [0.7 0.7 0.7];
                %rectangle(app.UIAxesCG_Original,'Position',[leftLocUp, lowerLimit,rightLocUp-leftLocUp,upperLimit-lowerLimit],'Curvature', 0.2,'FaceColor', [1, 0, 0, 0.7],'EdgeColor', [1, 0, 0, 0.7]);
                for v =1:nDiv
                    %rectangle(app.UIAxesCG_Original,'Position',[startLine(v),median(currData)-height/4,endLine(v)-startLine(v),height/2],'Curvature', 0.2,'FaceColor', [0, 1,0, 0.2],'EdgeColor', [0.2, 0.1, 0, 0.6]);
                    rectangle(ax2,'Position',[startLine(v),median(currData)-height/4,endLine(v)-startLine(v),height/2],'Curvature', 0.2,'FaceColor', [0, 1,0, 0.2],'EdgeColor', [0.2, 0.1, 0, 0.6]);
                    %line(app.UIAxesCG_Original,[startLine(v),endLine(v)],[median(currData)+height/4,median(currData)-height/4],'color',gray,'LineStyle','--')
                    line(ax2,[startLine(v),endLine(v)],[median(currData)+height/4,median(currData)-height/4],'color',gray,'LineStyle','--')
                end

            catch
                msgbox({'Problem in selection of paremeters...','Please try different combinations'})
            end

            title(ax1,'Cut File (Data Segmentation)')

        end

        % Value changed function: chkNormalization
        function chkNormalizationValueChanged(app, event)
            value = app.chkNormalization.Value;
            if value==1
                app.tabCG_ParameterSelection.SelectedTab= app.tabCG_ParameterSelection.Children(1);
            end
        end

        % Value changed function: chkAddNoise
        function chkAddNoiseValueChanged(app, event)
            value = app.chkAddNoise.Value;
            app.tabCG_ParameterSelection.SelectedTab= app.tabCG_ParameterSelection.Children(4);
        end

        % Value changed function: chkCutFile
        function chkCutFileValueChanged(app, event)
            value = app.chkCutFile.Value;
            if value==1
                app.chkIncrease.Value = 0;
                app.chkReduce.Value=0;
                app.tabCG_ParameterSelection.SelectedTab= app.tabCG_ParameterSelection.Children(5);
            end
        end

        % Callback function
        function btmDA_FDViewButtonPushed(app, event)
            cd('../com/ExternalPackages/Fractal_dimension_measures')
            try
                data = load('ECG_BC_c_25_8_hrv.txt');
                wlen=app.editDP_FD_WLength.Value;
                wstep = app.editDP_FD_WSteps.Value;
                [fdiml,fdimp,hd] = getNldian(data,'ECG_BC_c_25_8_hrv.txt',wlen,wstep,app.UIAxesDA_Results);
                cd('../../../scripts')
            catch ME
                disp(ME)
                cd('../../../scripts')
            end

        end

        % Button pushed function: pbSaveNoiseFile
        function pbSaveNoiseFileButtonPushed(app, event)


            selNoiseTypeNo = find(strcmp(app.pMenuNoiseType.Items,app.pMenuNoiseType.Value)) ;


            if selNoiseTypeNo==1
                msgbox("Please choose a NoiseType");
                return;
            end

            noiseData=UTIL_PROCESS_DATA.getOnlyNoise(app);
            fileName = app.editNoiseFileName.Value;
            writematrix(noiseData,strcat('../outputs/NoisyData/',fileName,'.txt'),'Delimiter','tab')

        end

        % Callback function
        function pbPlotTest_3ButtonPushed(app, event)
            global PreprocessedDataSet


            columnList = UTIL_GUI.getDimensionList(app);
            fileList= UTIL_GUI.getFileIDList(app);

            if size(fileList,2) <=0 || size(columnList ,2) <=0 || size(fileList,2) > 1

                msgbox('Please select one of file and column...');

                return;
            end


            currData = PreprocessedDataSet{fileList(1)};


            cd('../com/ExternalPackages/Fractal_dimension_measures')
            m   =str2double(app.editFDnldianWL.Value);
            tau   =str2double(app.editFDnldianWS.Value);


            fig1 = figure()

            ax1=subplot(221,'Parent',fig1)
            ax2=subplot(222,'Parent',fig1)

            ax3=subplot(223,'Parent',fig1)
            ax4=subplot(224,'Parent',fig1)

            title('Nldian')


            getNldian(currData(isnan(currData(:,1))==0,1),m,tau,{ax1,ax2,ax3,ax4});
            cd('../../../scripts')

        end

        % Button pushed function: pbPlotTest_6
        function pbPlotTest_6ButtonPushed(app, event)

            global PreprocessedDataSet


            UTIL_EXTRA.clearDataAnalysisDisplay(app);

            columnList = UTIL_GUI.getDimensionList(app);
            fileList= UTIL_GUI.getFileIDList(app);

            if size(fileList,2) <=0 || size(columnList ,2) <=0 || size(fileList,2) > 1

                msgbox('Please select one of file and column...');

                return;
            end


            currData = PreprocessedDataSet{fileList(1)};


            m   =str2double(app.editDataA_FDnldianWL.Value);
            tau   =str2double(app.editDataA_FDnldianWS.Value);

            t=tiledlayout(app.panelDA_fig1,2,2);
            ax1=nexttile(t);
            ax2=nexttile(t);
            ax3=nexttile(t);
            ax4=nexttile(t);


            if app.rdFDTypeNldwan.Value==1

                app.tabDA_Fig1.Title = "Nldwan Plots";
                cd('../com/ExternalPackages/Fractal_dimension_measures')
                getNldwan(currData(isnan(currData(:,1))==0,1),m,tau,{ax1,ax2,ax3,ax4});
                cd('../../../scripts')
            else
                app.tabDA_Fig1.Title = "Nldian Plots";
                cd('../com/ExternalPackages/Fractal_dimension_measures')
                getNldian(currData(isnan(currData(:,1))==0,1),m,tau,{ax1,ax2,ax3,ax4});
                cd('../../../scripts')
            end

        end

        % Selection changed function: GPPTypeButtonGroup
        function GPPTypeButtonGroupSelectionChanged(app, event)


            selectedButton = app.GPPTypeButtonGroup.SelectedObject;
            if selectedButton==app.rbDA_GPP_Sym
                app.editDataA_GPP_minOrder.Visible='off';
                app.editDataA_GPP_step.Visible='off'
            else
                app.editDataA_GPP_minOrder.Visible='on';
                app.editDataA_GPP_step.Visible='on'
            end
        end

        % Button pushed function: pbViewPlot_GPP
        function pbViewPlot_GPPButtonPushed(app, event)
            fnam='dataFile';

            global PreprocessedDataSet
            UTIL_EXTRA.clearDataAnalysisDisplay(app);


            columnList = UTIL_GUI.getDimensionList(app);
            fileList= UTIL_GUI.getFileIDList(app);

            if size(fileList,2) <=0 || size(columnList ,2) <=0 || size(fileList,2) > 1

                msgbox('Please select one of file and column...');

                return;
            end


            t=tiledlayout(app.panelDA_fig1,2,2);
            ax1=nexttile(t);
            ax2=nexttile(t);
            ax3=nexttile(t);
            ax4=nexttile(t);


            currData = PreprocessedDataSet{fileList(1)};


            if app.rbDA_GPP_Sym.Value==1
                maxOrder = str2num(app.editDataA_GPP_maxOrder.Value);
                app.tabDA_Fig1.Title='Kalauzi GPP Sym';
                cd('../com/ExternalPackages/Kalauzi_GPP');
                [sd1,sd2,c1,c2,pCol,rCol]=getGPPsym(fnam,currData,maxOrder,{ax1,ax2,ax3,ax4});
                cd('../../../scripts');
                t=[sd1',sd2',c1',c2',pCol,rCol];
                %t=mat2cell(t,size(t,1),size(t,2));
                var = {'sd1','sd2','c1','c2','pCol','rCol'};
                %fig = uifigure;
                %uit = uitable(fig,'data',t);
                app.tabDA_Data1.Title = "GPP Sym Data";
                set(app.uiTable_DA1,'data',t);
                set(app.uiTable_DA1,'ColumnName',var);
                %set(uit,'Parent',app.tabDA_Data1);


            else
                app.editDataA_GPP_minOrder.Visible='on';
                app.editDataA_GPP_step.Visible='on';
                maxOrder = str2num(app.editDataA_GPP_maxOrder.Value);
                minOrder = str2num(app.editDataA_GPP_minOrder.Value);
                step= str2num(app.editDataA_GPP_step.Value);
                app.tabDA_Fig1.Title='Kalauzi GPP Asym';
                cd('../com/ExternalPackages/Kalauzi_GPP');

                [rcoef,maxmatss,pCol,rCol,NAI]=getGppAsym(fnam,currData,minOrder, step, maxOrder,{ax1,ax2,ax3,ax4});
                cd('../../../scripts');


                app.tabDA_Data1.Title='GPP rcoef';

                app.tabDA_Data2.Title='GPP maxmatss';


                app.uiTable_DA1.Data = rcoef;
                app.uiTable_DA2.Data = maxmatss;
                
                app.txtNAI.Text = num2str(NAI);


            end

        end

        % Callback function
        function pbSaveDA_Plot1ButtonPushed(app, event)
            x=1
        end

        % Button pushed function: pbSaveDA_Data1
        function pbSaveDA_Data1ButtonPushed(app, event)

            fileName = app.editDA_File2.Value;

            dataToWrite = app.uiTable_DA1.Data;


            try
                xlswrite(strcat('../outputs/DataAnalysis/dataTable/',fileName,'.xlsx'),dataToWrite);
            catch
                cd('../com/ExternalPackages/xlwrite')
                javaaddpath('poi_library/xmlbeans-2.3.0.jar');
                javaaddpath('poi_library/stax-api-1.0.1.jar');
                javaaddpath('poi_library/poi-ooxml-schemas-3.8-20120326.jar');
                javaaddpath('poi_library/poi-ooxml-3.8-20120326.jar');
                javaaddpath('poi_library/poi-3.8-20120326.jar');
                javaaddpath('poi_library/dom4j-1.6.1.jar');
                xlswrite(strcat('../outputs/DataAnalysis/dataTable/',fileName,'.xlsx'),dataToWrite);
                cd('../../../scripts');
            end
            msgbox('Data S')
        end

        % Button pushed function: pbSaveDA_Data2
        function pbSaveDA_Data2ButtonPushed(app, event)

            fileName = app.editDA_File3.Value;

            dataToWrite = app.uiTable_DA2.Data;


            try
                xlswrite(strcat('../outputs/DataAnalysis/dataTable/',fileName,'.xlsx'),dataToWrite);
            catch
                cd('../com/ExternalPackages/xlwrite')
                javaaddpath('poi_library/xmlbeans-2.3.0.jar');
                javaaddpath('poi_library/stax-api-1.0.1.jar');
                javaaddpath('poi_library/poi-ooxml-schemas-3.8-20120326.jar');
                javaaddpath('poi_library/poi-ooxml-3.8-20120326.jar');
                javaaddpath('poi_library/poi-3.8-20120326.jar');
                javaaddpath('poi_library/dom4j-1.6.1.jar');
                xlswrite(strcat('../outputs/DataAnalysis/dataTable/',fileName,'.xlsx'),dataToWrite);
                cd('../../../scripts');
            end
            msgbox('Data S')
        end

        % Button pushed function: pbSaveDA_Data3
        function pbSaveDA_Data3ButtonPushed(app, event)

            fileName = app.editDA_File5.Value;

            dataToWrite = app.uiTable_DA3.Data;


            try
                xlswrite(strcat('../outputs/DataAnalysis/dataTable/',fileName,'.xlsx'),dataToWrite);
            catch
                cd('../com/ExternalPackages/xlwrite')
                javaaddpath('poi_library/xmlbeans-2.3.0.jar');
                javaaddpath('poi_library/stax-api-1.0.1.jar');
                javaaddpath('poi_library/poi-ooxml-schemas-3.8-20120326.jar');
                javaaddpath('poi_library/poi-ooxml-3.8-20120326.jar');
                javaaddpath('poi_library/poi-3.8-20120326.jar');
                javaaddpath('poi_library/dom4j-1.6.1.jar');
                xlswrite(strcat('../outputs/DataAnalysis/dataTable/',fileName,'.xlsx'),dataToWrite);
                cd('../../../scripts');
            end
            msgbox('Data S')
        end

        % Button pushed function: pbPlotTest_5
        function pbPlotTest_5ButtonPushed(app, event)

        end

        % Button pushed function: pbPlotTest_7
        function pbPlotTest_7ButtonPushed(app, event)
            global PreprocessedDataSet


            UTIL_EXTRA.clearDataAnalysisDisplay(app);

            columnList = UTIL_GUI.getDimensionList(app);
            fileList= UTIL_GUI.getFileIDList(app);

            if size(fileList,2) <=0 || size(columnList ,2) <=0 || size(fileList,2) > 1

                msgbox('Please select one of file and column...');

                return;
            end


            currData = PreprocessedDataSet{fileList(1)};


            nStrips   =str2double(app.editDsNumberE.Value);


            t=tiledlayout(app.panelDA_fig1,1,2);
            ax1=nexttile(t);
            ax2=nexttile(t);
            %                 ax3=nexttile(t);
            %                 ax4=nexttile(t);

            t=tiledlayout(app.panelDA_fig2,2,2);
            ax11=nexttile(t);
            ax12=nexttile(t);
            ax13=nexttile(t);
            ax14=nexttile(t);

            t=tiledlayout(app.panelDA_fig3,1,2);
            ax21=nexttile(t);
            ax22=nexttile(t);


            app.tabDA_Fig1.Title = "Rounded Data ";
            app.tabDA_Fig2.Title = "Event/Trajectory/Displacement";
            app.tabDA_Fig3.Title = "Event/Trajectory/Displacement";

            cd('../com/UnderDevelopment/DiffusionEntropyAnalysis')
            displayDiffusionEPlots(currData(isnan(currData(:,1))==0,1),nStrips,{ax1,ax2},{ax11,ax12,ax13,ax14},{ax21,ax22});
            cd('../../../scripts')

        end

        % Button pushed function: pbLoadData_RS
        function pbLoadData_RSButtonPushed(app, event)
            dbDir = uigetdir();
            files=dir(strcat(dbDir,'\*.',app.pMenuFileExtention_RS.Value))
            app.editFileLocation_RS.Value = dbDir;
            fileInfo{1} = '--- All Files----';
            for i=1:size(files,1)
                fileInfo{i+1}=files(i).name
            end
            app.pMenuFileNames_RS.Items=fileInfo;


            %% Done David

            %dbDir='../Data/SampleData/RR_Data';


            % figure(app.UIFigure); % magic command :)
            %%if Data directory not found open in current project/script


        end

        % Button pushed function: pbApplySave_RS
        function pbApplySave_RSButtonPushed(app, event)
            selFileIndex = find(strcmp(app.pMenuFileNames_RS.Items,app.pMenuFileNames_RS.Value));

            fileLists= {};
            if selFileIndex == 1
                for i = 2:size(app.pMenuFileNames_RS.Items,2)
                    fileLists{i-1}= app.pMenuFileNames_RS.Items{i};
                end
            else
                fileLists{1}= app.pMenuFileNames_RS.Value;
            end

            selExt = app.pMenuFileExtention_RS.Value;
            dbDirectoryPath = app.editFileLocation_RS.Value;


            f= waitbar(0,'0','Name','Processing statistic calculation .....');

            fCount = 1;
            for fileName = fileLists

                progressTxt = strcat(num2str(fCount),'/',fileName{1});
                try
                    f = waitbar(fCount/size(fileLists,2),f,progressTxt,'Name','Saving files .....');
                catch
                    f = waitbar(0.1,progressTxt);
                end

                data = UTIL_LOAD_DATA.loadDataFiles(app,selExt,dbDirectoryPath,fileName{1});
                if size(data,2) <2
                    msgbox(strcat(fileName,' has only one column, required at least two columns'));
                    continue;
                else
                    data = data(:,1:2);
                end

                if find(strcmp(app.pMenuTimeType.Items,app.pMenuTimeType.Value))==2
                    currSum=data(1,1);
                    for i=2:size(data,1)
                        currSum = currSum+data(i,1);
                        data(i,1)=currSum;
                    end
                else
                    if sum(data(2:end,1)<data(1:end-1,1)) > 1
                        msgbox('Time interval is not cummulative, so please choose interval')
                        close(f);
                        return
                    end
                end


                if app.chkResamplingFreq.Value ==1
                    desiredFs = str2num(app.editResamplingFreq.Value);
                else
                    desiredFs =  size(data,1)/data(end,1);
                end

                sampleFs=str2num(app.editSampleFS_DM.Value);

                if app.chkResamplingFreq.Value ==1
                    desiredFs = str2num(app.editResamplingFreq.Value);
                else
                    desiredFs =  size(data,1)/data(end,1);
                end

                methodID = find(strcmp(app.pMenuInterpolation_DM.Items,app.pMenuInterpolation_DM.Value));
                allMethodList = {'linear', 'pchip', 'spline'};


                if app.chkEndpointEffect.Value ==1
                    [p,q] = rat(desiredFs/sampleFs);
                    % ensure an odd length filter
                    n = 10*q+1;
                    % use .25 of Nyquist range of desired sample rate
                    cutoffRatio = str2double(app.editCutOffRatio.Value);
                    % construct lowpass filter
                    lpFilt = p * fir1(n, cutoffRatio * 1/q);
                    [y, Ty] = resample(data(:,2),data(:,1),desiredFs,allMethodList{methodID},p,q,lpFilt);
                else
                    [y, Ty] = resample(data(:,2),data(:,1),desiredFs,allMethodList{methodID});
                end

                fileName=strsplit(fileName{1},'.')
                save(strcat('../outputs/Resampling/',app.editResample_prefix.Value,'_',fileName{1},'_',app.editResample_sufix.Value,'.txt'),'y','-ascii')


                fCount = fCount +1;
            end
            close(f);


        end

        % Button pushed function: pbViewRS
        function pbViewRSButtonPushed(app, event)
            %   try


            selFileIndex = find(strcmp(app.pMenuFileNames_RS.Items,app.pMenuFileNames_RS.Value));
            if selFileIndex == 1
                msgbox('Please select one of the file');
                return;
            end

            selExt = app.pMenuFileExtention_RS.Value;
            dbDirectoryPath = app.editFileLocation_RS.Value;
            fileName=app.pMenuFileNames_RS.Value;
            data = UTIL_LOAD_DATA.loadDataFiles(app,selExt,dbDirectoryPath,fileName);
            if size(data,2) <2
                msgbox('Data file have only one column, required at least two columns');
                return
            else
                data = data(:,1:2);
            end

            if find(strcmp(app.pMenuTimeType.Items,app.pMenuTimeType.Value))==2
                currSum=data(1,1);
                for i=2:size(data,1)
                    currSum = currSum+data(i,1);
                    data(i,1)=currSum;
                end
            else
                if sum(data(2:end,1)<data(1:end-1,1)) > 1
                    msgbox('Time interval is not cummulative, so please choose interval')
                    return
                end
            end

            sampleFs=str2num(app.editSampleFS_DM.Value);

            if app.chkResamplingFreq.Value ==1
                desiredFs = str2num(app.editResamplingFreq.Value);
            else
                desiredFs =  size(data,1)/data(end,1);
                app.editSampleFS_DM.Value = num2str(desiredFs);
                app.editResamplingFreq.Value = num2str(desiredFs);
            end

            methodID = find(strcmp(app.pMenuInterpolation_DM.Items,app.pMenuInterpolation_DM.Value));
            allMethodList = {'linear', 'pchip', 'spline'};


            if app.chkEndpointEffect.Value ==1
                [p,q] = rat(desiredFs/sampleFs);
                % ensure an odd length filter
                n = 10*q+1;
                % use .25 of Nyquist range of desired sample rate
                cutoffRatio = str2double(app.editCutOffRatio.Value);
                % construct lowpass filter
                lpFilt = p * fir1(n, cutoffRatio * 1/q);
                [y, Ty] = resample(data(:,2),data(:,1),desiredFs,allMethodList{methodID},p,q,lpFilt);
            else
                [y, Ty] = resample(data(:,2),data(:,1),desiredFs,allMethodList{methodID});
            end


            app.PanelChk.AutoResizeChildren='off';

            t=tiledlayout(app.PanelChk,1,1);
            ax1=nexttile(t);


            %  ax1= plot('Parent',app.PanelChk);
            % plot(ax1,data)


            plot(ax1,data(:,1),data(:,2),'.-',Ty,y,'o-');


            legend(ax1,{'Original','Resampled'});


            % catch e

            % msgbox('Something wrong.....')
            %   end
        end

        % Value changed function: chkLoadData_Uneven
        function chkLoadData_UnevenValueChanged(app, event)
            value = app.chkLoadData_Uneven.Value;

            app.unevenDataPanel.Visible=value;

        end

        % Button pushed function: pbViewDetrend
        function pbViewDetrendButtonPushed(app, event)


            global PreprocessedDataSet
            UTIL_EXTRA.clearDataAnalysisDisplay(app);


            columnList = UTIL_GUI.getDimensionList(app);
            fileList= UTIL_GUI.getFileIDList(app);

            if size(fileList,2) <=0 || size(columnList ,2) <=0 || size(fileList,2) > 1

                msgbox('Please select one of file and column...');

                return;
            end


            t=tiledlayout(app.panelDA_fig1,1,1);
            ax1=nexttile(t);


            currData = PreprocessedDataSet{fileList(1)};

            n=str2num(app.editDegreePolynomial.Value);

            app.tabDA_Fig1.Title = "Detrend Plot";

            y = detrend(currData,n);
            t=1:size(currData,1);
            plot(ax1,t,currData,t,y,t,currData-y,':k');
            legend(ax1,'Input Data','Detrended Data','Trend','Location','northwest')

            t=[currData, y, currData-y];

            app.tabDA_Data1.Title = "Detrend Data";

            set(app.uiTable_DA1,'data',t);
            set(app.uiTable_DA1,'ColumnName',{'Original', 'Detrended','Trend'});


        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            id = find(strcmp(app.ListBox.Items,app.ListBox.Value));
            app.TG_AnalysisDown.SelectedTab= app.TG_AnalysisDown.Children(id);
        end

        % Value changed function: chkEndpointEffect
        function chkEndpointEffectValueChanged(app, event)
            value = app.chkEndpointEffect.Value;

            app.txtCutOffRatio.Visible= (value ==1);
            app.editCutOffRatio.Visible = (value ==1);

        end

        % Value changed function: chkEndpointEffect_LD
        function chkEndpointEffect_LDValueChanged(app, event)
            value = app.chkEndpointEffect_LD.Value;
            app.editCutOffRatio_LD.Visible= (value==1);
            app.txtCutOffRatio_LD.Visible= (value==1);
        end

        % Button pushed function: pbApplySave_Detrend
        function pbApplySave_DetrendButtonPushed(app, event)
            global PreprocessedDataSet


            fileList= UTIL_GUI.getFileIDList(app);


            n=str2num(app.editDegreePolynomial.Value);


            for fIndex = 1 :size(fileList,2)

                currData = PreprocessedDataSet{fileList(fIndex)};
                y = detrend(currData,n);
                fileName=strsplit(fileName{1},'.')
                save(strcat('../outputs/Detrending/',app.editPrefix_Detrend.Value,'_',fileName{1},'_',app.editSufix_Detrend.Value,'.txt'),'y','-ascii')

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI for the analysis of complexity and entropy in physiological signals';
            app.UIAxesCG_Original = uiaxes(app.figure1);
            title(app.UIAxesCG_Original, 'Original Data')
            xlabel(app.UIAxesCG_Original, 'X')
            ylabel(app.UIAxesCG_Original, 'Y')
            app.UIAxesCG_Original.ButtonDownFcn = createCallbackFcn(app, @UIAxesCG_OriginalButtonDown, true);
            app.text176 = uilabel(app.figure1);
            app.text176.Text = 'Application Mode';
            app.pMenuAppMode = uidropdown(app.figure1);
            app.pMenuAppMode.Items = {'Load Data', 'Pre-Process Data', 'Interpolation/Multiscale', 'Test Parameters/Run Pipeline', 'Process Results', 'Analysis'};
            app.pMenuAppMode.ValueChangedFcn = createCallbackFcn(app, @pMenuAppMode_Callback, true);
            app.pMenuAppMode.Value = 'Load Data';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Help';
            app.txtEpochT = uilabel(app.uipanel2);
            app.txtEpochT.Text = 'Epoch Length (sec)';
            app.editEpochT = uieditfield(app.uipanel2, 'text');
            app.editEpochT.ValueChangedFcn = createCallbackFcn(app, @editEpochTValueChanged, true);
            app.editEpochT.Value = '2';
            app.txtSampleR = uilabel(app.uipanel2);
            app.txtSampleR.Text = 'Sampling Rate';
            app.editSampleR = uieditfield(app.uipanel2, 'text');
            app.editSampleR.Value = '500';
            app.DataTypeButtonGroup = uibuttongroup(app.uipanel2);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.TimeSeriesButton = uiradiobutton(app.DataTypeButtonGroup);
            app.TimeSeriesButton.Text = 'Time Series';
            app.TimeSeriesButton.Value = true;
            app.NonTimeSeriesButton = uiradiobutton(app.DataTypeButtonGroup);
            app.NonTimeSeriesButton.Text = 'Non Time Series';
            app.pMenuHelp = uidropdown(app.uipanel2);
            app.pMenuHelp.Items = {'CEPS Manual', 'CEPS Primer', 'Usability Feedback form', 'Read Me'};
            app.pMenuHelp.Value = 'CEPS Manual';
            app.btmOpenHelpFile = uibutton(app.uipanel2, 'push');
            app.btmOpenHelpFile.ButtonPushedFcn = createCallbackFcn(app, @btmOpenHelpFileButtonPushed, true);
            app.btmOpenHelpFile.Text = 'Open  File';
            app.TabGroupButtom = uitabgroup(app.figure1);
            app.TabGroupButtom.SelectionChangedFcn = createCallbackFcn(app, @TabGroupButtomSelectionChanged, true);
            app.NoEventTab = uitab(app.TabGroupButtom);
            app.NoEventTab.Title = 'NoEvent';
            app.KeytoverificationstatusPanel = uipanel(app.NoEventTab);
            app.KeytoverificationstatusPanel.Title = 'Key to verification status';
            app.txtSampleR_7 = uilabel(app.KeytoverificationstatusPanel);
            app.txtSampleR_7.Text = {'O = Optimal'; 'A = Acceptable'; 'D = Divergent'; 'pD = Partially divergent'; 'T = Testing may still be required'; ''; 'If verification not indicated, assume testing may still be required!'};
            app.LoadDataTab = uitab(app.TabGroupButtom);
            app.LoadDataTab.Title = 'Load Data';
            app.Panel5 = uipanel(app.LoadDataTab);
            app.Panel5.Title = 'Panel5';
            app.txtEpochT_3 = uilabel(app.Panel5);
            app.txtEpochT_3.Text = 'Omit Row/s ';
            app.editRemoveRows = uieditfield(app.Panel5, 'text');
            app.txtEpochT_4 = uilabel(app.Panel5);
            app.txtEpochT_4.Text = 'Omit Column/s';
            app.editRemoveCols = uieditfield(app.Panel5, 'text');
            app.TextArea = uitextarea(app.Panel5);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'To omit individual columns or rows, enter their numbers separate by commas (e.g. 3,5). To omit ranges, separate by dashes (e.g. 2-5, 8-10).'};
            app.chkLoadAllSelectedFiles = uicheckbox(app.Panel5);
            app.chkLoadAllSelectedFiles.Text = 'Load all selected files';
            app.pbLoadData = uibutton(app.Panel5, 'push');
            app.pbLoadData.ButtonPushedFcn = createCallbackFcn(app, @pbLoadDataButtonPushed, true);
            app.pbLoadData.Text = 'Load Data File/s';
            app.editDataFileName = uieditfield(app.Panel5, 'text');
            app.editDataFileName.Enable = 'off';
            app.pbUnloadDataFile = uibutton(app.Panel5, 'push');
            app.pbUnloadDataFile.ButtonPushedFcn = createCallbackFcn(app, @pbUnloadDataFileButtonPushed, true);
            app.pbUnloadDataFile.Text = 'Unload Data File/s';
            app.TextArea_3 = uitextarea(app.Panel5);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Please select file to unload  from File ID combo box. -- All Files -- option will unload all files.'};
            app.chkLoadData_Uneven = uicheckbox(app.Panel5);
            app.chkLoadData_Uneven.ValueChangedFcn = createCallbackFcn(app, @chkLoadData_UnevenValueChanged, true);
            app.chkLoadData_Uneven.Text = 'Uneven Data';
            app.unevenDataPanel = uibuttongroup(app.Panel5);
            app.unevenDataPanel.Title = 'Uneven Data';
            app.unevenDataPanel.Visible = 'off';
            app.text117_6 = uilabel(app.unevenDataPanel);
            app.text117_6.Text = 'Time Interal';
            app.pMenuTimeType_LD = uidropdown(app.unevenDataPanel);
            app.pMenuTimeType_LD.Items = {'Cummulative', 'Interval'};
            app.pMenuTimeType_LD.Value = 'Cummulative';
            app.pMenuInterpolation_LD = uidropdown(app.unevenDataPanel);
            app.pMenuInterpolation_LD.Items = {'linear interpolation', 'shape-preserving piecewise cubic interpolation', 'piecewise cubic spline interpolation'};
            app.pMenuInterpolation_LD.Value = 'linear interpolation';
            app.text117_8 = uilabel(app.unevenDataPanel);
            app.text117_8.Text = 'Interpolation ';
            app.editResamplingFreq_LD = uieditfield(app.unevenDataPanel, 'text');
            app.editResamplingFreq_LD.Value = '1000';
            app.chkResamplingFreq_LD = uicheckbox(app.unevenDataPanel);
            app.chkResamplingFreq_LD.Text = 'Resampling Frequency';
            app.txtSampleR_24 = uilabel(app.unevenDataPanel);
            app.txtSampleR_24.Text = 'Sampling Rate';
            app.editSampleFS_LD = uieditfield(app.unevenDataPanel, 'text');
            app.editSampleFS_LD.Value = '500';
            app.chkEndpointEffect_LD = uicheckbox(app.unevenDataPanel);
            app.chkEndpointEffect_LD.ValueChangedFcn = createCallbackFcn(app, @chkEndpointEffect_LDValueChanged, true);
            app.chkEndpointEffect_LD.Text = 'Remove EndPoint Effect';
            app.txtCutOffRatio_LD = uilabel(app.unevenDataPanel);
            app.txtCutOffRatio_LD.Visible = 'off';
            app.txtCutOffRatio_LD.Text = 'Cutoff Ratio ';
            app.editCutOffRatio_LD = uieditfield(app.unevenDataPanel, 'text');
            app.editCutOffRatio_LD.Visible = 'off';
            app.editCutOffRatio_LD.Value = '0.25';
            app.LoadDataPanel = uipanel(app.LoadDataTab);
            app.LoadDataPanel.Title = 'Load Data';
            app.text45 = uilabel(app.LoadDataPanel);
            app.text45.Text = 'Data File Extension';
            app.pMenuDbFileExtension = uidropdown(app.LoadDataPanel);
            app.pMenuDbFileExtension.Items = {'txt', 'csv', 'mat', 'xlsx'};
            app.pMenuDbFileExtension.ValueChangedFcn = createCallbackFcn(app, @pMenuDbFileExtension_Callback, true);
            app.pMenuDbFileExtension.Value = 'txt';
            app.editDataDir = uieditfield(app.LoadDataPanel, 'text');
            app.editDataDir.Editable = 'off';
            app.editDataDir.Value = 'Edit Text';
            app.text45_2 = uilabel(app.LoadDataPanel);
            app.text45_2.Text = 'Current Data Folder';
            app.editFileExt = uieditfield(app.LoadDataPanel, 'text');
            app.editFileExt.Editable = 'off';
            app.editFileExt.Value = 'Edit Text';
            app.text45_3 = uilabel(app.LoadDataPanel);
            app.text45_3.Text = 'Current File Extension';
            app.pbSelectFiles = uibutton(app.LoadDataPanel, 'push');
            app.pbSelectFiles.ButtonPushedFcn = createCallbackFcn(app, @pbSelectFiles_Callback, true);
            app.pbSelectFiles.Text = 'Find data folder';
            app.text42 = uilabel(app.LoadDataPanel);
            app.text42.Text = 'Total Data Files';
            app.editNumDbFiles = uieditfield(app.LoadDataPanel, 'text');
            app.editNumDbFiles.Value = 'Edit Text';
            app.UITableFileData = uitable(app.LoadDataTab);
            app.UITableFileData.ColumnName = {'column1'};
            app.txtEpochT_2 = uilabel(app.LoadDataTab);
            app.txtEpochT_2.Text = 'Delimiter';
            app.pMenuDbFileExtension_2 = uidropdown(app.LoadDataTab);
            app.pMenuDbFileExtension_2.Items = {'comma', 'semicolon', 'Tab'};
            app.pMenuDbFileExtension_2.Value = 'comma';
            app.txtEpochT_6 = uilabel(app.LoadDataTab);
            app.txtEpochT_6.Text = 'Selected File:';
            app.pMenuDataFileName = uidropdown(app.LoadDataTab);
            app.pMenuDataFileName.Items = {'Select File To View'};
            app.pMenuDataFileName.ValueChangedFcn = createCallbackFcn(app, @pMenuDataFileNameValueChanged, true);
            app.pMenuDataFileName.Value = 'Select File To View';
            app.uiTableSelectedFiles = uitable(app.LoadDataTab);
            app.uiTableSelectedFiles.ColumnName = {'File Name'; 'Row Count'; 'Column Count'};
            app.uiTableSelectedFiles.CellEditCallback = createCallbackFcn(app, @uiTableSelectedFilesCellEdit, true);
            app.uiTableSelectedFiles.CellSelectionCallback = createCallbackFcn(app, @uiTableSelectedFilesCellSelection, true);
            app.txtEpochT_9 = uilabel(app.LoadDataTab);
            app.txtEpochT_9.Text = 'Loaded Data FIle Information';
            app.TabBottom_DataProcessing = uitab(app.TabGroupButtom);
            app.TabBottom_DataProcessing.Title = 'Pre-Process Data';
            app.Panel3 = uipanel(app.TabBottom_DataProcessing);
            app.Panel3.Title = 'Panel3';
            app.editOutlierMW = uieditfield(app.Panel3, 'text');
            app.editOutlierMW.Value = '5';
            app.chkApplyFilter = uicheckbox(app.Panel3);
            app.chkApplyFilter.Text = 'Apply FFT Filter';
            app.chkMedianFilter = uicheckbox(app.Panel3);
            app.chkMedianFilter.ValueChangedFcn = createCallbackFcn(app, @chkMedianFilter_Callback, true);
            app.chkMedianFilter.Text = 'Apply Median Filter';
            app.text191 = uilabel(app.Panel3);
            app.text191.Text = 'Filter Order';
            app.editMedianFilterOrder = uieditfield(app.Panel3, 'text');
            app.editMedianFilterOrder.Value = '5';
            app.text192 = uilabel(app.Panel3);
            app.text192.Text = 'High Pass';
            app.editLowBand = uieditfield(app.Panel3, 'text');
            app.editLowBand.Value = '5';
            app.text193 = uilabel(app.Panel3);
            app.text193.Text = 'Low Pass';
            app.editHighBand = uieditfield(app.Panel3, 'text');
            app.editHighBand.Value = '5';
            app.OutlierMW = uilabel(app.Panel3);
            app.OutlierMW.Text = 'Median Width';
            app.pMenuOutlierType = uidropdown(app.Panel3);
            app.pMenuOutlierType.Items = {'Whole Dataset', 'Per Epoch'};
            app.pMenuOutlierType.Value = 'Whole Dataset';
            app.chkRemoveOutliers = uicheckbox(app.Panel3);
            app.chkRemoveOutliers.Text = 'Remove Outliers ';
            app.pbProcessData = uibutton(app.Panel3, 'push');
            app.pbProcessData.ButtonPushedFcn = createCallbackFcn(app, @pbProcessData_Callback, true);
            app.pbProcessData.Text = 'Pre-process Data';
            app.TextArea_5 = uitextarea(app.Panel3);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Please select file to pre-process from File ID combo box. '; '-- All Files -- option will pre-process all files.'};
            app.pbRecoverTruncatedData = uibutton(app.Panel3, 'push');
            app.pbRecoverTruncatedData.ButtonPushedFcn = createCallbackFcn(app, @pbRecoverTruncatedDataButtonPushed, true);
            app.pbRecoverTruncatedData.Text = 'Recover Pre-processed';
            app.pbSaveDataFile = uibutton(app.Panel3, 'push');
            app.pbSaveDataFile.ButtonPushedFcn = createCallbackFcn(app, @pbSaveDataFileButtonPushed, true);
            app.pbSaveDataFile.Text = 'Save Data File';
            app.txtEpochT_11 = uilabel(app.Panel3);
            app.txtEpochT_11.Text = 'Prefix';
            app.txtEpochT_12 = uilabel(app.Panel3);
            app.txtEpochT_12.Text = 'Suffix';
            app.editFilePrefix = uieditfield(app.Panel3, 'text');
            app.editFileSuffix = uieditfield(app.Panel3, 'text');
            app.chkApplyDetrending_PPD = uicheckbox(app.Panel3);
            app.chkApplyDetrending_PPD.Text = 'Apply Detrending';
            app.text192_2 = uilabel(app.Panel3);
            app.text192_2.Text = 'Degree Polynomial (n)';
            app.editDegreePoly_PPD = uieditfield(app.Panel3, 'text');
            app.editDegreePoly_PPD.Value = '5';
            app.TruncateDataPanel = uipanel(app.TabBottom_DataProcessing);
            app.TruncateDataPanel.Title = 'Truncate Data';
            app.editStartT = uieditfield(app.TruncateDataPanel, 'text');
            app.editStartT.Value = '1';
            app.txtStartT = uilabel(app.TruncateDataPanel);
            app.txtStartT.Text = 'Truncate at start up to sec/point ';
            app.txtMaxLength = uilabel(app.TruncateDataPanel);
            app.txtMaxLength.Text = 'Truncate at end, from sec/point ';
            app.editMaxLength = uieditfield(app.TruncateDataPanel, 'text');
            app.editMaxLength.Value = '5';
            app.pbTruncateData = uibutton(app.TruncateDataPanel, 'push');
            app.pbTruncateData.ButtonPushedFcn = createCallbackFcn(app, @pbTruncateDataButtonPushed, true);
            app.pbTruncateData.Text = 'Truncate Data';
            app.TextArea_4 = uitextarea(app.TruncateDataPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Please select file to truncate  from File ID combo box. '; '-- All Files -- option will truncate all data from all files.'};
            app.pbRecoverRawData = uibutton(app.TruncateDataPanel, 'push');
            app.pbRecoverRawData.ButtonPushedFcn = createCallbackFcn(app, @pbRecoverRawDataButtonPushed, true);
            app.pbRecoverRawData.Text = 'Recover Raw Data';
            app.TabGroup = uitabgroup(app.TabBottom_DataProcessing);
            app.ViewDataTab = uitab(app.TabGroup);
            app.ViewDataTab.Title = 'View Data';
            app.myAxesUp = uiaxes(app.ViewDataTab);
            app.myAxesDown = uiaxes(app.ViewDataTab);
            app.pbPlotData = uibutton(app.ViewDataTab, 'push');
            app.pbPlotData.ButtonPushedFcn = createCallbackFcn(app, @pbPlotDataButtonPushed, true);
            app.pbPlotData.Text = 'Plot Data';
            app.pbDeleteDataRange = uibutton(app.ViewDataTab, 'push');
            app.pbDeleteDataRange.ButtonPushedFcn = createCallbackFcn(app, @pbDeleteDataRangeButtonPushed, true);
            app.pbDeleteDataRange.Enable = 'off';
            app.pbDeleteDataRange.Text = 'Delete Data Range';
            app.chkShowOutliers = uicheckbox(app.ViewDataTab);
            app.chkShowOutliers.Text = 'View Outliers';
            app.chkShowTruncatedData = uicheckbox(app.ViewDataTab);
            app.chkShowTruncatedData.ValueChangedFcn = createCallbackFcn(app, @chkShowTruncatedDataValueChanged, true);
            app.chkShowTruncatedData.Text = 'View All Data';
            app.PreviewEffectTab = uitab(app.TabGroup);
            app.PreviewEffectTab.Title = 'Preview Effect';
            app.UIAxesPreviewE = uiaxes(app.PreviewEffectTab);
            title(app.UIAxesPreviewE, 'Preview Parameter Effects')
            xlabel(app.UIAxesPreviewE, 'X')
            ylabel(app.UIAxesPreviewE, 'Y')
            app.pbPP_PreviewEffect = uibutton(app.PreviewEffectTab, 'push');
            app.pbPP_PreviewEffect.ButtonPushedFcn = createCallbackFcn(app, @pbPP_PreviewEffectButtonPushed, true);
            app.pbPP_PreviewEffect.Text = 'Preview Effect';
            app.pMenuPP_FilterType = uidropdown(app.PreviewEffectTab);
            app.pMenuPP_FilterType.Items = {'Select Filter Parameter Range', 'Outlier Median Width', 'Median Filter Order', 'FFT Filter High Pass', 'FFT Filter Low Pass', 'Deterndring - (n)'};
            app.pMenuPP_FilterType.Value = 'Select Filter Parameter Range';
            app.editPreviewLRange = uieditfield(app.PreviewEffectTab, 'text');
            app.editPreviewLRange.Value = '5';
            app.editPreviewURange = uieditfield(app.PreviewEffectTab, 'text');
            app.editPreviewURange.Value = '5';
            app.txtEpochT_10 = uilabel(app.PreviewEffectTab);
            app.txtEpochT_10.Text = 'Range';
            app.chkPP_Epoch = uicheckbox(app.PreviewEffectTab);
            app.chkPP_Epoch.Text = 'Per Epoch';
            app.OutliersDeletedEntitiesTab = uitab(app.TabGroup);
            app.OutliersDeletedEntitiesTab.Title = 'Outliers/Deleted Entities';
            app.UITablePP_Outliers = uitable(app.OutliersDeletedEntitiesTab);
            app.UITablePP_Outliers.ColumnName = {'File Name'; 'Column ID'; 'Row ID'; 'Orignal Value'; 'Replaced Value'};
            app.UITablePP_Outliers.RowName = {};
            app.UITablePP_Removed = uitable(app.OutliersDeletedEntitiesTab);
            app.UITablePP_Removed.ColumnName = {'File Name'; 'Start Index'; 'End Index'};
            app.UITablePP_Removed.RowName = {};
            app.text193_3 = uilabel(app.OutliersDeletedEntitiesTab);
            app.text193_3.Text = 'Outlier List';
            app.text193_4 = uilabel(app.OutliersDeletedEntitiesTab);
            app.text193_4.Text = 'Deleted Entities List';
            app.pbPreprocessRefresh = uibutton(app.OutliersDeletedEntitiesTab, 'push');
            app.pbPreprocessRefresh.ButtonPushedFcn = createCallbackFcn(app, @pbPreprocessRefreshButtonPushed, true);
            app.pbPreprocessRefresh.Text = 'Refresh';
            app.CompareDataFilesTab = uitab(app.TabGroup);
            app.CompareDataFilesTab.Title = 'Compare Data Files';
            app.UIAxesPPRaw = uiaxes(app.CompareDataFilesTab);
            title(app.UIAxesPPRaw, 'Raw Data Files')
            app.UIAxesPPRaw.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxesPPProcessed = uiaxes(app.CompareDataFilesTab);
            title(app.UIAxesPPProcessed, 'Processed Data Files')
            app.pbPPClearPlots = uibutton(app.CompareDataFilesTab, 'push');
            app.pbPPClearPlots.ButtonPushedFcn = createCallbackFcn(app, @pbPPClearPlotsButtonPushed, true);
            app.pbPPClearPlots.Text = 'Clear Plot';
            app.pbPPPlotData = uibutton(app.CompareDataFilesTab, 'push');
            app.pbPPPlotData.ButtonPushedFcn = createCallbackFcn(app, @pbPPPlotDataButtonPushed, true);
            app.pbPPPlotData.Text = 'Plot Data';
            app.pMenuPPViewMode = uidropdown(app.CompareDataFilesTab);
            app.pMenuPPViewMode.Items = {'Raw Data', 'Truncated Data'};
            app.pMenuPPViewMode.Value = 'Raw Data';
            app.DataModificationTab = uitab(app.TabGroupButtom);
            app.DataModificationTab.Title = 'Data Modification';
            app.PanelChk = uipanel(app.DataModificationTab);
            app.PanelChk.Title = 'File Processing View';
            app.editCG_ImageName = uieditfield(app.DataModificationTab, 'text');
            app.txtSampleR_22 = uilabel(app.DataModificationTab);
            app.txtSampleR_22.Text = 'File Name:';
            app.SaveCGImages = uibutton(app.DataModificationTab, 'push');
            app.SaveCGImages.ButtonPushedFcn = createCallbackFcn(app, @SaveCGImagesButtonPushed3, true);
            app.SaveCGImages.Text = 'Save Image';
            app.TabGroup4 = uitabgroup(app.DataModificationTab);
            app.tbDataModification_Down = uitab(app.TabGroup4);
            app.tbDataModification_Down.Title = 'Data Modification';
            app.tabCG_ParameterSelection = uitabgroup(app.tbDataModification_Down);
            app.NormalisationTab = uitab(app.tabCG_ParameterSelection);
            app.NormalisationTab.Title = 'Normalisation';
            app.NormalisationTab.ButtonDownFcn = createCallbackFcn(app, @NormalisationTabButtonDown, true);
            app.txtSampleR_21 = uilabel(app.NormalisationTab);
            app.txtSampleR_21.Text = 'C. Normalisation';
            app.pMenuNormalization = uidropdown(app.NormalisationTab);
            app.pMenuNormalization.Items = {'-- All Methods --', '(X-nanmean(X))/nanstd(X)', '(X-nanmean(X))/(nanmax(X)-nanmin(X))', '(X-nanmin(X))/(nanmax(X)-nanmin(X))'};
            app.pMenuNormalization.Value = '-- All Methods --';
            app.ViewNormalization = uibutton(app.NormalisationTab, 'push');
            app.ViewNormalization.ButtonPushedFcn = createCallbackFcn(app, @ViewNormalizationButtonPushed, true);
            app.ViewNormalization.Text = 'View Normalisation';
            app.IncreaseTab = uitab(app.tabCG_ParameterSelection);
            app.IncreaseTab.Title = 'Increase';
            app.pMenuIP_Method = uidropdown(app.IncreaseTab);
            app.pMenuIP_Method.Items = {'-- All Methods --', 'nearest', 'linear', 'spline', 'cubic', 'makima'};
            app.pMenuIP_Method.ValueChangedFcn = createCallbackFcn(app, @pMenuIP_MethodValueChanged, true);
            app.pMenuIP_Method.Value = '-- All Methods --';
            app.txtSampleR_8 = uilabel(app.IncreaseTab);
            app.txtSampleR_8.Text = 'A. Interpolation Method';
            app.txtSampleR_9 = uilabel(app.IncreaseTab);
            app.txtSampleR_9.Text = 'nFineGrid';
            app.pMenuFineGrid = uidropdown(app.IncreaseTab);
            app.pMenuFineGrid.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.pMenuFineGrid.Value = '1';
            app.ViewInterpolation = uibutton(app.IncreaseTab, 'push');
            app.ViewInterpolation.ButtonPushedFcn = createCallbackFcn(app, @ViewInterpolationButtonPushed, true);
            app.ViewInterpolation.Text = 'View Effects';
            app.ReduceTab = uitab(app.tabCG_ParameterSelection);
            app.ReduceTab.Title = 'Reduce';
            app.ViewReduce = uibutton(app.ReduceTab, 'push');
            app.ViewReduce.ButtonPushedFcn = createCallbackFcn(app, @ViewReduceButtonPushed, true);
            app.ViewReduce.Text = 'View Effects';
            app.t = uilabel(app.ReduceTab);
            app.t.Text = 'Scale/s';
            app.pMenuScale = uidropdown(app.ReduceTab);
            app.pMenuScale.Items = {'--All Scales--', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.pMenuScale.ValueChangedFcn = createCallbackFcn(app, @pMenuScaleValueChanged, true);
            app.pMenuScale.Value = '--All Scales--';
            app.editScaleRange = uieditfield(app.ReduceTab, 'text');
            app.editScaleRange.Value = '-- Give Range --';
            app.t_2 = uilabel(app.ReduceTab);
            app.t_2.Text = 'Slide By';
            app.pMenuScaleSlideBy = uidropdown(app.ReduceTab);
            app.pMenuScaleSlideBy.Items = {'--All Scales--', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.pMenuScaleSlideBy.Value = '--All Scales--';
            app.t_3 = uilabel(app.ReduceTab);
            app.t_3.Text = 'Aggregate :';
            app.pMenuScaleAggregate = uidropdown(app.ReduceTab);
            app.pMenuScaleAggregate.Items = {'-- All Methods --', 'mean', 'median', 'std'};
            app.pMenuScaleAggregate.Value = '-- All Methods --';
            app.AddNoiseTab_2 = uitab(app.tabCG_ParameterSelection);
            app.AddNoiseTab_2.Title = 'Add Noise';
            app.NoiseTypeLabel = uilabel(app.AddNoiseTab_2);
            app.NoiseTypeLabel.Text = 'Noise Type';
            app.pMenuNoiseType = uidropdown(app.AddNoiseTab_2);
            app.pMenuNoiseType.Items = {'-- Noise Type --', 'White Noise', 'Blue Noise', 'Red Noise', 'Violet Noise', 'Pink Noise', 'Sine Wave', 'Cosine Wave'};
            app.pMenuNoiseType.Value = '-- Noise Type --';
            app.BlendingCoefficientLabel = uilabel(app.AddNoiseTab_2);
            app.BlendingCoefficientLabel.Text = 'Blending Coefficient';
            app.NoisewaveFrequencyLabel = uilabel(app.AddNoiseTab_2);
            app.NoisewaveFrequencyLabel.Text = 'Noise wave Frequency';
            app.editNoiseWaveFrequecy = uieditfield(app.AddNoiseTab_2, 'text');
            app.editNoiseWaveFrequecy.Value = '60';
            app.editWaveAmplitude = uieditfield(app.AddNoiseTab_2, 'text');
            app.editWaveAmplitude.Value = '1';
            app.editNoiseBlendingCoeff = uieditfield(app.AddNoiseTab_2, 'text');
            app.editNoiseBlendingCoeff.ValueChangedFcn = createCallbackFcn(app, @editNoiseBlendingCoeffValueChanged, true);
            app.editNoiseBlendingCoeff.Value = '0';
            app.NoisewaveAmplitudeLabel = uilabel(app.AddNoiseTab_2);
            app.NoisewaveAmplitudeLabel.Text = 'Noise wave Amplitude';
            app.pbPreviewAddNoise = uibutton(app.AddNoiseTab_2, 'push');
            app.pbPreviewAddNoise.ButtonPushedFcn = createCallbackFcn(app, @pbPreviewAddNoiseButtonPushed, true);
            app.pbPreviewAddNoise.Text = 'Preview Effect';
            app.pbSaveNoiseFile = uibutton(app.AddNoiseTab_2, 'push');
            app.pbSaveNoiseFile.ButtonPushedFcn = createCallbackFcn(app, @pbSaveNoiseFileButtonPushed, true);
            app.pbSaveNoiseFile.Text = 'Save Copy of Noise';
            app.NoiseFileNameLabel = uilabel(app.AddNoiseTab_2);
            app.NoiseFileNameLabel.Text = 'Noise File Name';
            app.editNoiseFileName = uieditfield(app.AddNoiseTab_2, 'text');
            app.editNoiseFileName.Value = 'noise';
            app.NoteYoucansavethe5minutesNoisefileLabel = uilabel(app.AddNoiseTab_2);
            app.NoteYoucansavethe5minutesNoisefileLabel.Text = 'Note: You can save the 5 minutes Noise file.';
            app.CutfilesTab = uitab(app.tabCG_ParameterSelection);
            app.CutfilesTab.Title = 'Cut files';
            app.editCutFile_Init = uieditfield(app.CutfilesTab, 'text');
            app.txtSampleR_17 = uilabel(app.CutfilesTab);
            app.txtSampleR_17.Text = 'Initial Truncate';
            app.editCutFile_End = uieditfield(app.CutfilesTab, 'text');
            app.txtSampleR_18 = uilabel(app.CutfilesTab);
            app.txtSampleR_18.Text = 'End Truncate';
            app.editDivision = uieditfield(app.CutfilesTab, 'text');
            app.pMenuDivisionType = uidropdown(app.CutfilesTab);
            app.pMenuDivisionType.Items = {'Number of Division', 'Length of Division'};
            app.pMenuDivisionType.Value = 'Number of Division';
            app.editCutFile_Overlap = uieditfield(app.CutfilesTab, 'text');
            app.txtSampleR_20 = uilabel(app.CutfilesTab);
            app.txtSampleR_20.Text = 'Overlapping';
            app.ViewSegmentationButton = uibutton(app.CutfilesTab, 'push');
            app.ViewSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSegmentationButtonPushed2, true);
            app.ViewSegmentationButton.Text = 'View Segmentation';
            app.txtSampleR_19 = uilabel(app.CutfilesTab);
            app.txtSampleR_19.Text = 'For Time Series dataset, values are second and for non-series dataset alues are in number of data points.';
            app.chkAddNoise = uicheckbox(app.tbDataModification_Down);
            app.chkAddNoise.ValueChangedFcn = createCallbackFcn(app, @chkAddNoiseValueChanged, true);
            app.chkAddNoise.Text = '  Add Noise';
            app.chkIncrease = uicheckbox(app.tbDataModification_Down);
            app.chkIncrease.ValueChangedFcn = createCallbackFcn(app, @chkIncreaseValueChanged, true);
            app.chkIncrease.Text = '  Increase';
            app.chkReduce = uicheckbox(app.tbDataModification_Down);
            app.chkReduce.ValueChangedFcn = createCallbackFcn(app, @chkReduceValueChanged, true);
            app.chkReduce.Text = '  Reduce';
            app.chkCutFile = uicheckbox(app.tbDataModification_Down);
            app.chkCutFile.ValueChangedFcn = createCallbackFcn(app, @chkCutFileValueChanged, true);
            app.chkCutFile.Text = ' Cut Files';
            app.chkNormalization = uicheckbox(app.tbDataModification_Down);
            app.chkNormalization.ValueChangedFcn = createCallbackFcn(app, @chkNormalizationValueChanged, true);
            app.chkNormalization.Text = '  Normalisation';
            app.chkBinarisation = uicheckbox(app.tbDataModification_Down);
            app.chkBinarisation.Text = 'Binarisation ';
            app.pMenuBinarisation = uidropdown(app.tbDataModification_Down);
            app.pMenuBinarisation.Items = {'-- Binarisation Methods  --', 'average Method', 'Modified Zone Method', 'Differential Method'};
            app.pMenuBinarisation.Value = '-- Binarisation Methods  --';
            app.ViewCG_Effecs = uibutton(app.tbDataModification_Down, 'push');
            app.ViewCG_Effecs.ButtonPushedFcn = createCallbackFcn(app, @ViewCG_EffecsButtonPushed, true);
            app.ViewCG_Effecs.Text = 'View Effects';
            app.txtSampleR_13 = uilabel(app.tbDataModification_Down);
            app.txtSampleR_13.Text = 'Prefix';
            app.editCG_Prefix = uieditfield(app.tbDataModification_Down, 'text');
            app.txtSampleR_14 = uilabel(app.tbDataModification_Down);
            app.txtSampleR_14.Text = 'Suffix';
            app.editCG_Suffix = uieditfield(app.tbDataModification_Down, 'text');
            app.ApplyandSaveButton = uibutton(app.tbDataModification_Down, 'push');
            app.ApplyandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyCoarseGrainButtonPushed, true);
            app.ApplyandSaveButton.Text = 'Apply and Save';
            app.tbResampling = uitab(app.TabGroup4);
            app.tbResampling.Title = 'Resampling';
            app.text45_4 = uilabel(app.tbResampling);
            app.text45_4.Text = 'Data File Extension';
            app.pMenuFileExtention_RS = uidropdown(app.tbResampling);
            app.pMenuFileExtention_RS.Items = {'txt', 'csv', 'mat', 'xlsx'};
            app.pMenuFileExtention_RS.Value = 'txt';
            app.pbLoadData_RS = uibutton(app.tbResampling, 'push');
            app.pbLoadData_RS.ButtonPushedFcn = createCallbackFcn(app, @pbLoadData_RSButtonPushed, true);
            app.pbLoadData_RS.Text = 'Load Data Folder';
            app.text117_2 = uilabel(app.tbResampling);
            app.text117_2.Text = 'File/s ';
            app.pMenuFileNames_RS = uidropdown(app.tbResampling);
            app.pMenuFileNames_RS.Items = {'-- All Files --', '-- Range --'};
            app.pMenuFileNames_RS.Value = '-- All Files --';
            app.pMenuTimeType = uidropdown(app.tbResampling);
            app.pMenuTimeType.Items = {'Cummulative', 'Interval'};
            app.pMenuTimeType.Value = 'Cummulative';
            app.text117_3 = uilabel(app.tbResampling);
            app.text117_3.Text = 'Time Interal';
            app.pbViewRS = uibutton(app.tbResampling, 'push');
            app.pbViewRS.ButtonPushedFcn = createCallbackFcn(app, @pbViewRSButtonPushed, true);
            app.pbViewRS.Text = 'View  Resamplying';
            app.pbApplySave_RS = uibutton(app.tbResampling, 'push');
            app.pbApplySave_RS.ButtonPushedFcn = createCallbackFcn(app, @pbApplySave_RSButtonPushed, true);
            app.pbApplySave_RS.Text = 'Apply/Save';
            app.editResample_prefix = uieditfield(app.tbResampling, 'text');
            app.text117_4 = uilabel(app.tbResampling);
            app.text117_4.Text = 'Prefix';
            app.editResample_sufix = uieditfield(app.tbResampling, 'text');
            app.text117_5 = uilabel(app.tbResampling);
            app.text117_5.Text = 'sufix';
            app.text45_5 = uilabel(app.tbResampling);
            app.text45_5.Text = 'File Location:';
            app.editFileLocation_RS = uieditfield(app.tbResampling, 'text');
            app.editFileLocation_RS.Editable = 'off';
            app.chkSaveTimeStamp = uicheckbox(app.tbResampling);
            app.chkSaveTimeStamp.Text = 'Save Timestamp';
            app.chkSaveTimeStamp.Value = true;
            app.editResamplingFreq = uieditfield(app.tbResampling, 'text');
            app.editResamplingFreq.Value = '1000';
            app.chkResamplingFreq = uicheckbox(app.tbResampling);
            app.chkResamplingFreq.Text = 'Resampling Frequency';
            app.pMenuInterpolation_DM = uidropdown(app.tbResampling);
            app.pMenuInterpolation_DM.Items = {'linear interpolation', 'shape-preserving piecewise cubic interpolation', 'piecewise cubic spline interpolation'};
            app.pMenuInterpolation_DM.Value = 'linear interpolation';
            app.text117_7 = uilabel(app.tbResampling);
            app.text117_7.Text = 'Interpolation Method';
            app.chkEndpointEffect = uicheckbox(app.tbResampling);
            app.chkEndpointEffect.ValueChangedFcn = createCallbackFcn(app, @chkEndpointEffectValueChanged, true);
            app.chkEndpointEffect.Text = 'Remove EndPoint Effect';
            app.txtCutOffRatio = uilabel(app.tbResampling);
            app.txtCutOffRatio.Visible = 'off';
            app.txtCutOffRatio.Text = 'Cutoff Ratio ';
            app.editCutOffRatio = uieditfield(app.tbResampling, 'text');
            app.editCutOffRatio.Visible = 'off';
            app.editCutOffRatio.Value = '0.25';
            app.txtSampleR_23 = uilabel(app.tbResampling);
            app.txtSampleR_23.Text = 'Sampling Rate';
            app.editSampleFS_DM = uieditfield(app.tbResampling, 'text');
            app.editSampleFS_DM.Value = '500';
            app.TestParameter = uitab(app.TabGroupButtom);
            app.TestParameter.SizeChangedFcn = createCallbackFcn(app, @TestParameterSizeChanged, true);
            app.TestParameter.Title = 'Test Parameters / Run Pipeline';
            app.TestParametersPanel = uipanel(app.TestParameter);
            app.TestParametersPanel.Title = 'Test Parameters';
            app.editTestStepSize = uieditfield(app.TestParametersPanel, 'text');
            app.editTestStepSize.Value = '1';
            app.text129 = uilabel(app.TestParametersPanel);
            app.text129.Text = 'Parameter Increments';
            app.pbPlotTest = uibutton(app.TestParametersPanel, 'push');
            app.pbPlotTest.ButtonPushedFcn = createCallbackFcn(app, @pbPlotTest_Callback, true);
            app.pbPlotTest.Text = 'Plot Results';
            app.SaveDataTablePanel = uipanel(app.TestParametersPanel);
            app.SaveDataTablePanel.Title = 'Save Data Table';
            app.pbTableDirectory = uibutton(app.SaveDataTablePanel, 'push');
            app.pbTableDirectory.ButtonPushedFcn = createCallbackFcn(app, @pbTableDirectoryButtonPushed, true);
            app.pbTableDirectory.Text = 'Select Directory';
            app.editTableDirectory = uieditfield(app.SaveDataTablePanel, 'text');
            app.text129_2 = uilabel(app.SaveDataTablePanel);
            app.text129_2.Text = 'File Name';
            app.editTableDataFName = uieditfield(app.SaveDataTablePanel, 'text');
            app.editTableDataFName.Value = '1';
            app.pbSaveTableData = uibutton(app.SaveDataTablePanel, 'push');
            app.pbSaveTableData.ButtonPushedFcn = createCallbackFcn(app, @pbSaveTableDataButtonPushed, true);
            app.pbSaveTableData.Text = 'Save Table In Excel';
            app.txtInfoProcessing = uilabel(app.TestParametersPanel);
            app.txtInfoProcessing.Text = 'Computation Time(s)';
            app.TabGroupTestParameters = uitabgroup(app.TestParameter);
            app.PlotsTab = uitab(app.TabGroupTestParameters);
            app.PlotsTab.Title = 'Plots';
            app.axisNoEvent = uiaxes(app.PlotsTab);
            title(app.axisNoEvent, 'Title')
            xlabel(app.axisNoEvent, 'X')
            ylabel(app.axisNoEvent, 'Y')
            app.tableNoEvent = uitable(app.PlotsTab);
            app.tableNoEvent.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.tableNoEvent.RowName = {};
            app.tableNoEvent.Visible = 'off';
            app.Stationarity_And_NonlinearityTab = uitab(app.TabGroupTestParameters);
            app.Stationarity_And_NonlinearityTab.Title = 'Stationarity_And_Nonlinearity';
            app.uiPanelAutoCov = uipanel(app.Stationarity_And_NonlinearityTab);
            app.uiPanelAutoCov.Title = 'Auto Covariance';
            app.editAutoCovLagK_T1 = uieditfield(app.uiPanelAutoCov, 'text');
            app.editAutoCovLagK_T1.Visible = 'off';
            app.editAutoCovLagK_T1.Value = 'Edit Text';
            app.text13 = uilabel(app.uiPanelAutoCov);
            app.text13.Text = 'Lag𝑲';
            app.editAutoCovLagK = uieditfield(app.uiPanelAutoCov, 'text');
            app.editAutoCovLagK.Value = 'Edit Text';
            app.uiPanelMWindow = uipanel(app.Stationarity_And_NonlinearityTab);
            app.uiPanelMWindow.Title = 'Moving Window Test';
            app.editMWindowM_T1 = uieditfield(app.uiPanelMWindow, 'text');
            app.editMWindowM_T1.Visible = 'off';
            app.editMWindowM_T1.Value = 'Edit Text';
            app.text162 = uilabel(app.uiPanelMWindow);
            app.text162.Text = 'Window Length (𝒎)';
            app.editMWindowM = uieditfield(app.uiPanelMWindow, 'text');
            app.editMWindowM.Value = 'Edit Text';
            app.editMWindowT1_T1 = uieditfield(app.uiPanelMWindow, 'text');
            app.editMWindowT1_T1.Visible = 'off';
            app.editMWindowT1_T1.Value = 'Edit Text';
            app.text163 = uilabel(app.uiPanelMWindow);
            app.text163.Text = 'Threshold1 (𝒕1)';
            app.editMWindowT1 = uieditfield(app.uiPanelMWindow, 'text');
            app.editMWindowT1.Value = 'Edit Text';
            app.editMWindowT2_T1 = uieditfield(app.uiPanelMWindow, 'text');
            app.editMWindowT2_T1.Visible = 'off';
            app.editMWindowT2_T1.Value = 'Edit Text';
            app.text164 = uilabel(app.uiPanelMWindow);
            app.text164.Text = 'Threshold2 (𝒕2)';
            app.editMWindowT2 = uieditfield(app.uiPanelMWindow, 'text');
            app.editMWindowT2.Value = 'Edit Text';
            app.uiPanelAutoCorrelation = uipanel(app.Stationarity_And_NonlinearityTab);
            app.uiPanelAutoCorrelation.Title = 'Autocorrelation';
            app.text243 = uilabel(app.uiPanelAutoCorrelation);
            app.text243.Text = 'Num Lags';
            app.editAutoCorrNumLags = uieditfield(app.uiPanelAutoCorrelation, 'text');
            app.editAutoCorrNumLags.Value = '1';
            app.TextAreaTestInfo = uitextarea(app.uiPanelAutoCorrelation);
            app.TextAreaTestInfo.Editable = 'off';
            app.TextAreaTestInfo.Enable = 'off';
            app.TextAreaTestInfo.Visible = 'off';
            app.TextAreaTestInfo.Value = {'Autocorrelation from 1 to Num Lags +1 will be displayed'};
            app.editAutoCorrNumLags_T1 = uieditfield(app.uiPanelAutoCorrelation, 'text');
            app.editAutoCorrNumLags_T1.Visible = 'off';
            app.editAutoCorrNumLags_T1.Value = '1';
            app.uiPanelRATest = uipanel(app.Stationarity_And_NonlinearityTab);
            app.uiPanelRATest.Title = 'Reverse Arrangement Test';
            app.text177 = uilabel(app.uiPanelRATest);
            app.text177.Text = 'Method';
            app.pMenuRAMethod = uidropdown(app.uiPanelRATest);
            app.pMenuRAMethod.Items = {'Method 1', 'Method 2'};
            app.pMenuRAMethod.Value = 'Method 1';
            app.txtReverseAMethod_T1 = uilabel(app.uiPanelRATest);
            app.txtReverseAMethod_T1.Visible = 'off';
            app.txtReverseAMethod_T1.Text = 'Both Methods will be Tested.';
            app.Normality_TestsTab = uitab(app.TabGroupTestParameters);
            app.Normality_TestsTab.Title = 'Normality_Tests';
            app.uiPanelKS = uipanel(app.Normality_TestsTab);
            app.uiPanelKS.Title = 'Kolmogorov-Smirnov (KSTest)';
            app.text72 = uilabel(app.uiPanelKS);
            app.text72.Text = 'Level of Significance (𝒑)';
            app.editKSLevelOfSig = uieditfield(app.uiPanelKS, 'text');
            app.editKSLevelOfSig.Value = 'Edit Text';
            app.editKSLevelOfSig_T1 = uieditfield(app.uiPanelKS, 'text');
            app.editKSLevelOfSig_T1.Visible = 'off';
            app.editKSLevelOfSig_T1.Value = 'Edit Text';
            app.uiPanelSW = uipanel(app.Normality_TestsTab);
            app.uiPanelSW.Title = 'ShapiroWilk Test (SWTest)';
            app.text72_2 = uilabel(app.uiPanelSW);
            app.text72_2.Text = 'Level of Significance (𝒑)';
            app.editSW_P = uieditfield(app.uiPanelSW, 'text');
            app.editSW_P.Value = 'Edit Text';
            app.editSW_P_T1 = uieditfield(app.uiPanelSW, 'text');
            app.editSW_P_T1.Visible = 'off';
            app.editSW_P_T1.Value = 'Edit Text';
            app.text72_3 = uilabel(app.uiPanelSW);
            app.text72_3.Text = 'Choose Measure';
            app.pMenuSW_Measures = uidropdown(app.uiPanelSW);
            app.pMenuSW_Measures.Items = {'H', 'pValue', 'SW Statistic'};
            app.pMenuSW_Measures.Value = 'H';
            app.uiPanelContCM = uipanel(app.Normality_TestsTab);
            app.uiPanelContCM.Title = 'Continuous Complexity Measures';
            app.text22_4 = uilabel(app.uiPanelContCM);
            app.text22_4.Text = 'Maximum Value (max)';
            app.editCCM_Min_T1 = uieditfield(app.uiPanelContCM, 'text');
            app.editCCM_Min_T1.Visible = 'off';
            app.editCCM_Min_T1.Value = 'Edit Text';
            app.editCCM_Max_T1 = uieditfield(app.uiPanelContCM, 'text');
            app.editCCM_Max_T1.Visible = 'off';
            app.editCCM_Max_T1.Value = 'Edit Text';
            app.editCCM_DistSS_T1 = uieditfield(app.uiPanelContCM, 'text');
            app.editCCM_DistSS_T1.Visible = 'off';
            app.editCCM_DistSS_T1.Value = 'Edit Text';
            app.editCCM_Min = uieditfield(app.uiPanelContCM, 'text');
            app.editCCM_Min.Value = 'Edit Text';
            app.editCCM_Max = uieditfield(app.uiPanelContCM, 'text');
            app.editCCM_Max.Value = 'Edit Text';
            app.editCCM_DistSS = uieditfield(app.uiPanelContCM, 'text');
            app.editCCM_DistSS.Value = 'Edit Text';
            app.text21_4 = uilabel(app.uiPanelContCM);
            app.text21_4.Text = 'Minimum Value (min)';
            app.text23_2 = uilabel(app.uiPanelContCM);
            app.text23_2.Text = 'Dist Sample Size';
            app.text72_4 = uilabel(app.uiPanelContCM);
            app.text72_4.Text = 'Choose Measure';
            app.pMenuCM_Methods = uidropdown(app.uiPanelContCM);
            app.pMenuCM_Methods.Items = {'Emergency', 'Self Organization', 'Complexity', 'Entropy'};
            app.pMenuCM_Methods.Value = 'Complexity';
            app.Conditional_EntropiesTab = uitab(app.TabGroupTestParameters);
            app.Conditional_EntropiesTab.Title = 'Conditional_Entropies';
            app.uiPanelSEntropy1 = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelSEntropy1.Title = 'Sample Entropy (Data Share)';
            app.text22 = uilabel(app.uiPanelSEntropy1);
            app.text22.Text = 'Threshold (𝒓)';
            app.editSE_M_T1 = uieditfield(app.uiPanelSEntropy1, 'text');
            app.editSE_M_T1.Visible = 'off';
            app.editSE_M_T1.Value = 'Edit Text';
            app.editSE_R_T1 = uieditfield(app.uiPanelSEntropy1, 'text');
            app.editSE_R_T1.Visible = 'off';
            app.editSE_R_T1.Value = 'Edit Text';
            app.editSE_TAU_T1 = uieditfield(app.uiPanelSEntropy1, 'text');
            app.editSE_TAU_T1.Visible = 'off';
            app.editSE_TAU_T1.Value = 'Edit Text';
            app.editSE_M = uieditfield(app.uiPanelSEntropy1, 'text');
            app.editSE_M.Value = 'Edit Text';
            app.editSE_R = uieditfield(app.uiPanelSEntropy1, 'text');
            app.editSE_R.Value = 'Edit Text';
            app.editSE_TAU = uieditfield(app.uiPanelSEntropy1, 'text');
            app.editSE_TAU.Value = 'Edit Text';
            app.text21 = uilabel(app.uiPanelSEntropy1);
            app.text21.Text = 'Embedding Dimension (𝒎)';
            app.text23 = uilabel(app.uiPanelSEntropy1);
            app.text23.Text = 'Time Lag';
            app.uiPanelCosEn = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelCosEn.Title = 'Coeff Sample Entropy';
            app.text1001 = uilabel(app.uiPanelCosEn);
            app.text1001.Text = 'CosR';
            app.editCosEn_cosR = uieditfield(app.uiPanelCosEn, 'text');
            app.editCosEn_cosR.Value = 'Edit Text';
            app.text114 = uilabel(app.uiPanelCosEn);
            app.text114.Text = 'Embedding Dimension (𝒎)';
            app.editCosEn_M = uieditfield(app.uiPanelCosEn, 'text');
            app.editCosEn_M.Value = 'Edit Text';
            app.text115 = uilabel(app.uiPanelCosEn);
            app.text115.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.editCosEn_TAU = uieditfield(app.uiPanelCosEn, 'text');
            app.editCosEn_TAU.Value = 'Edit Text';
            app.editCosEn_cosR_T1 = uieditfield(app.uiPanelCosEn, 'text');
            app.editCosEn_cosR_T1.Visible = 'off';
            app.editCosEn_cosR_T1.Value = 'Edit Text';
            app.editCosEn_M_T1 = uieditfield(app.uiPanelCosEn, 'text');
            app.editCosEn_M_T1.Visible = 'off';
            app.editCosEn_M_T1.Value = 'Edit Text';
            app.editCosEn_TAU_T1 = uieditfield(app.uiPanelCosEn, 'text');
            app.editCosEn_TAU_T1.Visible = 'off';
            app.editCosEn_TAU_T1.Value = 'Edit Text';
            app.uipanelFE = uipanel(app.Conditional_EntropiesTab);
            app.uipanelFE.Title = 'Fuzzy Entropy';
            app.editFE_M_T1 = uieditfield(app.uipanelFE, 'text');
            app.editFE_M_T1.Visible = 'off';
            app.editFE_M_T1.Value = '2';
            app.editFE_Tau_T1 = uieditfield(app.uipanelFE, 'text');
            app.editFE_Tau_T1.Visible = 'off';
            app.editFE_Tau_T1.Value = '1';
            app.text182 = uilabel(app.uipanelFE);
            app.text182.Text = 'Embedding Dimension (𝒎)';
            app.text184 = uilabel(app.uipanelFE);
            app.text184.Text = 'Membership (mf)';
            app.Local = uilabel(app.uipanelFE);
            app.Local.Text = 'Local/Global';
            app.editFE_M = uieditfield(app.uipanelFE, 'text');
            app.editFE_M.Value = '2';
            app.pMenuFE_MF = uidropdown(app.uipanelFE);
            app.pMenuFE_MF.Items = {'Triangular', 'Trapezoidal', 'Z_shaped', 'Gaussian', 'Constant_Gaussia'};
            app.pMenuFE_MF.ValueChangedFcn = createCallbackFcn(app, @pMenuFE_MF_Callback, true);
            app.pMenuFE_MF.Value = 'Triangular';
            app.editFE_T_T1 = uieditfield(app.uipanelFE, 'text');
            app.editFE_T_T1.Visible = 'off';
            app.editFE_T_T1.Value = '0.3';
            app.pMenuFE_L = uidropdown(app.uipanelFE);
            app.pMenuFE_L.Items = {'Global', 'Local'};
            app.pMenuFE_L.Value = 'Local';
            app.chkFE_MF = uicheckbox(app.uipanelFE);
            app.chkFE_MF.Visible = 'off';
            app.chkFE_MF.Text = 'ALL';
            app.editFE_T = uieditfield(app.uipanelFE, 'text');
            app.editFE_T.Value = '0.3';
            app.text186 = uilabel(app.uipanelFE);
            app.text186.Text = 'Threshold (𝒓)';
            app.text183 = uilabel(app.uipanelFE);
            app.text183.Text = 'Time Delay (τ)';
            app.editFE_Tau = uieditfield(app.uipanelFE, 'text');
            app.editFE_Tau.Value = '1';
            app.chkFE_L = uicheckbox(app.uipanelFE);
            app.chkFE_L.ValueChangedFcn = createCallbackFcn(app, @chkFE_L_Callback, true);
            app.chkFE_L.Visible = 'off';
            app.chkFE_L.Text = 'ALL';
            app.uiPanelApEn = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelApEn.Title = 'Approximate Entropy (ApEn)';
            app.text157 = uilabel(app.uiPanelApEn);
            app.text157.Text = 'Similarity Measure (𝒓)';
            app.editApEnM_T1 = uieditfield(app.uiPanelApEn, 'text');
            app.editApEnM_T1.Visible = 'off';
            app.editApEnM_T1.Value = 'Edit Text';
            app.editApEnR_T1 = uieditfield(app.uiPanelApEn, 'text');
            app.editApEnR_T1.Visible = 'off';
            app.editApEnR_T1.Value = 'Edit Text';
            app.editApEnM = uieditfield(app.uiPanelApEn, 'text');
            app.editApEnM.Value = 'Edit Text';
            app.editApEnR = uieditfield(app.uiPanelApEn, 'text');
            app.editApEnR.Value = 'Edit Text';
            app.text158 = uilabel(app.uiPanelApEn);
            app.text158.Text = 'Window Length (𝒍)';
            app.uiPanelConditionalEn = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelConditionalEn.Title = 'Conditional Entropy';
            app.text187 = uilabel(app.uiPanelConditionalEn);
            app.text187.Text = 'Bin number (quantisation)';
            app.editCE_M_T1 = uieditfield(app.uiPanelConditionalEn, 'text');
            app.editCE_M_T1.Visible = 'off';
            app.editCE_M_T1.Value = 'Edit Text';
            app.editCE_NumInt_T1 = uieditfield(app.uiPanelConditionalEn, 'text');
            app.editCE_NumInt_T1.Visible = 'off';
            app.editCE_NumInt_T1.Value = 'Edit Text';
            app.editCE_M = uieditfield(app.uiPanelConditionalEn, 'text');
            app.editCE_M.Value = 'Edit Text';
            app.editCE_NumInt = uieditfield(app.uiPanelConditionalEn, 'text');
            app.editCE_NumInt.Value = 'Edit Text';
            app.text188 = uilabel(app.uiPanelConditionalEn);
            app.text188.Text = 'Embedding Dimension (𝒎)';
            app.uiPanelMSE = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelMSE.Title = 'Multiscale Entropy';
            app.text202 = uilabel(app.uiPanelMSE);
            app.text202.Text = 'Similarity Radius (𝒓)';
            app.editMSE_M_T1 = uieditfield(app.uiPanelMSE, 'text');
            app.editMSE_M_T1.Visible = 'off';
            app.editMSE_M_T1.Value = 'Edit Text';
            app.editMSE_R_T1 = uieditfield(app.uiPanelMSE, 'text');
            app.editMSE_R_T1.Visible = 'off';
            app.editMSE_R_T1.Value = 'Edit Text';
            app.editMSE_Tau_T1 = uieditfield(app.uiPanelMSE, 'text');
            app.editMSE_Tau_T1.Visible = 'off';
            app.editMSE_Tau_T1.Value = 'Edit Text';
            app.editMSE_M = uieditfield(app.uiPanelMSE, 'text');
            app.editMSE_M.Value = 'Edit Text';
            app.editMSE_R = uieditfield(app.uiPanelMSE, 'text');
            app.editMSE_R.Value = 'Edit Text';
            app.editMSE_Tau = uieditfield(app.uiPanelMSE, 'text');
            app.editMSE_Tau.Value = 'Edit Text';
            app.text203 = uilabel(app.uiPanelMSE);
            app.text203.Text = 'Pattern Length (𝒎)';
            app.text204 = uilabel(app.uiPanelMSE);
            app.text204.Text = 'Max Tau (𝘛𝘮𝘢𝘹)';
            app.uiPanelCorrectedCE = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelCorrectedCE.Title = 'Corrected Conditional Entropy';
            app.text189 = uilabel(app.uiPanelCorrectedCE);
            app.text189.Text = 'Bin number (quantisation)';
            app.editCorrectedCE_M_T1 = uieditfield(app.uiPanelCorrectedCE, 'text');
            app.editCorrectedCE_M_T1.Visible = 'off';
            app.editCorrectedCE_M_T1.Value = 'Edit Text';
            app.editCorrectedCE_NumInt_T1 = uieditfield(app.uiPanelCorrectedCE, 'text');
            app.editCorrectedCE_NumInt_T1.Visible = 'off';
            app.editCorrectedCE_NumInt_T1.Value = 'Edit Text';
            app.editCorrectedCE_NumInt = uieditfield(app.uiPanelCorrectedCE, 'text');
            app.editCorrectedCE_NumInt.Value = 'Edit Text';
            app.text190 = uilabel(app.uiPanelCorrectedCE);
            app.text190.Text = 'Embedding Dimension (𝒎)';
            app.editCorrectedCE_M = uieditfield(app.uiPanelCorrectedCE, 'text');
            app.editCorrectedCE_M.Value = 'Edit Text';
            app.uiPanelSEntropy_RH = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelSEntropy_RH.Title = 'Sample Entropy (Richmond)';
            app.text22_2 = uilabel(app.uiPanelSEntropy_RH);
            app.text22_2.Text = 'Threshold (𝒓)';
            app.editSE_RH_M_T1 = uieditfield(app.uiPanelSEntropy_RH, 'text');
            app.editSE_RH_M_T1.Visible = 'off';
            app.editSE_RH_M_T1.Value = 'Edit Text';
            app.editSE_RH_R_T1 = uieditfield(app.uiPanelSEntropy_RH, 'text');
            app.editSE_RH_R_T1.Visible = 'off';
            app.editSE_RH_R_T1.Value = 'Edit Text';
            app.editSE_RH_M = uieditfield(app.uiPanelSEntropy_RH, 'text');
            app.editSE_RH_M.Value = 'Edit Text';
            app.editSE_RH_R = uieditfield(app.uiPanelSEntropy_RH, 'text');
            app.editSE_RH_R.Value = 'Edit Text';
            app.text21_2 = uilabel(app.uiPanelSEntropy_RH);
            app.text21_2.Text = 'Embedding Dimension (𝒎)';
            app.uiPanelComplexityIndex = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelComplexityIndex.Title = 'Complexity Index';
            app.text202_2 = uilabel(app.uiPanelComplexityIndex);
            app.text202_2.Text = 'Similarity Radius (𝒓)';
            app.editCI_M_T1 = uieditfield(app.uiPanelComplexityIndex, 'text');
            app.editCI_M_T1.Visible = 'off';
            app.editCI_M_T1.Value = 'Edit Text';
            app.editCI_R_T1 = uieditfield(app.uiPanelComplexityIndex, 'text');
            app.editCI_R_T1.Visible = 'off';
            app.editCI_R_T1.Value = 'Edit Text';
            app.editCI_Tau_T1 = uieditfield(app.uiPanelComplexityIndex, 'text');
            app.editCI_Tau_T1.Visible = 'off';
            app.editCI_Tau_T1.Value = 'Edit Text';
            app.editCI_M = uieditfield(app.uiPanelComplexityIndex, 'text');
            app.editCI_M.Value = 'Edit Text';
            app.editCI_R = uieditfield(app.uiPanelComplexityIndex, 'text');
            app.editCI_R.Value = 'Edit Text';
            app.editCI_Tau = uieditfield(app.uiPanelComplexityIndex, 'text');
            app.editCI_Tau.Value = 'Edit Text';
            app.text203_2 = uilabel(app.uiPanelComplexityIndex);
            app.text203_2.Text = 'Pattern Length (𝒎)';
            app.text204_2 = uilabel(app.uiPanelComplexityIndex);
            app.text204_2.Text = 'Max Tau (𝘛𝘮𝘢𝘹)';
            app.uiPanelFixedSampEn = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelFixedSampEn.Title = 'Fixed Sample Entropy (fSampEn)';
            app.text22_3 = uilabel(app.uiPanelFixedSampEn);
            app.text22_3.Text = 'Threshold (𝒓)';
            app.editFSampEn_M_T1 = uieditfield(app.uiPanelFixedSampEn, 'text');
            app.editFSampEn_M_T1.Visible = 'off';
            app.editFSampEn_M_T1.Value = 'Edit Text';
            app.editFSampEn_R_T1 = uieditfield(app.uiPanelFixedSampEn, 'text');
            app.editFSampEn_R_T1.Visible = 'off';
            app.editFSampEn_R_T1.Value = 'Edit Text';
            app.editFSampEn_M = uieditfield(app.uiPanelFixedSampEn, 'text');
            app.editFSampEn_M.Value = 'Edit Text';
            app.editFSampEn_R = uieditfield(app.uiPanelFixedSampEn, 'text');
            app.editFSampEn_R.Value = 'Edit Text';
            app.text21_3 = uilabel(app.uiPanelFixedSampEn);
            app.text21_3.Text = 'Embedding D (𝒎)';
            app.editFSampEn_fNumStep_T1 = uieditfield(app.uiPanelFixedSampEn, 'text');
            app.editFSampEn_fNumStep_T1.Visible = 'off';
            app.editFSampEn_fNumStep_T1.Value = 'Edit Text';
            app.editFSampEn_fNumStep = uieditfield(app.uiPanelFixedSampEn, 'text');
            app.editFSampEn_fNumStep.Value = 'Edit Text';
            app.text158_2 = uilabel(app.uiPanelFixedSampEn);
            app.text158_2.Text = 'Window Length (𝒍)';
            app.editFSampEn_nWindow_T1 = uieditfield(app.uiPanelFixedSampEn, 'text');
            app.editFSampEn_nWindow_T1.Visible = 'off';
            app.editFSampEn_nWindow_T1.Value = 'Edit Text';
            app.editFSampEn_nWindow = uieditfield(app.uiPanelFixedSampEn, 'text');
            app.editFSampEn_nWindow.Value = 'Edit Text';
            app.text158_3 = uilabel(app.uiPanelFixedSampEn);
            app.text158_3.Text = 'No of Window (𝒏)';
            app.uipanelFE_CAFE = uipanel(app.Conditional_EntropiesTab);
            app.uipanelFE_CAFE.Title = 'Fuzzy Entropy CAFE (Jean-Marc Girault)';
            app.editFE_CAFE_M_T1 = uieditfield(app.uipanelFE_CAFE, 'text');
            app.editFE_CAFE_M_T1.Visible = 'off';
            app.editFE_CAFE_M_T1.Value = '2';
            app.editFE_CAFE_P_T1 = uieditfield(app.uipanelFE_CAFE, 'text');
            app.editFE_CAFE_P_T1.Visible = 'off';
            app.editFE_CAFE_P_T1.Value = '1';
            app.text182_2 = uilabel(app.uipanelFE_CAFE);
            app.text182_2.Text = 'Embedding Dimension (𝒎)';
            app.text184_2 = uilabel(app.uipanelFE_CAFE);
            app.text184_2.Text = 'Centring';
            app.Local_2 = uilabel(app.uipanelFE_CAFE);
            app.Local_2.Text = 'Isometry Type';
            app.editFE_CAFE_M = uieditfield(app.uipanelFE_CAFE, 'text');
            app.editFE_CAFE_M.Value = '2';
            app.pMenuFE_CAFE_Centering = uidropdown(app.uipanelFE_CAFE);
            app.pMenuFE_CAFE_Centering.Items = {'Non-Centering -0', 'Centering -1', ''};
            app.pMenuFE_CAFE_Centering.Value = 'Non-Centering -0';
            app.editFE_CAFE_R_T1 = uieditfield(app.uipanelFE_CAFE, 'text');
            app.editFE_CAFE_R_T1.Visible = 'off';
            app.editFE_CAFE_R_T1.Value = '0.3';
            app.pMenuFE_CAFE_Type = uidropdown(app.uipanelFE_CAFE);
            app.pMenuFE_CAFE_Type.Items = {'Translation -T', 'Vertical Reflexion -R', 'Inversion -I', 'Glide Reflexion -G'};
            app.pMenuFE_CAFE_Type.Value = 'Translation -T';
            app.chkFE_CAFE_Centering = uicheckbox(app.uipanelFE_CAFE);
            app.chkFE_CAFE_Centering.Visible = 'off';
            app.chkFE_CAFE_Centering.Text = 'ALL';
            app.editFE_CAFE_R = uieditfield(app.uipanelFE_CAFE, 'text');
            app.editFE_CAFE_R.Value = '0.3';
            app.text186_2 = uilabel(app.uipanelFE_CAFE);
            app.text186_2.Text = 'Threshold (𝒓)';
            app.text183_2 = uilabel(app.uipanelFE_CAFE);
            app.text183_2.Text = 'M. Function Power (p)';
            app.editFE_CAFE_P = uieditfield(app.uipanelFE_CAFE, 'text');
            app.editFE_CAFE_P.Value = '2';
            app.chkFE_CAFE_Type = uicheckbox(app.uipanelFE_CAFE);
            app.chkFE_CAFE_Type.Visible = 'off';
            app.chkFE_CAFE_Type.Text = 'ALL';
            app.uiPanelAvgApEn_Profile = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelAvgApEn_Profile.Title = 'Average Approximate Entropy Profile';
            app.editAvgApEn_Profile_M_T1 = uieditfield(app.uiPanelAvgApEn_Profile, 'text');
            app.editAvgApEn_Profile_M_T1.Visible = 'off';
            app.editAvgApEn_Profile_M_T1.Value = 'Edit Text';
            app.text190_2 = uilabel(app.uiPanelAvgApEn_Profile);
            app.text190_2.Text = 'Embedding Dimension (𝒎)';
            app.editAvgApEn_Profile_M = uieditfield(app.uiPanelAvgApEn_Profile, 'text');
            app.editAvgApEn_Profile_M.Value = 'Edit Text';
            app.uiPanelAvgSampleEn_Profile = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelAvgSampleEn_Profile.Title = 'Average Sample Entropy Profile';
            app.editAvgSampEn_Profile_M_T1 = uieditfield(app.uiPanelAvgSampleEn_Profile, 'text');
            app.editAvgSampEn_Profile_M_T1.Visible = 'off';
            app.editAvgSampEn_Profile_M_T1.Value = 'Edit Text';
            app.text190_3 = uilabel(app.uiPanelAvgSampleEn_Profile);
            app.text190_3.Text = 'Embedding Dimension (𝒎)';
            app.editAvgSampEn_Profile_M = uieditfield(app.uiPanelAvgSampleEn_Profile, 'text');
            app.editAvgSampEn_Profile_M.Value = 'Edit Text';
            app.uiPanelApEnLw = uipanel(app.Conditional_EntropiesTab);
            app.uiPanelApEnLw.Title = 'Approximate Entropy Light Weight (ApEn_LW)';
            app.text157_2 = uilabel(app.uiPanelApEnLw);
            app.text157_2.Text = 'Similarity Measure (𝒓)';
            app.editApLWEnM_T1 = uieditfield(app.uiPanelApEnLw, 'text');
            app.editApLWEnM_T1.Visible = 'off';
            app.editApLWEnM_T1.Value = 'Edit Text';
            app.editApLWEnR_T1 = uieditfield(app.uiPanelApEnLw, 'text');
            app.editApLWEnR_T1.Visible = 'off';
            app.editApLWEnR_T1.Value = 'Edit Text';
            app.editApLWEnM = uieditfield(app.uiPanelApEnLw, 'text');
            app.editApLWEnM.Value = 'Edit Text';
            app.editApLWEnR = uieditfield(app.uiPanelApEnLw, 'text');
            app.editApLWEnR.Value = 'Edit Text';
            app.text158_4 = uilabel(app.uiPanelApEnLw);
            app.text158_4.Text = 'Window Length (𝒍)';
            app.uiPanelAncillaryMethods = uitab(app.TabGroupTestParameters);
            app.uiPanelAncillaryMethods.Title = 'AncillaryMethods';
            app.uiPanelFracFNN = uipanel(app.uiPanelAncillaryMethods);
            app.uiPanelFracFNN.Title = 'FalseNearestNeighbours_FNN';
            app.editFracFNN_MinDim = uieditfield(app.uiPanelFracFNN, 'text');
            app.editFracFNN_MinDim.Value = 'Edit Text';
            app.editFracFNN_MaxDim = uieditfield(app.uiPanelFracFNN, 'text');
            app.editFracFNN_MaxDim.Value = 'Edit Text';
            app.text220 = uilabel(app.uiPanelFracFNN);
            app.text220.Text = 'Dimension of Delay (𝑴𝒊𝒏 & 𝑴𝒂𝒙)';
            app.editFracFNN_Tau = uieditfield(app.uiPanelFracFNN, 'text');
            app.editFracFNN_Tau.Value = 'Edit Text';
            app.editFracFNN_Tau_T1 = uieditfield(app.uiPanelFracFNN, 'text');
            app.editFracFNN_Tau_T1.Visible = 'off';
            app.editFracFNN_Tau_T1.Value = 'Edit Text';
            app.text222 = uilabel(app.uiPanelFracFNN);
            app.text222.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.editFracFNN_Rt = uieditfield(app.uiPanelFracFNN, 'text');
            app.editFracFNN_Rt.Value = 'Edit Text';
            app.editFracFNN_Rt_T1 = uieditfield(app.uiPanelFracFNN, 'text');
            app.editFracFNN_Rt_T1.Visible = 'off';
            app.editFracFNN_Rt_T1.Value = 'Edit Text';
            app.text223 = uilabel(app.uiPanelFracFNN);
            app.text223.Text = 'Ratio Factor (𝒓𝒕)';
            app.text222_2 = uilabel(app.uiPanelFracFNN);
            app.text222_2.Text = 'Range';
            app.uiPanelMI = uipanel(app.uiPanelAncillaryMethods);
            app.uiPanelMI.Title = 'Auto-Mutual Information';
            app.editMI_Partitions = uieditfield(app.uiPanelMI, 'text');
            app.editMI_Partitions.Value = '2';
            app.editMI_Partitions_T1 = uieditfield(app.uiPanelMI, 'text');
            app.editMI_Partitions_T1.Visible = 'off';
            app.editMI_Partitions_T1.Value = '10';
            app.txt300 = uilabel(app.uiPanelMI);
            app.txt300.Text = 'Bin Size (𝑩)';
            app.editMI_TMax = uieditfield(app.uiPanelMI, 'text');
            app.editMI_TMax.Value = '1';
            app.text225 = uilabel(app.uiPanelMI);
            app.text225.Text = 'Max Time Delay (τmax)';
            app.editMI_TMax_T1 = uieditfield(app.uiPanelMI, 'text');
            app.editMI_TMax_T1.Visible = 'off';
            app.editMI_TMax_T1.Value = '1';
            app.uiPanelAFN = uipanel(app.uiPanelAncillaryMethods);
            app.uiPanelAFN.Title = 'AveragedFalseNeighbours_AFN';
            app.editAFN_BinSize = uieditfield(app.uiPanelAFN, 'text');
            app.editAFN_BinSize.Value = '2';
            app.editAFN_BinSize_T1 = uieditfield(app.uiPanelAFN, 'text');
            app.editAFN_BinSize_T1.Visible = 'off';
            app.editAFN_BinSize_T1.Value = '10';
            app.txt300_4 = uilabel(app.uiPanelAFN);
            app.txt300_4.Text = 'Bin Size (𝑩)';
            app.editAFN_TMax = uieditfield(app.uiPanelAFN, 'text');
            app.editAFN_TMax.Value = '1';
            app.text225_2 = uilabel(app.uiPanelAFN);
            app.text225_2.Text = 'Max Time Delay (τmax)';
            app.editAFN_TMax_T1 = uieditfield(app.uiPanelAFN, 'text');
            app.editAFN_TMax_T1.Visible = 'off';
            app.editAFN_TMax_T1.Value = '1';
            app.uiPanelCPEI = uipanel(app.uiPanelAncillaryMethods);
            app.uiPanelCPEI.Title = 'Olofsen CPEI';
            app.editCPEI_epz = uieditfield(app.uiPanelCPEI, 'text');
            app.editCPEI_epz.Value = '2';
            app.editCPEI_epz_T1 = uieditfield(app.uiPanelCPEI, 'text');
            app.editCPEI_epz_T1.Visible = 'off';
            app.editCPEI_epz_T1.Value = 'Edit Text';
            app.text216_5 = uilabel(app.uiPanelCPEI);
            app.text216_5.Text = 'threshold';
            app.chkUseIntraQuarderRange = uicheckbox(app.uiPanelCPEI);
            app.chkUseIntraQuarderRange.Text = 'Use Intra-Quatile Range (Q3-Q1) * threshold';
            app.uiPanelOtherE = uitab(app.TabGroupTestParameters);
            app.uiPanelOtherE.Title = 'Other_Entropies';
            app.uiPanelRCMDE1 = uipanel(app.uiPanelOtherE);
            app.uiPanelRCMDE1.Title = 'RCmDE';
            app.editRCMDE_Scale_T1 = uieditfield(app.uiPanelRCMDE1, 'text');
            app.editRCMDE_Scale_T1.Visible = 'off';
            app.editRCMDE_Scale_T1.Value = 'Edit Text';
            app.editRCMDE_Tau_T1 = uieditfield(app.uiPanelRCMDE1, 'text');
            app.editRCMDE_Tau_T1.Visible = 'off';
            app.editRCMDE_Tau_T1.Value = 'Edit Text';
            app.editRCMDE_NoC_T1 = uieditfield(app.uiPanelRCMDE1, 'text');
            app.editRCMDE_NoC_T1.Visible = 'off';
            app.editRCMDE_NoC_T1.Value = 'Edit Text';
            app.editRCMDE_M_T1 = uieditfield(app.uiPanelRCMDE1, 'text');
            app.editRCMDE_M_T1.Visible = 'off';
            app.editRCMDE_M_T1.Value = 'Edit Text';
            app.text89 = uilabel(app.uiPanelRCMDE1);
            app.text89.Text = 'Scale (τmax)';
            app.text35 = uilabel(app.uiPanelRCMDE1);
            app.text35.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.text34 = uilabel(app.uiPanelRCMDE1);
            app.text34.Text = 'N Classes (𝒄)';
            app.editRCMDE_Scale = uieditfield(app.uiPanelRCMDE1, 'text');
            app.editRCMDE_Scale.Value = 'Edit Text';
            app.editRCMDE_Tau = uieditfield(app.uiPanelRCMDE1, 'text');
            app.editRCMDE_Tau.Value = 'Edit Text';
            app.editRCMDE_NoC = uieditfield(app.uiPanelRCMDE1, 'text');
            app.editRCMDE_NoC.Value = 'Edit Text';
            app.editRCMDE_M = uieditfield(app.uiPanelRCMDE1, 'text');
            app.editRCMDE_M.Value = 'Edit Text';
            app.text85 = uilabel(app.uiPanelRCMDE1);
            app.text85.Text = 'Embedding Dimension (𝒎)';
            app.uiPanelRCMFESigma = uipanel(app.uiPanelOtherE);
            app.uiPanelRCMFESigma.Title = 'RCmFE_SIGMA';
            app.editRCMFEstdM_T1 = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdM_T1.Visible = 'off';
            app.editRCMFEstdM_T1.Value = 'Edit Text';
            app.editRCMFEstdR_T1 = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdR_T1.Visible = 'off';
            app.editRCMFEstdR_T1.Value = 'Edit Text';
            app.editRCMFEstdTau_T1 = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdTau_T1.Visible = 'off';
            app.editRCMFEstdTau_T1.Value = 'Edit Text';
            app.editRCMFEstdScale_T1 = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdScale_T1.Visible = 'off';
            app.editRCMFEstdScale_T1.Value = 'Edit Text';
            app.editRCMFEstdN_T1 = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdN_T1.Visible = 'off';
            app.editRCMFEstdN_T1.Value = 'Edit Text';
            app.text116 = uilabel(app.uiPanelRCMFESigma);
            app.text116.Text = 'Embedding Dimension (𝒎)';
            app.editRCMFEstdM = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdM.Value = 'Edit Text';
            app.editRCMFEstdR = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdR.Value = 'Edit Text';
            app.editRCMFEstdTau = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdTau.Value = 'Edit Text';
            app.editRCMFEstdScale = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdScale.Value = 'Edit Text';
            app.text28 = uilabel(app.uiPanelRCMFESigma);
            app.text28.Text = 'Scale (τmax)';
            app.text44 = uilabel(app.uiPanelRCMFESigma);
            app.text44.Text = 'Fuzzy Power';
            app.editRCMFEstdN = uieditfield(app.uiPanelRCMFESigma, 'text');
            app.editRCMFEstdN.Value = 'Edit Text';
            app.text86 = uilabel(app.uiPanelRCMFESigma);
            app.text86.Text = 'Threshold (𝒓) [std]';
            app.text87 = uilabel(app.uiPanelRCMFESigma);
            app.text87.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.uiPanelRCMSESigma = uipanel(app.uiPanelOtherE);
            app.uiPanelRCMSESigma.Title = 'RCmSE_SIGMA';
            app.editRCMSEstdM_T1 = uieditfield(app.uiPanelRCMSESigma, 'text');
            app.editRCMSEstdM_T1.Visible = 'off';
            app.editRCMSEstdM_T1.Value = 'Edit Text';
            app.editRCMSEstdR_T1 = uieditfield(app.uiPanelRCMSESigma, 'text');
            app.editRCMSEstdR_T1.Visible = 'off';
            app.editRCMSEstdR_T1.Value = 'Edit Text';
            app.editRCMSEstdTau_T1 = uieditfield(app.uiPanelRCMSESigma, 'text');
            app.editRCMSEstdTau_T1.Visible = 'off';
            app.editRCMSEstdTau_T1.Value = 'Edit Text';
            app.editRCMSEstdScale_T1 = uieditfield(app.uiPanelRCMSESigma, 'text');
            app.editRCMSEstdScale_T1.Visible = 'off';
            app.editRCMSEstdScale_T1.Value = 'Edit Text';
            app.text135 = uilabel(app.uiPanelRCMSESigma);
            app.text135.Text = 'Embedding Dimension (𝒎)';
            app.editRCMSEstdR = uieditfield(app.uiPanelRCMSESigma, 'text');
            app.editRCMSEstdR.Value = 'Edit Text';
            app.editRCMSEstdTau = uieditfield(app.uiPanelRCMSESigma, 'text');
            app.editRCMSEstdTau.Value = 'Edit Text';
            app.editRCMSEstdScale = uieditfield(app.uiPanelRCMSESigma, 'text');
            app.editRCMSEstdScale.Value = 'Edit Text';
            app.text136 = uilabel(app.uiPanelRCMSESigma);
            app.text136.Text = 'Scale (τmax)';
            app.text138 = uilabel(app.uiPanelRCMSESigma);
            app.text138.Text = 'Threshold (𝒓)';
            app.text139 = uilabel(app.uiPanelRCMSESigma);
            app.text139.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.editRCMSEstdM = uieditfield(app.uiPanelRCMSESigma, 'text');
            app.editRCMSEstdM.Value = 'Edit Text';
            app.uiPanelBubbleEn = uipanel(app.uiPanelOtherE);
            app.uiPanelBubbleEn.Title = 'Bubble Entropy';
            app.editBubbleEnM_T1 = uieditfield(app.uiPanelBubbleEn, 'text');
            app.editBubbleEnM_T1.Visible = 'off';
            app.editBubbleEnM_T1.Value = 'Edit Text';
            app.text145 = uilabel(app.uiPanelBubbleEn);
            app.text145.Text = 'Embedding Dimension (𝒎)';
            app.editBubbleEnM = uieditfield(app.uiPanelBubbleEn, 'text');
            app.editBubbleEnM.Value = 'Edit Text';
            app.uiPanelSlopeEn = uipanel(app.uiPanelOtherE);
            app.uiPanelSlopeEn.Title = 'Slope Entropy';
            app.editSlopeEnM_T1 = uieditfield(app.uiPanelSlopeEn, 'text');
            app.editSlopeEnM_T1.Visible = 'off';
            app.editSlopeEnM_T1.Value = 'Edit Text';
            app.editSlopeEnGamma_T1 = uieditfield(app.uiPanelSlopeEn, 'text');
            app.editSlopeEnGamma_T1.Visible = 'off';
            app.editSlopeEnGamma_T1.Value = 'Edit Text';
            app.editSlopeEnDelta_T1 = uieditfield(app.uiPanelSlopeEn, 'text');
            app.editSlopeEnDelta_T1.Visible = 'off';
            app.editSlopeEnDelta_T1.Value = 'Edit Text';
            app.text140 = uilabel(app.uiPanelSlopeEn);
            app.text140.Text = 'Embedding Dimension (𝒎)';
            app.editSlopeEnM = uieditfield(app.uiPanelSlopeEn, 'text');
            app.editSlopeEnM.Value = 'Edit Text';
            app.editSlopeEnGamma = uieditfield(app.uiPanelSlopeEn, 'text');
            app.editSlopeEnGamma.Value = 'Edit Text';
            app.editSlopeEnDelta = uieditfield(app.uiPanelSlopeEn, 'text');
            app.editSlopeEnDelta.Value = 'Edit Text';
            app.text142 = uilabel(app.uiPanelSlopeEn);
            app.text142.Text = 'gamma';
            app.text143 = uilabel(app.uiPanelSlopeEn);
            app.text143.Text = 'delta';
            app.uiPanelPhaseEntropy = uipanel(app.uiPanelOtherE);
            app.uiPanelPhaseEntropy.Title = 'Phase Entropy';
            app.editPhEnK = uieditfield(app.uiPanelPhaseEntropy, 'text');
            app.editPhEnK.Value = '2';
            app.editPhEnK_T1 = uieditfield(app.uiPanelPhaseEntropy, 'text');
            app.editPhEnK_T1.Visible = 'off';
            app.editPhEnK_T1.Value = '10';
            app.txt300_2 = uilabel(app.uiPanelPhaseEntropy);
            app.txt300_2.Text = 'Number of Sectors (𝒌)';
            app.uiPanelDistributionEn = uipanel(app.uiPanelOtherE);
            app.uiPanelDistributionEn.Title = 'Distribution Entropy';
            app.editDE_M_T1 = uieditfield(app.uiPanelDistributionEn, 'text');
            app.editDE_M_T1.Visible = 'off';
            app.editDE_M_T1.Value = 'Edit Text';
            app.editDE_Tau_T1 = uieditfield(app.uiPanelDistributionEn, 'text');
            app.editDE_Tau_T1.Visible = 'off';
            app.editDE_Tau_T1.Value = 'Edit Text';
            app.text227 = uilabel(app.uiPanelDistributionEn);
            app.text227.Text = 'Embedding Dimension (𝒎)';
            app.editDE_Tau = uieditfield(app.uiPanelDistributionEn, 'text');
            app.editDE_Tau.Value = 'Edit Text';
            app.text228 = uilabel(app.uiPanelDistributionEn);
            app.text228.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.editDE_M = uieditfield(app.uiPanelDistributionEn, 'text');
            app.editDE_M.Value = 'Edit Text';
            app.editDE_B_T1 = uieditfield(app.uiPanelDistributionEn, 'text');
            app.editDE_B_T1.Visible = 'off';
            app.editDE_B_T1.Value = 'Edit Text';
            app.editDE_B = uieditfield(app.uiPanelDistributionEn, 'text');
            app.editDE_B.Value = 'Edit Text';
            app.text231 = uilabel(app.uiPanelDistributionEn);
            app.text231.Text = 'Bin Number (𝑴)';
            app.uiPanelMScalsePhaseEntropy = uipanel(app.uiPanelOtherE);
            app.uiPanelMScalsePhaseEntropy.Title = 'Multiscale Phase Entropy';
            app.editMPhEnK = uieditfield(app.uiPanelMScalsePhaseEntropy, 'text');
            app.editMPhEnK.Value = '2';
            app.editMPhEnK_T1 = uieditfield(app.uiPanelMScalsePhaseEntropy, 'text');
            app.editMPhEnK_T1.Visible = 'off';
            app.editMPhEnK_T1.Value = '10';
            app.txt300_5 = uilabel(app.uiPanelMScalsePhaseEntropy);
            app.txt300_5.Text = 'Number of Sectors (𝒌)';
            app.editMPhEnScale = uieditfield(app.uiPanelMScalsePhaseEntropy, 'text');
            app.editMPhEnScale.Value = '2';
            app.editMPhEnScale_T1 = uieditfield(app.uiPanelMScalsePhaseEntropy, 'text');
            app.editMPhEnScale_T1.Visible = 'off';
            app.editMPhEnScale_T1.Value = '10';
            app.txt300_6 = uilabel(app.uiPanelMScalsePhaseEntropy);
            app.txt300_6.Text = 'Scale';
            app.uiPanelModifiedMSE = uipanel(app.uiPanelOtherE);
            app.uiPanelModifiedMSE.Title = 'Modified MultiScale Entropy';
            app.editMMSE_scale = uieditfield(app.uiPanelModifiedMSE, 'text');
            app.editMMSE_scale.Value = '2';
            app.editMMSE_scale_T1 = uieditfield(app.uiPanelModifiedMSE, 'text');
            app.editMMSE_scale_T1.Visible = 'off';
            app.editMMSE_scale_T1.Value = '10';
            app.txt300_7 = uilabel(app.uiPanelModifiedMSE);
            app.txt300_7.Text = 'Scale';
            app.uiPanelCompositMSE = uipanel(app.uiPanelOtherE);
            app.uiPanelCompositMSE.Title = 'Composit MultiScale Entropy';
            app.editCMSE_scale = uieditfield(app.uiPanelCompositMSE, 'text');
            app.editCMSE_scale.Value = '2';
            app.editCMSE_scale_T1 = uieditfield(app.uiPanelCompositMSE, 'text');
            app.editCMSE_scale_T1.Visible = 'off';
            app.editCMSE_scale_T1.Value = '10';
            app.txt300_8 = uilabel(app.uiPanelCompositMSE);
            app.txt300_8.Text = 'Scale';
            app.uiPanelDimensionsAndE = uitab(app.TabGroupTestParameters);
            app.uiPanelDimensionsAndE.Title = 'Dimensions_And_Exponents';
            app.uiPanelHFD = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelHFD.Title = 'Higuchi FD';
            app.editHiguchiFD_KMax_T1 = uieditfield(app.uiPanelHFD, 'text');
            app.editHiguchiFD_KMax_T1.Visible = 'off';
            app.editHiguchiFD_KMax_T1.Value = '6';
            app.editHiguchiFD_KMax = uieditfield(app.uiPanelHFD, 'text');
            app.editHiguchiFD_KMax.Value = '6';
            app.text46 = uilabel(app.uiPanelHFD);
            app.text46.Text = 'Max No. of sub series';
            app.uiPanelCorrDim = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelCorrDim.Title = 'CorrDim_D2';
            app.editCorrDimLag = uieditfield(app.uiPanelCorrDim, 'text');
            app.editCorrDimLag.Value = '1';
            app.editCorrDimLag_T1 = uieditfield(app.uiPanelCorrDim, 'text');
            app.editCorrDimLag_T1.Visible = 'off';
            app.editCorrDimLag_T1.Value = '1';
            app.text217 = uilabel(app.uiPanelCorrDim);
            app.text217.Text = 'Embedding Dimension (𝒎)';
            app.editCorrDimM_T1 = uieditfield(app.uiPanelCorrDim, 'text');
            app.editCorrDimM_T1.Visible = 'off';
            app.editCorrDimM_T1.Value = '2';
            app.text218 = uilabel(app.uiPanelCorrDim);
            app.text218.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.editCorrDimM = uieditfield(app.uiPanelCorrDim, 'text');
            app.editCorrDimM.Value = '2';
            app.uiPanelEPP = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelEPP.Title = 'Extended Poincare_EPP';
            app.editEPPLag = uieditfield(app.uiPanelEPP, 'text');
            app.editEPPLag.Value = '1';
            app.text200 = uilabel(app.uiPanelEPP);
            app.text200.Text = 'Max Steps/Lags (𝒌)';
            app.textEPPOutput = uilabel(app.uiPanelEPP);
            app.textEPPOutput.Visible = 'off';
            app.textEPPOutput.Text = 'Select Output Parameter';
            app.pMenuEEPOutput = uidropdown(app.uiPanelEPP);
            app.pMenuEEPOutput.Items = {'R', 'P', 'SD1', 'SD2'};
            app.pMenuEEPOutput.Visible = 'off';
            app.pMenuEEPOutput.Value = 'R';
            app.editEPPLag_T1 = uieditfield(app.uiPanelEPP, 'text');
            app.editEPPLag_T1.Value = '1';
            app.uiPanelM_LZC = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelM_LZC.Title = 'Multiscale LZC';
            app.text207 = uilabel(app.uiPanelM_LZC);
            app.text207.Text = 'Scal ';
            app.text208 = uilabel(app.uiPanelM_LZC);
            app.text208.Text = '(Please enter scale (odd) numbers seperated by comma/s)';
            app.editMLZCScal = uieditfield(app.uiPanelM_LZC, 'text');
            app.editMLZCScal.Value = '1,3,5';
            app.uiPanelRQA = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelRQA.Title = 'RQA Statistics';
            app.editRQA_M = uieditfield(app.uiPanelRQA, 'text');
            app.editRQA_M.ValueChangedFcn = createCallbackFcn(app, @editRQA_M_Callback, true);
            app.editRQA_M.Value = '10';
            app.text209 = uilabel(app.uiPanelRQA);
            app.text209.Text = 'Embedding Dimension (𝒎)';
            app.editRQA_M_T1 = uieditfield(app.uiPanelRQA, 'text');
            app.editRQA_M_T1.Visible = 'off';
            app.editRQA_M_T1.Value = '10';
            app.editRQA_Tau = uieditfield(app.uiPanelRQA, 'text');
            app.editRQA_Tau.Value = '1';
            app.editRQA_Tau_T1 = uieditfield(app.uiPanelRQA, 'text');
            app.editRQA_Tau_T1.Visible = 'off';
            app.editRQA_Tau_T1.Value = '1';
            app.editRQA_R = uieditfield(app.uiPanelRQA, 'text');
            app.editRQA_R.Value = '0.01';
            app.text212 = uilabel(app.uiPanelRQA);
            app.text212.Text = 'Threshold (𝒓)';
            app.editRQA_R_T1 = uieditfield(app.uiPanelRQA, 'text');
            app.editRQA_R_T1.Visible = 'off';
            app.editRQA_R_T1.Value = '0.01';
            app.editRQA_Minline = uieditfield(app.uiPanelRQA, 'text');
            app.editRQA_Minline.Value = '2';
            app.text213 = uilabel(app.uiPanelRQA);
            app.text213.Text = 'Minline';
            app.editRQA_Minline_T1 = uieditfield(app.uiPanelRQA, 'text');
            app.editRQA_Minline_T1.Visible = 'off';
            app.editRQA_Minline_T1.Value = '2';
            app.text214 = uilabel(app.uiPanelRQA);
            app.text214.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.pMenuRQAOutput = uidropdown(app.uiPanelRQA);
            app.pMenuRQAOutput.Items = {' REC (%)', 'DET (%)', 'Lmax', 'ENT', 'LAM', 'TT'};
            app.pMenuRQAOutput.Value = ' REC (%)';
            app.textRQAOutput = uilabel(app.uiPanelRQA);
            app.textRQAOutput.Text = 'Select View Parameter';
            app.uiPanelDFA = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelDFA.Title = 'DFA';
            app.editDFAOrder = uieditfield(app.uiPanelDFA, 'text');
            app.editDFAOrder.Value = '5';
            app.editDFAOrder_T1 = uieditfield(app.uiPanelDFA, 'text');
            app.editDFAOrder_T1.Visible = 'off';
            app.editDFAOrder_T1.Value = '5';
            app.text168 = uilabel(app.uiPanelDFA);
            app.text168.Text = 'Embedding Dimension (𝒎)';
            app.editDFAEmbededDim_T1 = uieditfield(app.uiPanelDFA, 'text');
            app.editDFAEmbededDim_T1.Visible = 'off';
            app.editDFAEmbededDim_T1.Value = '2';
            app.text169 = uilabel(app.uiPanelDFA);
            app.text169.Text = 'Order';
            app.editDFAEmbededDim = uieditfield(app.uiPanelDFA, 'text');
            app.editDFAEmbededDim.Value = '2';
            app.text169_2 = uilabel(app.uiPanelDFA);
            app.text169_2.Text = '(mimimum dimensions  is 3)';
            app.uiPanelLLE = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelLLE.Title = 'LargestLyapunov Exponent_LLE';
            app.editLLE_M = uieditfield(app.uiPanelLLE, 'text');
            app.editLLE_M.Value = '10';
            app.text209_2 = uilabel(app.uiPanelLLE);
            app.text209_2.Text = 'Embedding Dimension (𝒎)';
            app.editLLE_M_T1 = uieditfield(app.uiPanelLLE, 'text');
            app.editLLE_M_T1.Visible = 'off';
            app.editLLE_M_T1.Value = '10';
            app.editLLE_Tau = uieditfield(app.uiPanelLLE, 'text');
            app.editLLE_Tau.Value = '10';
            app.editLLE_Tau_T1 = uieditfield(app.uiPanelLLE, 'text');
            app.editLLE_Tau_T1.Visible = 'off';
            app.editLLE_Tau_T1.Value = '10';
            app.editLLE_MeanPeriod_T1 = uieditfield(app.uiPanelLLE, 'text');
            app.editLLE_MeanPeriod_T1.Visible = 'off';
            app.editLLE_MeanPeriod_T1.Value = 'Edit Text2';
            app.editLLE_MeanPeriod = uieditfield(app.uiPanelLLE, 'text');
            app.editLLE_MeanPeriod.Value = 'Edit Text2';
            app.text213_2 = uilabel(app.uiPanelLLE);
            app.text213_2.Text = 'Mean Period (𝑴𝑷)';
            app.editLLE_Maxiter_T1 = uieditfield(app.uiPanelLLE, 'text');
            app.editLLE_Maxiter_T1.Visible = 'off';
            app.editLLE_Maxiter_T1.Value = '50';
            app.text214_2 = uilabel(app.uiPanelLLE);
            app.text214_2.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.editLLE_Maxiter = uieditfield(app.uiPanelLLE, 'text');
            app.editLLE_Maxiter.Value = '50';
            app.text213_3 = uilabel(app.uiPanelLLE);
            app.text213_3.Text = 'Max n Iterations';
            app.uiPanelCCM = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelCCM.Title = 'Complex Correlation Measure_CCM';
            app.text112_2 = uilabel(app.uiPanelCCM);
            app.text112_2.Text = 'lag (𝒌)';
            app.editCCM_Lag_T1 = uieditfield(app.uiPanelCCM, 'text');
            app.editCCM_Lag_T1.Visible = 'off';
            app.editCCM_Lag_T1.Value = '1';
            app.editCCM_Lag = uieditfield(app.uiPanelCCM, 'text');
            app.editCCM_Lag.Value = '1';
            app.uiPanelAF = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelAF.Title = 'Alan Factor AF';
            app.editAlanFactor_nWindow_T1 = uieditfield(app.uiPanelAF, 'text');
            app.editAlanFactor_nWindow_T1.Visible = 'off';
            app.editAlanFactor_nWindow_T1.Value = '10';
            app.editAlanFactor_nWindow = uieditfield(app.uiPanelAF, 'text');
            app.editAlanFactor_nWindow.Value = '10';
            app.text46_2 = uilabel(app.uiPanelAF);
            app.text46_2.Text = 'number of Window';
            app.uiPanelMultiS_FD = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelMultiS_FD.Title = 'Multiscale Fractal Dimension';
            app.editMultiScaleFD_scale = uieditfield(app.uiPanelMultiS_FD, 'text');
            app.editMultiScaleFD_scale.Value = '5';
            app.editMultiScaleFD_scale_T1 = uieditfield(app.uiPanelMultiS_FD, 'text');
            app.editMultiScaleFD_scale_T1.Visible = 'off';
            app.editMultiScaleFD_scale_T1.Value = '5';
            app.text217_2 = uilabel(app.uiPanelMultiS_FD);
            app.text217_2.Text = 'number of Window';
            app.editMultiScaleFD_M_T1 = uieditfield(app.uiPanelMultiS_FD, 'text');
            app.editMultiScaleFD_M_T1.Visible = 'off';
            app.editMultiScaleFD_M_T1.Value = '5';
            app.text218_2 = uilabel(app.uiPanelMultiS_FD);
            app.text218_2.Text = 'maximum Scale';
            app.editMultiScaleFD_M = uieditfield(app.uiPanelMultiS_FD, 'text');
            app.editMultiScaleFD_M.Value = '5';
            app.text217_3 = uilabel(app.uiPanelMultiS_FD);
            app.text217_3.Text = 'number of Window <= maximum Scale';
            app.uiPanelHRM_Measures = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelHRM_Measures.Title = 'HRM Measures';
            app.editHRM_Tau_T1 = uieditfield(app.uiPanelHRM_Measures, 'text');
            app.editHRM_Tau_T1.Visible = 'off';
            app.editHRM_Tau_T1.Value = '5';
            app.editHRM_Tau = uieditfield(app.uiPanelHRM_Measures, 'text');
            app.editHRM_Tau.Value = '5';
            app.text214_6 = uilabel(app.uiPanelHRM_Measures);
            app.text214_6.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.pMenuHRM = uidropdown(app.uiPanelHRM_Measures);
            app.pMenuHRM.Items = {'ALL Methods', 'PI', 'GI', 'SI', 'AI'};
            app.pMenuHRM.Visible = 'off';
            app.pMenuHRM.Value = 'ALL Methods';
            app.uiPanelFDNldwan = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelFDNldwan.Title = 'FD Nldwan';
            app.editFDnldwanWS = uieditfield(app.uiPanelFDNldwan, 'text');
            app.editFDnldwanWS.Value = '3';
            app.editFDnldwanWS_T1 = uieditfield(app.uiPanelFDNldwan, 'text');
            app.editFDnldwanWS_T1.Visible = 'off';
            app.editFDnldwanWS_T1.Value = '5';
            app.text217_4 = uilabel(app.uiPanelFDNldwan);
            app.text217_4.Text = 'Moving Window Length';
            app.editFDnldwanWL_T1 = uieditfield(app.uiPanelFDNldwan, 'text');
            app.editFDnldwanWL_T1.Visible = 'off';
            app.editFDnldwanWL_T1.Value = '5';
            app.text218_3 = uilabel(app.uiPanelFDNldwan);
            app.text218_3.Text = 'Moving Window Step';
            app.editFDnldwanWL = uieditfield(app.uiPanelFDNldwan, 'text');
            app.editFDnldwanWL.Value = '5';
            app.text218_15 = uilabel(app.uiPanelFDNldwan);
            app.text218_15.Text = 'Choose Method';
            app.pMenuFDNldwan = uidropdown(app.uiPanelFDNldwan);
            app.pMenuFDNldwan.Items = {'mean FDim', 'standard FDim', 'mean FImp', 'standard FImp'};
            app.pMenuFDNldwan.Value = 'mean FDim';
            app.uiPanelFDNldian = uipanel(app.uiPanelDimensionsAndE);
            app.uiPanelFDNldian.Title = 'FD Nldian';
            app.editFDnldianWS = uieditfield(app.uiPanelFDNldian, 'text');
            app.editFDnldianWS.Value = '3';
            app.editFDnldianWS_T1 = uieditfield(app.uiPanelFDNldian, 'text');
            app.editFDnldianWS_T1.Visible = 'off';
            app.editFDnldianWS_T1.Value = '5';
            app.text217_5 = uilabel(app.uiPanelFDNldian);
            app.text217_5.Text = 'Moving Window Length';
            app.editFDnldianWL_T1 = uieditfield(app.uiPanelFDNldian, 'text');
            app.editFDnldianWL_T1.Visible = 'off';
            app.editFDnldianWL_T1.Value = '5';
            app.text218_4 = uilabel(app.uiPanelFDNldian);
            app.text218_4.Text = 'Moving Window Step';
            app.editFDnldianWL = uieditfield(app.uiPanelFDNldian, 'text');
            app.editFDnldianWL.Value = '5';
            app.text218_14 = uilabel(app.uiPanelFDNldian);
            app.text218_14.Text = 'Choose Method';
            app.pMenuFDNldian = uidropdown(app.uiPanelFDNldian);
            app.pMenuFDNldian.Items = {'mean FDim', 'standard FDim', 'mean FImp', 'standard FImp'};
            app.pMenuFDNldian.Value = 'mean FDim';
            app.uiPanelOrdinalE = uitab(app.TabGroupTestParameters);
            app.uiPanelOrdinalE.Title = 'Ordinal_Entropies';
            app.uiPanelImprovedPE = uipanel(app.uiPanelOrdinalE);
            app.uiPanelImprovedPE.Title = 'Improved PE';
            app.editImPEM = uieditfield(app.uiPanelImprovedPE, 'text');
            app.editImPEM.Value = '2';
            app.editImPET_T1 = uieditfield(app.uiPanelImprovedPE, 'text');
            app.editImPET_T1.Visible = 'off';
            app.editImPET_T1.Value = '1';
            app.editImPEM_T1 = uieditfield(app.uiPanelImprovedPE, 'text');
            app.editImPEM_T1.Visible = 'off';
            app.editImPEM_T1.Value = '2';
            app.text78 = uilabel(app.uiPanelImprovedPE);
            app.text78.Text = 'Order';
            app.text180_3 = uilabel(app.uiPanelImprovedPE);
            app.text180_3.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.editImPET = uieditfield(app.uiPanelImprovedPE, 'text');
            app.editImPET.Value = '1';
            app.editImPEScale = uieditfield(app.uiPanelImprovedPE, 'text');
            app.editImPEScale.Value = '2';
            app.editImPEScale_T1 = uieditfield(app.uiPanelImprovedPE, 'text');
            app.editImPEScale_T1.Visible = 'off';
            app.editImPEScale_T1.Value = '2';
            app.text80 = uilabel(app.uiPanelImprovedPE);
            app.text80.Text = 'Scale';
            app.uiPanelPe = uipanel(app.uiPanelOrdinalE);
            app.uiPanelPe.Title = 'Multiscale Permutation Entropy (mPE)';
            app.text180 = uilabel(app.uiPanelPe);
            app.text180.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.editPET = uieditfield(app.uiPanelPe, 'text');
            app.editPET.Value = '1';
            app.editPEM = uieditfield(app.uiPanelPe, 'text');
            app.editPEM.Value = '3';
            app.editPET_T1 = uieditfield(app.uiPanelPe, 'text');
            app.editPET_T1.Visible = 'off';
            app.editPET_T1.Value = '1';
            app.editPEM_T1 = uieditfield(app.uiPanelPe, 'text');
            app.editPEM_T1.Visible = 'off';
            app.editPEM_T1.Value = '3';
            app.text181 = uilabel(app.uiPanelPe);
            app.text181.Text = 'Embedding Dimension (𝒎)';
            app.editPEScale_T1 = uieditfield(app.uiPanelPe, 'text');
            app.editPEScale_T1.Visible = 'off';
            app.editPEScale_T1.Value = '2';
            app.text170_9 = uilabel(app.uiPanelPe);
            app.text170_9.Text = 'Scale';
            app.editPEScale = uieditfield(app.uiPanelPe, 'text');
            app.editPEScale.Value = '2';
            app.uiPanelMPme1 = uipanel(app.uiPanelOrdinalE);
            app.uiPanelMPme1.Title = 'Permutation Min Entropy';
            app.text94 = uilabel(app.uiPanelMPme1);
            app.text94.Text = 'Embedding Dimension (𝒎)';
            app.editMPeM_T1 = uieditfield(app.uiPanelMPme1, 'text');
            app.editMPeM_T1.Visible = 'off';
            app.editMPeM_T1.Value = 'Edit Text';
            app.editMPeM = uieditfield(app.uiPanelMPme1, 'text');
            app.editMPeM.Value = 'Edit Text';
            app.editMPeT_T1 = uieditfield(app.uiPanelMPme1, 'text');
            app.editMPeT_T1.Visible = 'off';
            app.editMPeT_T1.Value = 'Edit Text';
            app.editMPeT = uieditfield(app.uiPanelMPme1, 'text');
            app.editMPeT.Value = 'Edit Text';
            app.text170_2 = uilabel(app.uiPanelMPme1);
            app.text170_2.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.uiPanelAAPE = uipanel(app.uiPanelOrdinalE);
            app.uiPanelAAPE.Title = 'Amplitude Aware PE';
            app.text180_4 = uilabel(app.uiPanelAAPE);
            app.text180_4.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.editAAPET = uieditfield(app.uiPanelAAPE, 'text');
            app.editAAPET.Value = '1';
            app.editAAPEM = uieditfield(app.uiPanelAAPE, 'text');
            app.editAAPEM.Value = '6';
            app.editAAPET_T1 = uieditfield(app.uiPanelAAPE, 'text');
            app.editAAPET_T1.Visible = 'off';
            app.editAAPET_T1.Value = '1';
            app.editAAPEM_T1 = uieditfield(app.uiPanelAAPE, 'text');
            app.editAAPEM_T1.Visible = 'off';
            app.editAAPEM_T1.Value = '6';
            app.text181_3 = uilabel(app.uiPanelAAPE);
            app.text181_3.Text = 'Order';
            app.text180_5 = uilabel(app.uiPanelAAPE);
            app.text180_5.Text = 'Adjusting Coeff. (𝑨)';
            app.editAAPEA = uieditfield(app.uiPanelAAPE, 'text');
            app.editAAPEA.Value = '0.5';
            app.editAAPEA_T1 = uieditfield(app.uiPanelAAPE, 'text');
            app.editAAPEA_T1.Visible = 'off';
            app.editAAPEA_T1.Value = '0.5';
            app.uiPanelPermutationTE = uipanel(app.uiPanelOrdinalE);
            app.uiPanelPermutationTE.Title = 'Tsallis  Permutation Entropy (TPE)';
            app.text180_8 = uilabel(app.uiPanelPermutationTE);
            app.text180_8.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.editPTE_Tau = uieditfield(app.uiPanelPermutationTE, 'text');
            app.editPTE_Tau.Value = 'Edit Text';
            app.editPTE_M = uieditfield(app.uiPanelPermutationTE, 'text');
            app.editPTE_M.Value = 'Edit Text';
            app.editPTE_Tau_T1 = uieditfield(app.uiPanelPermutationTE, 'text');
            app.editPTE_Tau_T1.Visible = 'off';
            app.editPTE_Tau_T1.Value = 'Edit Text';
            app.editPTE_M_T1 = uieditfield(app.uiPanelPermutationTE, 'text');
            app.editPTE_M_T1.Visible = 'off';
            app.editPTE_M_T1.Value = 'Edit Text';
            app.text181_4 = uilabel(app.uiPanelPermutationTE);
            app.text181_4.Text = 'Embedding Dimension (𝒎)';
            app.editPTE_TO_T1 = uieditfield(app.uiPanelPermutationTE, 'text');
            app.editPTE_TO_T1.Visible = 'off';
            app.editPTE_TO_T1.Value = 'Edit Text';
            app.text170_10 = uilabel(app.uiPanelPermutationTE);
            app.text170_10.Text = 'Tsallis order';
            app.editPTE_TO = uieditfield(app.uiPanelPermutationTE, 'text');
            app.editPTE_TO.Value = 'Edit Text';
            app.uiPanelRenyiPE = uipanel(app.uiPanelOrdinalE);
            app.uiPanelRenyiPE.Title = 'Renyi Permutation Entropy (RPE)';
            app.text180_9 = uilabel(app.uiPanelRenyiPE);
            app.text180_9.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.editRPE_Tau = uieditfield(app.uiPanelRenyiPE, 'text');
            app.editRPE_Tau.Value = 'Edit Text';
            app.editRPE_M = uieditfield(app.uiPanelRenyiPE, 'text');
            app.editRPE_M.Value = 'Edit Text';
            app.editRPE_Tau_T1 = uieditfield(app.uiPanelRenyiPE, 'text');
            app.editRPE_Tau_T1.Visible = 'off';
            app.editRPE_Tau_T1.Value = 'Edit Text';
            app.editRPE_M_T1 = uieditfield(app.uiPanelRenyiPE, 'text');
            app.editRPE_M_T1.Visible = 'off';
            app.editRPE_M_T1.Value = 'Edit Text';
            app.text181_5 = uilabel(app.uiPanelRenyiPE);
            app.text181_5.Text = 'Embedding Dimension (𝒎)';
            app.editRPE_RO_T1 = uieditfield(app.uiPanelRenyiPE, 'text');
            app.editRPE_RO_T1.Visible = 'off';
            app.editRPE_RO_T1.Value = 'Edit Text';
            app.text170_11 = uilabel(app.uiPanelRenyiPE);
            app.text170_11.Text = 'Renyi order';
            app.editRPE_RO = uieditfield(app.uiPanelRenyiPE, 'text');
            app.editRPE_RO.Value = 'Edit Text';
            app.uiPaneledgeEntropy = uipanel(app.uiPanelOrdinalE);
            app.uiPaneledgeEntropy.Title = 'Edge Permutation Entropy (edgePE)';
            app.editEdgePE_M = uieditfield(app.uiPaneledgeEntropy, 'text');
            app.editEdgePE_M.Value = 'Edit Text';
            app.editEdgePE_T_T1 = uieditfield(app.uiPaneledgeEntropy, 'text');
            app.editEdgePE_T_T1.Visible = 'off';
            app.editEdgePE_T_T1.Value = 'Edit Text';
            app.editEdgePE_M_T1 = uieditfield(app.uiPaneledgeEntropy, 'text');
            app.editEdgePE_M_T1.Visible = 'off';
            app.editEdgePE_M_T1.Value = 'Edit Text';
            app.text78_2 = uilabel(app.uiPaneledgeEntropy);
            app.text78_2.Text = 'Order';
            app.text180_10 = uilabel(app.uiPaneledgeEntropy);
            app.text180_10.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.editEdgePE_T = uieditfield(app.uiPaneledgeEntropy, 'text');
            app.editEdgePE_T.Value = 'Edit Text';
            app.editEdgePE_R = uieditfield(app.uiPaneledgeEntropy, 'text');
            app.editEdgePE_R.Value = 'Edit Text';
            app.editEdgePE_R_T1 = uieditfield(app.uiPaneledgeEntropy, 'text');
            app.editEdgePE_R_T1.Visible = 'off';
            app.editEdgePE_R_T1.Value = 'Edit Text';
            app.text80_2 = uilabel(app.uiPaneledgeEntropy);
            app.text80_2.Text = 'Sensitivity(r)';

            app.uiPanelShannnonGEntropies = uitab(app.TabGroupTestParameters);
            app.uiPanelShannnonGEntropies.Title = 'Shannon_and_Generalised_Entropies';
            app.uiPanelShannonEn = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelShannonEn.Title = 'Shannon Entropy';
            app.text215 = uilabel(app.uiPanelShannonEn);
            app.text215.Text = 'Bin number (quantisation)';
            app.editShEn_numInt = uieditfield(app.uiPanelShannonEn, 'text');
            app.editShEn_numInt.Value = '3';
            app.editShEn_M = uieditfield(app.uiPanelShannonEn, 'text');
            app.editShEn_M.Value = '6';
            app.editShEn_numInt_T1 = uieditfield(app.uiPanelShannonEn, 'text');
            app.editShEn_numInt_T1.Visible = 'off';
            app.editShEn_numInt_T1.Value = '3';
            app.editShEn_M_T1 = uieditfield(app.uiPanelShannonEn, 'text');
            app.editShEn_M_T1.Visible = 'off';
            app.editShEn_M_T1.Value = '6';
            app.text216 = uilabel(app.uiPanelShannonEn);
            app.text216.Text = 'Embedding Dimension (m)';
            app.uiPanelEntropyMoChan = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelEntropyMoChan.Title = 'Entropy_MoChan';
            app.text180_2 = uilabel(app.uiPanelEntropyMoChan);
            app.text180_2.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.editMoChan_T = uieditfield(app.uiPanelEntropyMoChan, 'text');
            app.editMoChan_T.Value = 'Edit Text';
            app.editMoChan_M = uieditfield(app.uiPanelEntropyMoChan, 'text');
            app.editMoChan_M.Value = 'Edit Text';
            app.editMoChan_T_T1 = uieditfield(app.uiPanelEntropyMoChan, 'text');
            app.editMoChan_T_T1.Visible = 'off';
            app.editMoChan_T_T1.Value = 'Edit Text';
            app.editMoChan_M_T1 = uieditfield(app.uiPanelEntropyMoChan, 'text');
            app.editMoChan_M_T1.Visible = 'off';
            app.editMoChan_M_T1.Value = 'Edit Text';
            app.text181_2 = uilabel(app.uiPanelEntropyMoChan);
            app.text181_2.Text = 'Embedding D (𝒎)';
            app.uiPanelAverageEntropy = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelAverageEntropy.Title = 'Average Entropy(AE)';
            app.text94_2 = uilabel(app.uiPanelAverageEntropy);
            app.text94_2.Text = 'Bin Size';
            app.editAE_Min_T1 = uieditfield(app.uiPanelAverageEntropy, 'text');
            app.editAE_Min_T1.Visible = 'off';
            app.editAE_Min_T1.Value = '0.3';
            app.editAE_BinSize_T1 = uieditfield(app.uiPanelAverageEntropy, 'text');
            app.editAE_BinSize_T1.Visible = 'off';
            app.editAE_BinSize_T1.Value = '55';
            app.editAE_BinSize = uieditfield(app.uiPanelAverageEntropy, 'text');
            app.editAE_BinSize.Value = '55';
            app.editAE_Scale_T1 = uieditfield(app.uiPanelAverageEntropy, 'text');
            app.editAE_Scale_T1.Visible = 'off';
            app.editAE_Scale_T1.Value = '2';
            app.text170_3 = uilabel(app.uiPanelAverageEntropy);
            app.text170_3.Text = 'Scale';
            app.editAE_Scale = uieditfield(app.uiPanelAverageEntropy, 'text');
            app.editAE_Scale.Value = '2';
            app.editAE_Min = uieditfield(app.uiPanelAverageEntropy, 'text');
            app.editAE_Min.Value = '0.3';
            app.text170_4 = uilabel(app.uiPanelAverageEntropy);
            app.text170_4.Text = 'Min';
            app.editAE_Max_T1 = uieditfield(app.uiPanelAverageEntropy, 'text');
            app.editAE_Max_T1.Visible = 'off';
            app.editAE_Max_T1.Value = '1.6';
            app.editAE_Max = uieditfield(app.uiPanelAverageEntropy, 'text');
            app.editAE_Max.Value = '1.6';
            app.text170_5 = uilabel(app.uiPanelAverageEntropy);
            app.text170_5.Text = 'Max';
            app.uiPanelEntropyOfEntropy = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelEntropyOfEntropy.Title = 'Entropy Of Entropy (EoE)';
            app.text94_3 = uilabel(app.uiPanelEntropyOfEntropy);
            app.text94_3.Text = 'Bin Size';
            app.editEE_Min_T1 = uieditfield(app.uiPanelEntropyOfEntropy, 'text');
            app.editEE_Min_T1.Visible = 'off';
            app.editEE_Min_T1.Value = '0.3';
            app.editEE_BinSize_T1 = uieditfield(app.uiPanelEntropyOfEntropy, 'text');
            app.editEE_BinSize_T1.Visible = 'off';
            app.editEE_BinSize_T1.Value = '55';
            app.editEE_BinSize = uieditfield(app.uiPanelEntropyOfEntropy, 'text');
            app.editEE_BinSize.Value = '55';
            app.editEE_Scale_T1 = uieditfield(app.uiPanelEntropyOfEntropy, 'text');
            app.editEE_Scale_T1.Visible = 'off';
            app.editEE_Scale_T1.Value = '2';
            app.text170_6 = uilabel(app.uiPanelEntropyOfEntropy);
            app.text170_6.Text = 'Scale';
            app.editEE_Scale = uieditfield(app.uiPanelEntropyOfEntropy, 'text');
            app.editEE_Scale.Value = '2';
            app.editEE_Min = uieditfield(app.uiPanelEntropyOfEntropy, 'text');
            app.editEE_Min.Value = '0.3';
            app.text170_7 = uilabel(app.uiPanelEntropyOfEntropy);
            app.text170_7.Text = 'Min';
            app.editEE_Max_T1 = uieditfield(app.uiPanelEntropyOfEntropy, 'text');
            app.editEE_Max_T1.Visible = 'off';
            app.editEE_Max_T1.Value = '1.6';
            app.editEE_Max = uieditfield(app.uiPanelEntropyOfEntropy, 'text');
            app.editEE_Max.Value = '1.6';
            app.text170_8 = uilabel(app.uiPanelEntropyOfEntropy);
            app.text170_8.Text = 'Max';
            app.uiPanelREntropy = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelREntropy.Title = 'Renyi Entropy';
            app.text112 = uilabel(app.uiPanelREntropy);
            app.text112.Text = 'Entropic Index (𝒒)';
            app.editREntropyQ_T1 = uieditfield(app.uiPanelREntropy, 'text');
            app.editREntropyQ_T1.Visible = 'off';
            app.editREntropyQ_T1.Value = '1';
            app.editREntropyQ = uieditfield(app.uiPanelREntropy, 'text');
            app.editREntropyQ.Value = '1';
            app.editREntropyT_T1 = uieditfield(app.uiPanelREntropy, 'text');
            app.editREntropyT_T1.Visible = 'off';
            app.editREntropyT_T1.Value = 'Edit Text';
            app.uiPanelTEntropy = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelTEntropy.Title = 'Tsallis Entropy';
            app.editTEntropyQ_T1 = uieditfield(app.uiPanelTEntropy, 'text');
            app.editTEntropyQ_T1.Visible = 'off';
            app.editTEntropyQ_T1.Value = '1';
            app.editTEntropyQ = uieditfield(app.uiPanelTEntropy, 'text');
            app.editTEntropyQ.Value = '1';
            app.editTentropyQ = uilabel(app.uiPanelTEntropy);
            app.editTentropyQ.Text = 'Entropic Index (𝒒)';
            app.uiPanelEoD = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelEoD.Title = 'Entropy of Difference (EoD)';
            app.text215_2 = uilabel(app.uiPanelEoD);
            app.text215_2.Text = 'Shift (𝒔)';
            app.editEoD_Shift = uieditfield(app.uiPanelEoD, 'text');
            app.editEoD_Shift.Value = '1';
            app.editEoD_M = uieditfield(app.uiPanelEoD, 'text');
            app.editEoD_M.Value = '4';
            app.editEoD_Shift_T1 = uieditfield(app.uiPanelEoD, 'text');
            app.editEoD_Shift_T1.Visible = 'off';
            app.editEoD_Shift_T1.Value = '1';
            app.editEoD_M_T1 = uieditfield(app.uiPanelEoD, 'text');
            app.editEoD_M_T1.Visible = 'off';
            app.editEoD_M_T1.Value = '4';
            app.text216_2 = uilabel(app.uiPanelEoD);
            app.text216_2.Text = 'Embedding D (𝒎)';
            app.uiPanelKLD = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelKLD.Title = 'KullbachLeibler Divergence (KLD)';
            app.text215_3 = uilabel(app.uiPanelKLD);
            app.text215_3.Text = 'Shift (𝒔)';
            app.editKLD_Shift = uieditfield(app.uiPanelKLD, 'text');
            app.editKLD_Shift.Value = '1';
            app.editKLD_M = uieditfield(app.uiPanelKLD, 'text');
            app.editKLD_M.Value = '4';
            app.editKLD_Shift_T1 = uieditfield(app.uiPanelKLD, 'text');
            app.editKLD_Shift_T1.Visible = 'off';
            app.editKLD_Shift_T1.Value = '1';
            app.editKLD_M_T1 = uieditfield(app.uiPanelKLD, 'text');
            app.editKLD_M_T1.Visible = 'off';
            app.editKLD_M_T1.Value = '4';
            app.text216_3 = uilabel(app.uiPanelKLD);
            app.text216_3.Text = 'Embedding D (𝒎)';
            app.uiPanelToneEntropy = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelToneEntropy.Title = 'Tone Entropy';
            app.editToneE_M_T1 = uieditfield(app.uiPanelToneEntropy, 'text');
            app.editToneE_M_T1.Visible = 'off';
            app.editToneE_M_T1.Value = '1';
            app.editToneE_M = uieditfield(app.uiPanelToneEntropy, 'text');
            app.editToneE_M.Value = '1';
            app.text180_6 = uilabel(app.uiPanelToneEntropy);
            app.text180_6.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.pMenuToneEntropy = uidropdown(app.uiPanelToneEntropy);
            app.pMenuToneEntropy.Items = {'Entropy', 'Tone', ''};
            app.pMenuToneEntropy.Visible = 'off';
            app.pMenuToneEntropy.Value = 'Entropy';
            app.uiPanelShannonEx = uipanel(app.uiPanelShannnonGEntropies);
            app.uiPanelShannonEx.Title = 'Shannon Extropy';
            app.text215_4 = uilabel(app.uiPanelShannonEx);
            app.text215_4.Text = 'Num int';
            app.editSEx_NumInt = uieditfield(app.uiPanelShannonEx, 'text');
            app.editSEx_NumInt.Value = '2';
            app.editSEx_M = uieditfield(app.uiPanelShannonEx, 'text');
            app.editSEx_M.Value = '4';
            app.editSEx_NumInt_T1 = uieditfield(app.uiPanelShannonEx, 'text');
            app.editSEx_NumInt_T1.Visible = 'off';
            app.editSEx_NumInt_T1.Value = '2';
            app.editSEx_M_T1 = uieditfield(app.uiPanelShannonEx, 'text');
            app.editSEx_M_T1.Visible = 'off';
            app.editSEx_M_T1.Value = '4';
            app.text216_6 = uilabel(app.uiPanelShannonEx);
            app.text216_6.Text = 'Embedding D (𝒎)';
            app.EntropyHub_EntropiesTab = uitab(app.TabGroupTestParameters);
            app.EntropyHub_EntropiesTab.Title = 'EntropyHub_Entropies';
            app.uiPanelCosiEN = uipanel(app.EntropyHub_EntropiesTab);
            app.uiPanelCosiEN.Title = 'Cosine Similarity Entropy';
            app.editCosiEN_M = uieditfield(app.uiPanelCosiEN, 'text');
            app.editCosiEN_M.Value = 'Edit Text2';
            app.text209_3 = uilabel(app.uiPanelCosiEN);
            app.text209_3.Text = 'Embedding Dimension (𝒎)';
            app.editCosiEN_M_T1 = uieditfield(app.uiPanelCosiEN, 'text');
            app.editCosiEN_M_T1.Visible = 'off';
            app.editCosiEN_M_T1.Value = 'Edit Text2';
            app.editCosiEN_Tau = uieditfield(app.uiPanelCosiEN, 'text');
            app.editCosiEN_Tau.Value = 'Edit Text2';
            app.editCosiEN_Tau_T1 = uieditfield(app.uiPanelCosiEN, 'text');
            app.editCosiEN_Tau_T1.Visible = 'off';
            app.editCosiEN_Tau_T1.Value = 'Edit Text2';
            app.editCosiEN_R = uieditfield(app.uiPanelCosiEN, 'text');
            app.editCosiEN_R.Value = 'Edit Text2';
            app.text212_2 = uilabel(app.uiPanelCosiEN);
            app.text212_2.Text = 'Angular Threshold (𝒓)';
            app.editCosiEN_R_T1 = uieditfield(app.uiPanelCosiEN, 'text');
            app.editCosiEN_R_T1.Visible = 'off';
            app.editCosiEN_R_T1.Value = 'Edit Text2';
            app.editCosiEN_Logx = uieditfield(app.uiPanelCosiEN, 'text');
            app.editCosiEN_Logx.Value = 'Edit Text2';
            app.text213_4 = uilabel(app.uiPanelCosiEN);
            app.text213_4.Text = 'Logarithm(Logx)';
            app.editCosiEN_Logx_T1 = uieditfield(app.uiPanelCosiEN, 'text');
            app.editCosiEN_Logx_T1.Visible = 'off';
            app.editCosiEN_Logx_T1.Value = 'Edit Text2';
            app.text214_3 = uilabel(app.uiPanelCosiEN);
            app.text214_3.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.uiPaneAttnEN = uipanel(app.EntropyHub_EntropiesTab);
            app.uiPaneAttnEN.Title = 'Attention Entropy';
            app.editAttnE_Logx = uieditfield(app.uiPaneAttnEN, 'text');
            app.editAttnE_Logx.Value = 'Edit Text2';
            app.text213_5 = uilabel(app.uiPaneAttnEN);
            app.text213_5.Text = 'Logarithm(Logx)';
            app.editAttnE_Logx_T1 = uieditfield(app.uiPaneAttnEN, 'text');
            app.editAttnE_Logx_T1.Visible = 'off';
            app.editAttnE_Logx_T1.Value = 'Edit Text2';
            app.uiPanelGridEn = uipanel(app.EntropyHub_EntropiesTab);
            app.uiPanelGridEn.Title = 'Gridded Distribution Entropy ';
            app.editGridEN_M = uieditfield(app.uiPanelGridEn, 'text');
            app.editGridEN_M.Value = 'Edit Text2';
            app.text209_4 = uilabel(app.uiPanelGridEn);
            app.text209_4.Text = 'Embedding Dimension (𝒎)';
            app.editGridEN_M_T1 = uieditfield(app.uiPanelGridEn, 'text');
            app.editGridEN_M_T1.Visible = 'off';
            app.editGridEN_M_T1.Value = 'Edit Text2';
            app.editGridEN_Tau = uieditfield(app.uiPanelGridEn, 'text');
            app.editGridEN_Tau.Value = 'Edit Text2';
            app.editGridEN_Tau_T1 = uieditfield(app.uiPanelGridEn, 'text');
            app.editGridEN_Tau_T1.Visible = 'off';
            app.editGridEN_Tau_T1.Value = 'Edit Text2';
            app.editGridEN_Logx = uieditfield(app.uiPanelGridEn, 'text');
            app.editGridEN_Logx.Value = 'Edit Text2';
            app.text213_6 = uilabel(app.uiPanelGridEn);
            app.text213_6.Text = 'Logarithm(Logx)';
            app.editGridEN_Logx_T1 = uieditfield(app.uiPanelGridEn, 'text');
            app.editGridEN_Logx_T1.Visible = 'off';
            app.editGridEN_Logx_T1.Value = 'Edit Text2';
            app.text214_4 = uilabel(app.uiPanelGridEn);
            app.text214_4.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.uiPanelIncrEn = uipanel(app.EntropyHub_EntropiesTab);
            app.uiPanelIncrEn.Title = 'Increment Entropy';
            app.editIncrEN_M = uieditfield(app.uiPanelIncrEn, 'text');
            app.editIncrEN_M.Value = 'Edit Text2';
            app.text209_5 = uilabel(app.uiPanelIncrEn);
            app.text209_5.Text = 'Embedding Dimension (𝒎)';
            app.editIncrEN_M_T1 = uieditfield(app.uiPanelIncrEn, 'text');
            app.editIncrEN_M_T1.Visible = 'off';
            app.editIncrEN_M_T1.Value = 'Edit Text2';
            app.editIncrEN_Tau = uieditfield(app.uiPanelIncrEn, 'text');
            app.editIncrEN_Tau.Value = 'Edit Text2';
            app.editIncrEN_Tau_T1 = uieditfield(app.uiPanelIncrEn, 'text');
            app.editIncrEN_Tau_T1.Visible = 'off';
            app.editIncrEN_Tau_T1.Value = 'Edit Text2';
            app.editIncrEN_R = uieditfield(app.uiPanelIncrEn, 'text');
            app.editIncrEN_R.Value = 'Edit Text2';
            app.text212_4 = uilabel(app.uiPanelIncrEn);
            app.text212_4.Text = 'Quantifying resolution (R)';
            app.editIncrEN_R_T1 = uieditfield(app.uiPanelIncrEn, 'text');
            app.editIncrEN_R_T1.Visible = 'off';
            app.editIncrEN_R_T1.Value = 'Edit Text2';
            app.editIncrEN_Logx = uieditfield(app.uiPanelIncrEn, 'text');
            app.editIncrEN_Logx.Value = 'Edit Text2';
            app.text213_7 = uilabel(app.uiPanelIncrEn);
            app.text213_7.Text = 'Logarithm(Logx)';
            app.editIncrEN_Logx_T1 = uieditfield(app.uiPanelIncrEn, 'text');
            app.editIncrEN_Logx_T1.Visible = 'off';
            app.editIncrEN_Logx_T1.Value = 'Edit Text2';
            app.text214_5 = uilabel(app.uiPanelIncrEn);
            app.text214_5.Text = 'Time Delay (𝒕𝒂𝒖)';
            app.Time_Frequency_DomainTab = uitab(app.TabGroupTestParameters);
            app.Time_Frequency_DomainTab.Title = 'Time_Frequency_Domain';
            app.Other_Complexity_MeasuresTab = uitab(app.TabGroupTestParameters);
            app.Other_Complexity_MeasuresTab.Title = 'Other_Complexity_Measures';
            app.uiPanelPJSC = uipanel(app.Other_Complexity_MeasuresTab);
            app.uiPanelPJSC.Title = 'Permutation JS Complexity (PJSC)';
            app.editPJSC_Tau = uieditfield(app.uiPanelPJSC, 'text');
            app.editPJSC_Tau.Value = 'Edit Text';
            app.editPJSC_M = uieditfield(app.uiPanelPJSC, 'text');
            app.editPJSC_M.Value = 'Edit Text';
            app.editPJSC_Tau_T1 = uieditfield(app.uiPanelPJSC, 'text');
            app.editPJSC_Tau_T1.Visible = 'off';
            app.editPJSC_Tau_T1.Value = 'Edit Text';
            app.editPJSC_M_T1 = uieditfield(app.uiPanelPJSC, 'text');
            app.editPJSC_M_T1.Visible = 'off';
            app.editPJSC_M_T1.Value = 'Edit Text';
            app.text216_4 = uilabel(app.uiPanelPJSC);
            app.text216_4.Text = 'Embedding D (𝒎)';
            app.text180_7 = uilabel(app.uiPanelPJSC);
            app.text180_7.Text = 'Time Lag (𝒕𝒂𝒖)';
            app.uiPanelEDD_Kalauzi = uipanel(app.Other_Complexity_MeasuresTab);
            app.uiPanelEDD_Kalauzi.Title = 'Kalauzi EDD';
            app.editK_EDD_WS = uieditfield(app.uiPanelEDD_Kalauzi, 'text');
            app.editK_EDD_WS.Value = '3';
            app.editK_EDD_WS_T1 = uieditfield(app.uiPanelEDD_Kalauzi, 'text');
            app.editK_EDD_WS_T1.Visible = 'off';
            app.editK_EDD_WS_T1.Value = '5';
            app.text217_6 = uilabel(app.uiPanelEDD_Kalauzi);
            app.text217_6.Text = 'Order';
            app.editK_EDD_order_T1 = uieditfield(app.uiPanelEDD_Kalauzi, 'text');
            app.editK_EDD_order_T1.Visible = 'off';
            app.editK_EDD_order_T1.Value = '5';
            app.text218_5 = uilabel(app.uiPanelEDD_Kalauzi);
            app.text218_5.Text = 'Moving Window Step';
            app.editK_EDD_order = uieditfield(app.uiPanelEDD_Kalauzi, 'text');
            app.editK_EDD_order.Value = '5';
            app.pbPlotTest_4 = uibutton(app.uiPanelEDD_Kalauzi, 'push');
            app.pbPlotTest_4.Text = 'View Plots';
            app.pbPlotTest_5 = uibutton(app.uiPanelEDD_Kalauzi, 'push');
            app.pbPlotTest_5.ButtonPushedFcn = createCallbackFcn(app, @pbPlotTest_5ButtonPushed, true);
            app.pbPlotTest_5.Text = 'View Plots';
            app.pbRunExperiment = uibutton(app.TestParameter, 'push');
            app.pbRunExperiment.ButtonPushedFcn = createCallbackFcn(app, @pbRunExperiment_Callback, true);
            app.pbRunExperiment.Text = 'Run Pipeline';
            app.pMenuRunMode = uidropdown(app.TestParameter);
            app.pMenuRunMode.Items = {'Run Pipeline', 'Test Parameters'};
            app.pMenuRunMode.ValueChangedFcn = createCallbackFcn(app, @pMenuRunModeValueChanged, true);
            app.pMenuRunMode.Value = 'Run Pipeline';
            app.TabBottom_ProcessResult = uitab(app.TabGroupButtom);
            app.TabBottom_ProcessResult.Title = 'Process Results';
            app.UIAxesViewResults = uiaxes(app.TabBottom_ProcessResult);
            title(app.UIAxesViewResults, 'Results')
            xlabel(app.UIAxesViewResults, 'X')
            ylabel(app.UIAxesViewResults, 'Y')
            app.Panel4 = uipanel(app.TabBottom_ProcessResult);
            app.Panel4.Title = 'Panel4';
            app.editResultDir = uieditfield(app.Panel4, 'text');
            app.editResultDir.Value = 'Edit Text';
            app.text232 = uilabel(app.Panel4);
            app.text232.Text = 'Experiment Name';
            app.pbResultDirectory = uibutton(app.Panel4, 'push');
            app.pbResultDirectory.ButtonPushedFcn = createCallbackFcn(app, @pbResultDirectory_Callback, true);
            app.pbResultDirectory.Text = 'Select Result Directory';
            app.pbSaveResults = uibutton(app.Panel4, 'push');
            app.pbSaveResults.ButtonPushedFcn = createCallbackFcn(app, @pbSaveResults_Callback, true);
            app.pbSaveResults.Text = 'Save Results';
            app.pbViewPlot = uibutton(app.Panel4, 'push');
            app.pbViewPlot.ButtonPushedFcn = createCallbackFcn(app, @pbViewPlot_Callback, true);
            app.pbViewPlot.Text = 'View Plot';
            app.text233 = uilabel(app.Panel4);
            app.text233.Text = 'Aggregate By:';
            app.pMenuViewType = uidropdown(app.Panel4);
            app.pMenuViewType.Items = {'Mean', 'Median'};
            app.pMenuViewType.Value = 'Mean';
            app.editExperimentName = uieditfield(app.Panel4, 'text');
            app.editExperimentName.Value = 'Exp1';
            app.chkSaveInSExcel = uicheckbox(app.Panel4);
            app.chkSaveInSExcel.Text = 'Save In Excel';
            app.chkSaveAsMat = uicheckbox(app.Panel4);
            app.chkSaveAsMat.Text = 'Save In matlab ';
            app.chkSaveTime = uicheckbox(app.Panel4);
            app.chkSaveTime.Text = 'Save Computation Time';
            app.pMenuSaveExcelType = uidropdown(app.Panel4);
            app.pMenuSaveExcelType.Items = {'All In One Sheet', 'One Sheet Per File'};
            app.pMenuSaveExcelType.Value = 'All In One Sheet';
            app.chkShowError = uicheckbox(app.Panel4);
            app.chkShowError.Text = 'Show Error';
            app.text233_2 = uilabel(app.Panel4);
            app.text233_2.Text = 'View By';
            app.pMenuViewBy = uidropdown(app.Panel4);
            app.pMenuViewBy.Items = {'Files', 'Epochs', 'Columns', 'Scales'};
            app.pMenuViewBy.ValueChangedFcn = createCallbackFcn(app, @pMenuViewByValueChanged, true);
            app.pMenuViewBy.Value = 'Files';
            app.uiPanelEpochSelection = uipanel(app.TabBottom_ProcessResult);
            app.uiPanelEpochSelection.Title = 'Epoch Selection';
            app.chkSingleData = uicheckbox(app.uiPanelEpochSelection);
            app.chkSingleData.ValueChangedFcn = createCallbackFcn(app, @chkSingleDataValueChanged, true);
            app.chkSingleData.Text = 'Consider as Single Dataset';
            app.chkSingleData.Value = true;
            app.chkAllEpoch = uicheckbox(app.uiPanelEpochSelection);
            app.chkAllEpoch.ValueChangedFcn = createCallbackFcn(app, @chkAllEpochValueChanged, true);
            app.chkAllEpoch.Text = 'All Epochs';
            app.editSelEpochList = uieditfield(app.uiPanelEpochSelection, 'text');
            app.chkSelEpoch = uicheckbox(app.uiPanelEpochSelection);
            app.chkSelEpoch.ValueChangedFcn = createCallbackFcn(app, @chkSelEpoch_Callback, true);
            app.chkSelEpoch.Text = 'Enter Epoch Number/s';
            app.TextArea_2 = uitextarea(app.uiPanelEpochSelection);
            app.TextArea_2.Value = {'Enter Epoch number/s seperated by comma (e.g. 1,2). For range, separate by dashes (e.g. 1,2,5-10,12-15).'};
            app.AnalysisTab = uitab(app.TabGroupButtom);
            app.AnalysisTab.Title = 'Analysis';
            app.TabGroup3 = uitabgroup(app.AnalysisTab);
            app.tabDA_Fig1 = uitab(app.TabGroup3);
            app.tabDA_Fig1.Title = 'Plot1';
            app.panelDA_fig1 = uipanel(app.tabDA_Fig1);
            app.panelDA_fig1.Title = 'Data Analysis Plot 1';
            app.tabDA_Data1 = uitab(app.TabGroup3);
            app.tabDA_Data1.Title = 'Data Table';
            app.uiTable_DA1 = uitable(app.tabDA_Data1);
            app.uiTable_DA1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.uiTable_DA1.RowName = {};
            app.editDA_File2 = uieditfield(app.tabDA_Data1, 'text');
            app.editDA_File2.Value = 'DA_Plot1';
            app.text218_10 = uilabel(app.tabDA_Data1);
            app.text218_10.Text = 'File Name';
            app.pbSaveDA_Data1 = uibutton(app.tabDA_Data1, 'push');
            app.pbSaveDA_Data1.ButtonPushedFcn = createCallbackFcn(app, @pbSaveDA_Data1ButtonPushed, true);
            app.pbSaveDA_Data1.Text = 'Save';
            app.tabDA_Data2 = uitab(app.TabGroup3);
            app.tabDA_Data2.Title = 'DataTable';
            app.uiTable_DA2 = uitable(app.tabDA_Data2);
            app.uiTable_DA2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.uiTable_DA2.RowName = {};
            app.editDA_File3 = uieditfield(app.tabDA_Data2, 'text');
            app.editDA_File3.Value = 'DA_Plot1';
            app.text218_11 = uilabel(app.tabDA_Data2);
            app.text218_11.Text = 'File Name';
            app.pbSaveDA_Data2 = uibutton(app.tabDA_Data2, 'push');
            app.pbSaveDA_Data2.ButtonPushedFcn = createCallbackFcn(app, @pbSaveDA_Data2ButtonPushed, true);
            app.pbSaveDA_Data2.Text = 'Save';
            app.tabDA_Fig2 = uitab(app.TabGroup3);
            app.tabDA_Fig2.Title = 'Plot2';
            app.panelDA_fig2 = uipanel(app.tabDA_Fig2);
            app.panelDA_fig2.Title = 'Data Analysis Plot 1';
            app.tabDA_Data3 = uitab(app.TabGroup3);
            app.tabDA_Data3.Title = 'Data Table';
            app.uiTable_DA3 = uitable(app.tabDA_Data3);
            app.uiTable_DA3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.uiTable_DA3.RowName = {};
            app.editDA_File5 = uieditfield(app.tabDA_Data3, 'text');
            app.editDA_File5.Value = 'DA_Plot1';
            app.text218_13 = uilabel(app.tabDA_Data3);
            app.text218_13.Text = 'File Name';
            app.pbSaveDA_Data3 = uibutton(app.tabDA_Data3, 'push');
            app.pbSaveDA_Data3.ButtonPushedFcn = createCallbackFcn(app, @pbSaveDA_Data3ButtonPushed, true);
            app.pbSaveDA_Data3.Text = 'Save';
            app.tabDA_Fig3 = uitab(app.TabGroup3);
            app.tabDA_Fig3.Title = 'Plot3';
            app.panelDA_fig3 = uipanel(app.tabDA_Fig3);
            app.panelDA_fig3.Title = 'Data Analysis Plot 3';
            app.TG_AnalysisDown = uitabgroup(app.AnalysisTab);
            app.GPP_Kalauzi_SymmetricTab = uitab(app.TG_AnalysisDown);
            app.GPP_Kalauzi_SymmetricTab.Title = 'GPP_Kalauzi_Symmetric';
            app.editDataA_GPP_maxOrder = uieditfield(app.GPP_Kalauzi_SymmetricTab, 'text');
            app.editDataA_GPP_maxOrder.Value = '100';
            app.text217_8 = uilabel(app.GPP_Kalauzi_SymmetricTab);
            app.text217_8.Text = 'min Order';
            app.text218_7 = uilabel(app.GPP_Kalauzi_SymmetricTab);
            app.text218_7.Text = 'max Order';
            app.editDataA_GPP_minOrder = uieditfield(app.GPP_Kalauzi_SymmetricTab, 'text');
            app.editDataA_GPP_minOrder.Visible = 'off';
            app.editDataA_GPP_minOrder.Value = '1';
            app.pbViewPlot_GPP = uibutton(app.GPP_Kalauzi_SymmetricTab, 'push');
            app.pbViewPlot_GPP.ButtonPushedFcn = createCallbackFcn(app, @pbViewPlot_GPPButtonPushed, true);
            app.pbViewPlot_GPP.Text = 'View Plots';
            app.GPPTypeButtonGroup = uibuttongroup(app.GPP_Kalauzi_SymmetricTab);
            app.GPPTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GPPTypeButtonGroupSelectionChanged, true);
            app.GPPTypeButtonGroup.Title = 'GPP Type';
            app.rbDA_GPP_Sym = uiradiobutton(app.GPPTypeButtonGroup);
            app.rbDA_GPP_Sym.Text = 'Symmetric';
            app.rbDA_GPP_Sym.Value = true;
            app.rbDA_GPP_Asym = uiradiobutton(app.GPPTypeButtonGroup);
            app.rbDA_GPP_Asym.Text = 'Asymmetric';
            app.editDataA_GPP_step = uieditfield(app.GPP_Kalauzi_SymmetricTab, 'text');
            app.editDataA_GPP_step.Visible = 'off';
            app.editDataA_GPP_step.Value = '5';
            app.text218_8 = uilabel(app.GPP_Kalauzi_SymmetricTab);
            app.text218_8.Text = 'Step';
            app.text218_16 = uilabel(app.GPP_Kalauzi_SymmetricTab);
            app.text218_16.Text = 'Normalized Asymmetric Index (NAI) :';
            app.txtNAI = uilabel(app.GPP_Kalauzi_SymmetricTab);
            app.txtNAI.Text = '-';
            app.FDNldianTab = uitab(app.TG_AnalysisDown);
            app.FDNldianTab.Title = 'FD Nldian';
            app.editDataA_FDnldianWS = uieditfield(app.FDNldianTab, 'text');
            app.editDataA_FDnldianWS.Value = '3';
            app.text217_7 = uilabel(app.FDNldianTab);
            app.text217_7.Text = 'Moving Window Length';
            app.text218_6 = uilabel(app.FDNldianTab);
            app.text218_6.Text = 'Moving Window Step';
            app.editDataA_FDnldianWL = uieditfield(app.FDNldianTab, 'text');
            app.editDataA_FDnldianWL.Value = '5';
            app.pbPlotTest_6 = uibutton(app.FDNldianTab, 'push');
            app.pbPlotTest_6.ButtonPushedFcn = createCallbackFcn(app, @pbPlotTest_6ButtonPushed, true);
            app.pbPlotTest_6.Text = 'View Plots';
            app.FDTypeButtonGroup = uibuttongroup(app.FDNldianTab);
            app.FDTypeButtonGroup.Title = 'FD Type';
            app.rdFDTypeNldwan = uiradiobutton(app.FDTypeButtonGroup);
            app.rdFDTypeNldwan.Text = 'FD Nldian';
            app.rdFDTypeNldwan.Value = true;
            app.rdFDTypeNldian = uiradiobutton(app.FDTypeButtonGroup);
            app.rdFDTypeNldian.Text = 'FD Nldwan';
            app.DiffusiuonEntropyTab = uitab(app.TG_AnalysisDown);
            app.DiffusiuonEntropyTab.Title = 'Diffusiuon Entropy';
            app.text217_9 = uilabel(app.DiffusiuonEntropyTab);
            app.text217_9.Text = 'Number of Strips';
            app.editDsNumberE = uieditfield(app.DiffusiuonEntropyTab, 'text');
            app.editDsNumberE.Value = '5';
            app.pbPlotTest_7 = uibutton(app.DiffusiuonEntropyTab, 'push');
            app.pbPlotTest_7.ButtonPushedFcn = createCallbackFcn(app, @pbPlotTest_7ButtonPushed, true);
            app.pbPlotTest_7.Text = 'View Plots';
            app.DetrendTab = uitab(app.TG_AnalysisDown);
            app.DetrendTab.Title = 'Detrend';
            app.text217_10 = uilabel(app.DetrendTab);
            app.text217_10.Text = 'Degree of  Polynomial (n)';
            app.editDegreePolynomial = uieditfield(app.DetrendTab, 'text');
            app.editDegreePolynomial.Value = '5';
            app.pbViewDetrend = uibutton(app.DetrendTab, 'push');
            app.pbViewDetrend.ButtonPushedFcn = createCallbackFcn(app, @pbViewDetrendButtonPushed, true);
            app.pbViewDetrend.Text = 'View Plots';
            app.text217_11 = uilabel(app.DetrendTab);
            app.text217_11.Text = 'Degree of  Polynomial (n) -  removes the nth-degree polynomial trend. For example, when n = 0, detrend removes the mean value from x. When n = 1, detrend removes the linear trend, which is equivalent to the previous syntax. When n = 2, detrend removes the quadratic trend.';
            app.pbApplySave_Detrend = uibutton(app.DetrendTab, 'push');
            app.pbApplySave_Detrend.ButtonPushedFcn = createCallbackFcn(app, @pbApplySave_DetrendButtonPushed, true);
            app.pbApplySave_Detrend.Text = 'Apply/Save all';
            app.text217_12 = uilabel(app.DetrendTab);
            app.text217_12.Text = 'prefix';
            app.editPrefix_Detrend = uieditfield(app.DetrendTab, 'text');
            app.text217_13 = uilabel(app.DetrendTab);
            app.text217_13.Text = 'sufix';
            app.editSufix_Detrend = uieditfield(app.DetrendTab, 'text');
            app.ListBoxLabel = uilabel(app.AnalysisTab);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.AnalysisTab);
            app.ListBox.Items = {'GPP_Kalauzi', 'FD_Nldian', 'Diffusiion Entropy', 'Detrend'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'GPP_Kalauzi';
            app.text176_2 = uilabel(app.AnalysisTab);
            app.text176_2.Text = 'Metrices';
            app.TabGroupSelection = uitabgroup(app.figure1);
            app.TabGroupSelection.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionSelectionChanged, true);
            app.DataFilesTab = uitab(app.TabGroupSelection);
            app.DataFilesTab.Title = 'Data Files';
            app.chkAllFiles = uicheckbox(app.DataFilesTab);
            app.chkAllFiles.ValueChangedFcn = createCallbackFcn(app, @chkAllFilesValueChanged, true);
            app.chkAllFiles.Text = 'check/uncheck all files';
            app.chkAllFiles.Value = true;
            app.uiTableFilesName = uitable(app.DataFilesTab);
            app.uiTableFilesName.ColumnName = {'File Name'; 'check/uncheck'};
            app.uiTableFilesName.CellEditCallback = createCallbackFcn(app, @uiTableFilesNameCellEdit, true);
            app.uiTableFilesName.CellSelectionCallback = createCallbackFcn(app, @uiTableFilesNameCellSelection, true);
            app.MeasuresTab = uitab(app.TabGroupSelection);
            app.MeasuresTab.Title = 'Measures';
            app.chkAllMeasures = uicheckbox(app.MeasuresTab);
            app.chkAllMeasures.ValueChangedFcn = createCallbackFcn(app, @chkAllMeasures_Callback, true);
            app.chkAllMeasures.Text = 'check/uncheck all measures';
            app.chkAllMeasures.Value = true;
            app.uitableSMeasures = uitable(app.MeasuresTab);
            app.uitableSMeasures.ColumnName = {'Measure'; 'Enable/Disable'};
            app.uitableSMeasures.ColumnEditable = [false true];
            app.uitableSMeasures.CellEditCallback = createCallbackFcn(app, @uitableSMeasures_CellEditCallback, true);
            app.uitableSMeasures.CellSelectionCallback = createCallbackFcn(app, @uitableSMeasuresCellSelection, true);
            app.MVerificationTab = uitab(app.TabGroupSelection);
            app.MVerificationTab.SizeChangedFcn = createCallbackFcn(app, @ApplyCoarseGrainButtonPushed, true);
            app.MVerificationTab.Title = 'M Verification';
            app.uiTableVerification = uitable(app.MVerificationTab);
            app.uiTableVerification.ColumnName = {'Measurement'; 'Status'};
            app.uiTableVerification.RowName = {};
            app.text117 = uilabel(app.figure1);
            app.text117.Text = 'File/s ';
            app.pMenuFileNames = uidropdown(app.figure1);
            app.pMenuFileNames.Items = {'-- All Files --', '-- Range --'};
            app.pMenuFileNames.ValueChangedFcn = createCallbackFcn(app, @pMenuFileNamesValueChanged, true);
            app.pMenuFileNames.Value = '-- All Files --';
            app.text118 = uilabel(app.figure1);
            app.text118.Text = 'Epoch/s';
            app.pMenuEpochs = uidropdown(app.figure1);
            app.pMenuEpochs.Items = {'-- All Epochs --'};
            app.pMenuEpochs.ValueChangedFcn = createCallbackFcn(app, @pMenuEpochsValueChanged, true);
            app.pMenuEpochs.Value = '-- All Epochs --';
            app.text120 = uilabel(app.figure1);
            app.text120.Text = 'Column/s';
            app.pMenuDimension = uidropdown(app.figure1);
            app.pMenuDimension.Items = {'-- All Columns --'};
            app.pMenuDimension.Value = '-- All Columns --';
            app.chkDebugMode = uicheckbox(app.figure1);
            app.chkDebugMode.Text = 'DEBUG MODE';
            app.editFileRange = uieditfield(app.figure1, 'text');
            app.editFileRange.Enable = 'off';
            app.editFileRange.Value = '-- Give Range --';
            app.editEpochRange = uieditfield(app.figure1, 'text');
            app.editEpochRange.Enable = 'off';
            app.editEpochRange.Value = '-- Give Range --';
            app.editColumnRange = uieditfield(app.figure1, 'text');
            app.editColumnRange.Enable = 'off';
            app.editColumnRange.Value = '-- Give Range --';
            app.ContextMenu = uicontextmenu(app.figure1);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            app.ContextMenu2 = uicontextmenu(app.figure1);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            app.ContextMenu3 = uicontextmenu(app.figure1);
            app.Menu_3 = uimenu(app.ContextMenu3);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ContextMenu3);
            app.Menu2_3.Text = 'Menu2';
            app.ContextMenu4 = uicontextmenu(app.figure1);
            app.Menu_4 = uimenu(app.ContextMenu4);
            app.Menu_4.Text = 'Menu';
            app.Menu2_4 = uimenu(app.ContextMenu4);
            app.Menu2_4.Text = 'Menu2';
            % Assign app.ContextMenu4
            app.rbDA_GPP_Asym.ContextMenu = app.ContextMenu4;
            app.ContextMenu5 = uicontextmenu(app.figure1);
            app.Menu_5 = uimenu(app.ContextMenu5);
            app.Menu_5.Text = 'Menu';
            app.Menu2_5 = uimenu(app.ContextMenu5);
            app.Menu2_5.Text = 'Menu2';
            % Assign app.ContextMenu5
            app.pbSaveDA_Data1.ContextMenu = app.ContextMenu5;
            app.pbSaveDA_Data2.ContextMenu = app.ContextMenu5;
            app.pbSaveDA_Data3.ContextMenu = app.ContextMenu5;
            app.pbViewPlot_GPP.ContextMenu = app.ContextMenu5;
            app.ContextMenu6 = uicontextmenu(app.figure1);
            app.Menu_6 = uimenu(app.ContextMenu6);
            app.Menu_6.Text = 'Menu';
            app.Menu2_6 = uimenu(app.ContextMenu6);
            app.Menu2_6.Text = 'Menu2';
            % Assign app.ContextMenu6
            app.pbPlotTest.ContextMenu = app.ContextMenu6;
            app.ContextMenu7 = uicontextmenu(app.figure1);
            app.Menu_7 = uimenu(app.ContextMenu7);
            app.Menu_7.Text = 'Menu';
            app.Menu2_7 = uimenu(app.ContextMenu7);
            app.Menu2_7.Text = 'Menu2';
            % Assign app.ContextMenu7
            app.pbPlotTest_6.ContextMenu = app.ContextMenu7;
            app.ContextMenu8 = uicontextmenu(app.figure1);
            app.Menu_8 = uimenu(app.ContextMenu8);
            app.Menu_8.Text = 'Menu';
            app.Menu2_8 = uimenu(app.ContextMenu8);
            app.Menu2_8.Text = 'Menu2';
            % Assign app.ContextMenu8
            app.pbViewRS.ContextMenu = app.ContextMenu8;
            app.ContextMenu9 = uicontextmenu(app.figure1);
            app.Menu_9 = uimenu(app.ContextMenu9);
            app.Menu_9.Text = 'Menu';
            app.Menu2_9 = uimenu(app.ContextMenu9);
            app.Menu2_9.Text = 'Menu2';
            % Assign app.ContextMenu9
            app.pbLoadData.ContextMenu = app.ContextMenu9;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CEPS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @ProcessData_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end